classdef TargetCoverage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetCoverageAppUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        LoadAppStateButton              matlab.ui.control.Button
        SaveAppStateButton              matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        BasicInfoLabel                  matlab.ui.control.Label
        BasemodelparametersPanel        matlab.ui.container.Panel
        hrsLabel                        matlab.ui.control.Label
        SimulationendtimeEditField      matlab.ui.control.NumericEditField
        SimulationendtimeEditFieldLabel  matlab.ui.control.Label
        GenerateModelsButton            matlab.ui.control.Button
        CarryingCapacityEditField       matlab.ui.control.NumericEditField
        CarryingCapacityEditFieldLabel  matlab.ui.control.Label
        hrLabel                         matlab.ui.control.Label
        SeedingEditField                matlab.ui.control.NumericEditField
        SeedingDensityEditFieldLabel    matlab.ui.control.Label
        GrowthEditField                 matlab.ui.control.NumericEditField
        GrowthRateEditFieldLabel        matlab.ui.control.Label
        OncologyTargetCoverageSimulationAppLabel  matlab.ui.control.Label
        SimulationPlotsTab              matlab.ui.container.Tab
        ExtractIndPlotsButton           matlab.ui.control.Button
        ExtractSubPlotsButton           matlab.ui.control.Button
        NoneCheckBox                    matlab.ui.control.CheckBox
        AllCheckBox                     matlab.ui.control.CheckBox
        BothfitCheckBox                 matlab.ui.control.CheckBox
        WashfitCheckBox                 matlab.ui.control.CheckBox
        IC50fitCheckBox                 matlab.ui.control.CheckBox
        ManualCheckBox                  matlab.ui.control.CheckBox
        DataCheckBox                    matlab.ui.control.CheckBox
        ShowplotsLabel                  matlab.ui.control.Label
        FitallButton                    matlab.ui.control.Button
        FittoButtonGroup_All            matlab.ui.container.ButtonGroup
        BothButton_All                  matlab.ui.control.RadioButton
        WashoutOnlyButton_All           matlab.ui.control.RadioButton
        IC50onlyButton_All              matlab.ui.control.RadioButton
        AUCparamPanel                   matlab.ui.container.Panel
        FittoButtonGroup_Cavg           matlab.ui.container.ButtonGroup
        BothButton_Cavg                 matlab.ui.control.RadioButton
        WashoutOnlyButton_Cavg          matlab.ui.control.RadioButton
        IC50onlyButton_Cavg             matlab.ui.control.RadioButton
        LockLabel_2                     matlab.ui.control.Label
        fracNRlockBtn_Cavg              matlab.ui.control.CheckBox
        IC50lockBtn_Cavg                matlab.ui.control.CheckBox
        FitIndividButton_Cavg           matlab.ui.control.Button
        fracnonrespEditField_Cavg       matlab.ui.control.NumericEditField
        FracnonrespLabel_2              matlab.ui.control.Label
        IC50EditField_Cavg              matlab.ui.control.NumericEditField
        S_AUCLabel                      matlab.ui.control.Label
        CmaxparamPanel                  matlab.ui.container.Panel
        FittoButtonGroup_Cmax           matlab.ui.container.ButtonGroup
        BothButton_Cmax                 matlab.ui.control.RadioButton
        WashoutOnlyButton_Cmax          matlab.ui.control.RadioButton
        IC50onlyButton_Cmax             matlab.ui.control.RadioButton
        fracNRlockBtn_Cmax              matlab.ui.control.CheckBox
        nlockBtn_Cmax                   matlab.ui.control.CheckBox
        IC50lockBtn_Cmax                matlab.ui.control.CheckBox
        LockLabel_3                     matlab.ui.control.Label
        FitIndividButton_Cmax           matlab.ui.control.Button
        nEditField_Cmax                 matlab.ui.control.NumericEditField
        HilCoefLabel                    matlab.ui.control.Label
        IC50EditField_Cmax              matlab.ui.control.NumericEditField
        S_maxLabel                      matlab.ui.control.Label
        fracnonrespEditField_Cmax       matlab.ui.control.NumericEditField
        FracnonrespLabel                matlab.ui.control.Label
        ThresholdparamPanel             matlab.ui.container.Panel
        EmaxLockBtn_Cmin                matlab.ui.control.CheckBox
        EmaxEditField_Cmin              matlab.ui.control.NumericEditField
        EmaxLabel                       matlab.ui.control.Label
        FittoButtonGroup_Cmin           matlab.ui.container.ButtonGroup
        BothButton_Cmin                 matlab.ui.control.RadioButton
        WashoutOnlyButton_Cmin          matlab.ui.control.RadioButton
        IC50onlyButton_Cmin             matlab.ui.control.RadioButton
        fracNRlockBtn_Cmin              matlab.ui.control.CheckBox
        nlockBtn_Cmin                   matlab.ui.control.CheckBox
        IC50lockBtn_Cmin                matlab.ui.control.CheckBox
        LockLabel                       matlab.ui.control.Label
        FitIndividButton_Cmin           matlab.ui.control.Button
        fracnonrespEditField_Cmin       matlab.ui.control.NumericEditField
        fracnonrespEditFieldLabel_4     matlab.ui.control.Label
        nEditField_Cmin                 matlab.ui.control.NumericEditField
        HillCoefLabel                   matlab.ui.control.Label
        IC50EditField_Cmin              matlab.ui.control.NumericEditField
        IC50EditFieldLabel              matlab.ui.control.Label
        CmaxIC50ax                      matlab.ui.control.UIAxes
        CavgIC50ax                      matlab.ui.control.UIAxes
        CminIC50ax                      matlab.ui.control.UIAxes
        CmaxWOax                        matlab.ui.control.UIAxes
        CavgWOax                        matlab.ui.control.UIAxes
        CminWOax                        matlab.ui.control.UIAxes
        ResidualsTab                    matlab.ui.container.Tab
        WashoutdataresidualplotsLabel   matlab.ui.control.Label
        ExtractIndPlotsButton_resid     matlab.ui.control.Button
        ExtractSubPlotsButton_resid     matlab.ui.control.Button
        SelResButtonGroup               matlab.ui.container.ButtonGroup
        FitbothResidButton              matlab.ui.control.RadioButton
        WashoutonlyfitResidButton       matlab.ui.control.RadioButton
        IC50onlyfitResidButton          matlab.ui.control.RadioButton
        CmaxResidAx_IC50                matlab.ui.control.UIAxes
        CavgResidAx_IC50                matlab.ui.control.UIAxes
        CminResidAx_IC50                matlab.ui.control.UIAxes
        CmaxResidAx                     matlab.ui.control.UIAxes
        CavgResidAx                     matlab.ui.control.UIAxes
        CminResidAx                     matlab.ui.control.UIAxes
        FitParameterResultsTab          matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        IC50onlyPanel                   matlab.ui.container.Panel
        SetCurrentParamsasButton_IC50   matlab.ui.control.Button
        paramtypeButtonGroup_IC50       matlab.ui.container.ButtonGroup
        BoundedFitButton_IC50           matlab.ui.control.RadioButton
        InitialParamButton_IC50         matlab.ui.control.RadioButton
        forthesemodelsPanel_IC50        matlab.ui.container.Panel
        AllCheckBox_IC50                matlab.ui.control.CheckBox
        CmaxCheckBox_IC50               matlab.ui.control.CheckBox
        CavgCheckBox_IC50               matlab.ui.control.CheckBox
        CminCheckBox_IC50               matlab.ui.control.CheckBox
        FitData_IC50fit                 matlab.ui.control.Table
        FittobothPanel                  matlab.ui.container.Panel
        forthesemodelsPanel_both        matlab.ui.container.Panel
        AllCheckBox_both                matlab.ui.control.CheckBox
        CmaxCheckBox_both               matlab.ui.control.CheckBox
        CavgCheckBox_both               matlab.ui.control.CheckBox
        CminCheckBox_both               matlab.ui.control.CheckBox
        paramtypeButtonGroup_both       matlab.ui.container.ButtonGroup
        BoundedFitButton_both           matlab.ui.control.RadioButton
        InitialParamButton_both         matlab.ui.control.RadioButton
        SetCurrentParamsasButton_both   matlab.ui.control.Button
        FitData_bothfit                 matlab.ui.control.Table
        WashoutonlyfitPanel             matlab.ui.container.Panel
        forthesemodelsPanel_WO          matlab.ui.container.Panel
        AllCheckBox_WO                  matlab.ui.control.CheckBox
        CmaxCheckBox_WO                 matlab.ui.control.CheckBox
        CavgCheckBox_WO                 matlab.ui.control.CheckBox
        CminCheckBox_WO                 matlab.ui.control.CheckBox
        paramtypeButtonGroup_WO         matlab.ui.container.ButtonGroup
        BoundedFitButton_WO             matlab.ui.control.RadioButton
        InitialParamButton_WO           matlab.ui.control.RadioButton
        SetCurrentParamsasButton_WO     matlab.ui.control.Button
        FitData_WOfit                   matlab.ui.control.Table
        DataTab                         matlab.ui.container.Tab
        ImportfromexceltemplatePanel    matlab.ui.container.Panel
        NormalizeDataButtonGroup        matlab.ui.container.ButtonGroup
        AcrossallsheetsButton           matlab.ui.control.RadioButton
        WithinsheetButton               matlab.ui.control.RadioButton
        ImportIC50excelButton           matlab.ui.control.Button
        IC50datafromwashoutGroup        matlab.ui.container.ButtonGroup
        YesIC50dataButton               matlab.ui.control.RadioButton
        NoIC50dataButton                matlab.ui.control.RadioButton
        ImportWOexcelButton             matlab.ui.control.Button
        ImportfromMATLABworkspacetablevariablesPanel  matlab.ui.container.Panel
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        WAOdataWSimportButton           matlab.ui.control.Button
        IC50dataWSimportButton          matlab.ui.control.Button
        WOdatatable                     matlab.ui.control.Table
        IC50datatable                   matlab.ui.control.Table
        GrowthParameterFittingTab       matlab.ui.container.Tab
        SetFitGrowthParams              matlab.ui.control.Button
        FitGrowthParametersButton       matlab.ui.control.Button
        GrowthParamTable                matlab.ui.control.Table
        ImportGrowthDataPanel           matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ImportGrowthfromExcelButton     matlab.ui.control.Button
        GrowthDataTable                 matlab.ui.control.Table
        GrowthAx                        matlab.ui.control.UIAxes
        OtherOptionsTab                 matlab.ui.container.Tab
        SimultaneousfittowashoutandIC50dataPanel  matlab.ui.container.Panel
        WeightBothFit                   matlab.ui.control.CheckBox
        FitparameterlimitsPanel         matlab.ui.container.Panel
        CmaxModelPanel                  matlab.ui.container.Panel
        Cmax_hill_LL                    matlab.ui.control.NumericEditField
        hillcoefLabel                   matlab.ui.control.Label
        Cmax_fracNR_LL                  matlab.ui.control.NumericEditField
        Cmax_fracNR_UL                  matlab.ui.control.NumericEditField
        fracnonrespEditFieldLabel_7     matlab.ui.control.Label
        Cmax_hill_UL                    matlab.ui.control.NumericEditField
        Cmax_IC50_UL                    matlab.ui.control.NumericEditField
        MaxLabel_3                      matlab.ui.control.Label
        MinLabel_3                      matlab.ui.control.Label
        Cmax_IC50_LL                    matlab.ui.control.NumericEditField
        S_maxLabel_2                    matlab.ui.control.Label
        AUCmodelPanel                   matlab.ui.container.Panel
        AUC_fracNR_LL                   matlab.ui.control.NumericEditField
        AUC_fracNR_UL                   matlab.ui.control.NumericEditField
        fracnonrespEditFieldLabel_6     matlab.ui.control.Label
        AUC_IC50_UL                     matlab.ui.control.NumericEditField
        AUC_IC50_LL                     matlab.ui.control.NumericEditField
        S_AUCLabel_2                    matlab.ui.control.Label
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        ThresholdmodelPanel             matlab.ui.container.Panel
        Thresh_fracNR_LL                matlab.ui.control.NumericEditField
        Thresh_fracNR_UL                matlab.ui.control.NumericEditField
        fracnonrespEditFieldLabel_5     matlab.ui.control.Label
        Thresh_Emax_UL                  matlab.ui.control.NumericEditField
        Thresh_Emax_LL                  matlab.ui.control.NumericEditField
        EmaxEditFieldLabel              matlab.ui.control.Label
        Thresh_hill_UL                  matlab.ui.control.NumericEditField
        Thresh_hill_LL                  matlab.ui.control.NumericEditField
        hillcoefEditFieldLabel          matlab.ui.control.Label
        Thresh_IC50_UL                  matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        Thresh_IC50_LL                  matlab.ui.control.NumericEditField
        IC50EditFieldLabel_4            matlab.ui.control.Label
        PlotOptionsPanel                matlab.ui.container.Panel
        WashoutColormapDropDown         matlab.ui.control.DropDown
        WashoutColormapDropDownLabel    matlab.ui.control.Label
        UpdatePlotDisplayButton         matlab.ui.control.Button
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel        matlab.ui.control.Label
        DataPlotFormatButtonGroup       matlab.ui.container.ButtonGroup
        SEMButton                       matlab.ui.control.ToggleButton
        IndividualButton                matlab.ui.control.ToggleButton
        DrugConcentrationcorrectionsPanel  matlab.ui.container.Panel
        UpdateModelSettingsButton       matlab.ui.control.Button
        fraccarryoverafterwashoutEditField  matlab.ui.control.NumericEditField
        fraccarryoverafterwashoutEditFieldLabel  matlab.ui.control.Label
        hrLabel_2                       matlab.ui.control.Label
        degradationrateEditField        matlab.ui.control.NumericEditField
        storderdegradationrateEditFieldLabel  matlab.ui.control.Label
        ReportTab                       matlab.ui.container.Tab
        AnalysisOutcomePanel            matlab.ui.container.Panel
        FinalCommentsTextArea           matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        ConfidenceDropDown              matlab.ui.control.DropDown
        ConfidenceDropDownLabel         matlab.ui.control.Label
        PKDriverDropDown                matlab.ui.control.DropDown
        PKDriverDropDownLabel           matlab.ui.control.Label
        ExperimentalMetadataPanel       matlab.ui.container.Panel
        NumReplEditField                matlab.ui.control.NumericEditField
        NumberofReplEditFieldLabel      matlab.ui.control.Label
        SourceEditField                 matlab.ui.control.EditField
        SourceEditFieldLabel            matlab.ui.control.Label
        DatesEditField                  matlab.ui.control.EditField
        DatesEditFieldLabel             matlab.ui.control.Label
        CellTypeEditField               matlab.ui.control.EditField
        CellTypeEditFieldLabel          matlab.ui.control.Label
        CompoundMetadataPanel           matlab.ui.container.Panel
        TargetEditField                 matlab.ui.control.EditField
        TargetEditFieldLabel            matlab.ui.control.Label
        CompNameEditField               matlab.ui.control.EditField
        NameifAvailEditFieldLabel       matlab.ui.control.Label
        CompNumberEditField             matlab.ui.control.EditField
        CompNumberEditFieldLabel        matlab.ui.control.Label
        GenerateReportButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_Cmin % Pointer to Cmin model
        m_Cavg % Pointer to Cavg Model
        m_Cmax % Pointer to Cmax Model
        
        % Pointers to different Sim functions
        simfunc_Cmin;
        simfunc_Cavg;
        simfunc_Cmax;
        
        % Default input parameters
        IC50_Cmin = 15;
        n_Cmin = 1;
        frac_nr_Cmin = 0;
        Emax_Cmin = 1;
        
        IC50_Cavg = 15;
        frac_nr_Cavg = 0.1;
        
        IC50_Cmax = 15;
        n_Cmax = 1.5;
        frac_nr_Cmax = 0.1;
        
        Growth = 0.0627; % 1/hr growth rate
        InitSeeding = 4.8; % percent confluent
        Volmax = 130; % carrying capacity
        CompDeg = 0; % first order degradation rate of the drug in media
        CarryOver = 0; % Amount of residual drug left behind after washout
        
        % Define the parameters that are acting as inputs for the simfunc
        % Growth parameter names
        growthParamNames = {'Growth','InitSeeding','Volmax','CompDeg','CarryOver'};
        % stimulation/experiment names
        paramNames = {'Drug','duration'};
        % define the output variable
        observables = 'Plasma.Tumor_tot';
        % define how long the simulation should run
        t_run = 72;
        
        param_lb_Cmin = [.001,0,0.5,0.001];
        param_ub_Cmin = [1000,1,10,10];
        param_lb_Cavg = [.001,0];
        param_ub_Cavg = [1000,1];
        param_lb_Cmax = [.001,0,1.3];
        param_ub_Cmax  = [1000,1,10];
        % Model control normalization
        NormVal
        
        % Data in
        Growth_tbl;
        WOtbl;
        IC50tbl;
        IC50paramsSingle;
        IC50dataparams;
        IC50_doses= logspace(-1,3,10)';
        effectiveDoses= [.9 3.4 8.3 33]';
        WashoutParamsInSingle;
        WashoutDataParams;
        nDoses;
        nTime;
        Durations = [4,8,24,48,72]';
        
        % Fitting options
        fitOptions = optimoptions('lsqcurvefit','Display','iter');
        WeightBothFitVal=false; % Option to weight the washout and IC50 data during fitting both
        
        % plotting handle holders
        dataPlotHand = {{},{},{};{},{},{}};
        manualPlotHand = {{},{},{};{},{},{}};
        IC50fitPlotHand = {{},{},{};{},{},{}};
        WOfitPlotHand = {{},{},{};{},{},{}};
        bothfitPlotHand = {{},{},{};{},{},{}};
        growthPlotHandleGroup={};
        
        % plotting options
        colors;
        DataMarkerSize = 5;
        DisplIndData = true;
        nSmoothPoints = 40; % number of washout times used to get smooth simulation values
        maxDoseFoldIncr = 50; % sets the lower bound of the washout time for the smooth simulated washout 
        ColormapSel = 'copper';
        
        % Fit results container
        fitRes=cell(3,3);
        
        fitParamNames = {'Cmin_IC50';'Cmin_frac_nr';'Cmin_h';'AUC_S';'AUC_frac_nr';'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
        
        
        fitParamResults_IC50 = table(zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),...
            'VariableNames',{'fit_value','param_CI_lb','param_CI_ub','inital_param_value','lower_lim','upper_lim','AIC','BIC'},...
            'RowNames',{'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax';'AUC_S';'AUC_frac_nr';'Cmax_S';'Cmax_n';'Cmax_frac_nr'});
        fitParamResults_WOonly = table(zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),...
            'VariableNames',{'fit_value','param_CI_lb','param_CI_ub','inital_param_value','lower_lim','upper_lim','AIC','BIC'},...
            'RowNames',{'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax';'AUC_S';'AUC_frac_nr';'Cmax_S';'Cmax_n';'Cmax_frac_nr'});
        fitParamResults_both = table(zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),zeros(9,1),...
            'VariableNames',{'fit_value','param_CI_lb','param_CI_ub','inital_param_value','lower_lim','upper_lim','AIC','BIC'},...
            'RowNames',{'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax';'AUC_S';'AUC_frac_nr';'Cmax_S';'Cmax_n';'Cmax_frac_nr'});
        
        fitParamResults_growth = table(zeros(4,1),zeros(4,1),zeros(4,1),zeros(4,1),...
            'VariableNames',{'fit_value','param_CI_lb','param_CI_ub','inital_param_value'},...
            'RowNames',{'Growth_Rate';'Carrying_Capacity';'Seeding_Density';'Simulation_end_time'});
        growthFit_simRes;
        
        % Report properties
        ReportName;
        ReportPath;
        
        % Compound Metadata
        CompNum = ''; % Compound number
        CompName = ''; % compound generic name if avail.
        CompTarg = ''; % inhibition target of compound
        
        % Experimental Metadata
        CellType = ''; % Cell type measured in
        ExptSource = ''; % Where the expt was performed e.g. internal or CRO name
        ExptDates = ''; % Dates where expt was performed
        ExptNumRep = 1; % Number of independent experiments performed (i.e. number of repeated plates)
        
        % Final Decision Information
        FinalPKdriver  = 'TBD'; % decision on PK driver
        FinalResultConf = 0; % Confidence level on final choice 0 - TBD, 1 - Low conf, 2 - Medium-low conf, 3 - Medium conf, 4 - Medium-high conf 5 - High conf
        FinalComm = ''; % Comments on final decision
    end
    
    methods (Access = private)
        
        function [washout_modelCurves,IC50_modelCurves,washout_modelCurves_resamp,resampleDurationRange]= updateSimAndPlot(app,modelType,plotGroup)            
            %%%% Simulate an ODE model and update plots
            % Inputs:
            %   modelType - (string) Defines whether to run the Cmin,
            %       Cave or Cmax ODE model
            %   plotGroup - (string) Specifies how to store line handles 
            %       and display properties (Parameters have already been
            %       saved in the app struct before entering this fcn)
            %       manual - uses values from input fields on 'Simulation
            %           plots' Tab
            %       IC50only - uses fit result values when model was fit
            %           only to the IC50 data
            %       WOonly - uses fit result values when model was fit
            %           only to the washout data
            %       both - uses fit result values when model was fit
            %           simultaneously to both washout and IC50 data
            %%%%

            % Extract simulation function, current active parameter sets,
            % IC50 and washout axes handles and which column of the app plot storage cell
            % parameter to save line handles in
            [simfunc,paramIn,~,~,IC50ax,WOax,handleCol] = modelSel(app,modelType);

            % Set plot variables based on parameter set (type of fit or
            % manual) is passed in by 'plotGroup'
            %   plotHandleGroup - Define app struct var to save line handles in 
            switch plotGroup
                case 'manual'
                    plotHandleGroup = 'manualPlotHand';
                    modelStr = 'Manual';
                    lineType = '-';
                    lineColor = 'k';
                    
                case 'IC50only'
                    plotHandleGroup = 'IC50fitPlotHand';
                    lineType = '-.';
                    modelStr = 'IC50 only fit';
                    lineColor = 'r';
                    
                case 'WOonly'
                    plotHandleGroup = 'WOfitPlotHand';
                    lineType = '--';
                    modelStr = 'WO only fit';
                    lineColor = 'g';
                    
                case 'both'
                    plotHandleGroup = 'bothfitPlotHand';
                    lineType = ':';
                    modelStr = 'Fit both';
                    lineColor = 'b';
            end
            
            % Generate more sim points for smoother curves
            % Define span of duration values
            nModelDur = app.nSmoothPoints; % Number of duration data points
            maxDoseFoldIncrease = app.maxDoseFoldIncr; % Set upper limit on how much higher the dose can be than no washout case
            minDur = max(app.Durations)/maxDoseFoldIncrease; % Calculate the minimum duration based on this max dose limit
            if min(app.Durations)<minDur
                % If the input data has shorter duration than this limit,
                % use the shortest duration from the provided data
                minDur = min(app.Durations);
            end
            % Generate duration values over span
            resampleDurationRange = linspace(minDur,max(app.Durations),nModelDur)';
            % Calculate concentration values for each duration value for
            % each AUC matched group 
            nEffDose = length(app.effectiveDoses); % number of AUC matched groups
            resampWOparamsIn = nan([nEffDose*nModelDur,1]);
            for k = 1:nEffDose
                for m =1:nModelDur
                    resampWOparamsIn(m+nModelDur*(k-1),1) = app.effectiveDoses(k)*app.t_run/resampleDurationRange(m);
                    resampWOparamsIn(m+nModelDur*(k-1),2) = resampleDurationRange(m);
                end                
            end
            
            % Run ODE model for these extended concentration and duration values
            model_washout_out_resamp = app.multiEndpointRunFn(paramIn,resampWOparamsIn,simfunc,app.NormVal);
            washout_modelCurves_resamp=reshape(model_washout_out_resamp',nModelDur,nEffDose);
            
            % Run ODE model for concentration and duration values that
            % exactly match the data washout values
            model_washout_out = app.multiEndpointRunFn(paramIn,app.WashoutParamsInSingle,simfunc,app.NormVal);
            washout_modelCurves=reshape(model_washout_out',app.nTime,app.nDoses);

            % Clear any washout plot lines of this group from plots and 
            % empty the washout line handle storage container
            if ~isempty(app.(plotHandleGroup){1,handleCol})
                cellfun(@(x) delete(x),app.(plotHandleGroup){1,handleCol});
                app.(plotHandleGroup){1,handleCol} = {};
            end

            % Plot washout simulation lines and store line handles
            handList = cell(app.nDoses,1);
            for m = 1:app.nDoses
                hOut = plot(WOax,resampleDurationRange,washout_modelCurves_resamp(:,m),lineType,'LineWidth',1.5,'Color',app.colors(m,:),...
                    'DisplayName',[modelStr,' - ',num2str(app.effectiveDoses(m))]);
                hold(WOax,'on');
                handList{m} = hOut;
            end
            ylim(WOax,[-.1 1.1]);
            app.(plotHandleGroup){1,handleCol} =handList;
            
            % Clear any IC50 plot lines of this group from plots and 
            % empty the IC50 line handle storage container
            if ~isempty(app.(plotHandleGroup){2,handleCol})
                cellfun(@(x) delete(x),app.(plotHandleGroup){2,handleCol});
                app.(plotHandleGroup){2,handleCol} = {};
            end
            
            % Run the ODE model for the IC50 (no washout) plots
            IC50_modelCurves=app.multiEndpointRunFn(paramIn,app.IC50paramsSingle,simfunc,app.NormVal);

            % Plot IC50 simulation line and store line handle
            hOut=plot(IC50ax,app.IC50_doses,IC50_modelCurves,lineColor,'LineWidth',1.5,'DisplayName',modelStr);
            app.(plotHandleGroup){2,handleCol} ={hOut};
            ylim(IC50ax,[-.1 1.1]);
            IC50ax.XScale='log';
            
            % Function to update line visability (helps push app to update
            % plots)
            updatePlotVis(app);
        end
        
        function fitData(app,modelType,fit_to)
            %%%% Fit ODE model to washout and/or IC50 data
            % Inputs:
            %   modelType - (string) Defines whether to fit using the Cmin,
            %       Cave or Cmax ODE model
            %   fit_to - (string) Defines what dataset to fit model
            %       parameters against
            %       IC50only - fit only to the IC50 data
            %       WOonly - fit only to the washout data
            %       both - fit simultaneously to both washout and IC50 data
            %%%%

            % Check that IC50 and washout data has been loaded into the app
            if isempty(app.IC50tbl) || isempty(app.WOtbl)
                baseException = MException('exploreTargetCov:Fitting','Can not perform fit: data has not been imported');                
                throw(baseException);                
            end
            
            % Extract simulation function, initial parameter values,
            % parameter upper and lower bounds and which column of the app plot storage cell
            % parameter to save line handles in for given ODE model type
            [simfunc,paramIn,param_lb,param_ub,~,~,handleCol] = modelSel(app,modelType);
            
            % Fit model to specified data type
            switch fit_to
                case 'IC50only'
                    % Run LSq fit to just IC50 data
                    [paramOut,RSS,resid,exitflag,~,~,J] = lsqcurvefit(@(p,conds) app.multiEndpointRunFn(p,conds,simfunc,app.NormVal),...
                        paramIn,app.IC50dataparams,app.IC50tbl.NormCell,param_lb,param_ub,app.fitOptions);
                    
                    % Separately run washout and IC50 ODE values to collect
                    % residuals                    
                    % Washout residuals
                    model_washout_out = app.multiEndpointRunFn(paramOut,app.WashoutDataParams,simfunc,app.NormVal);                    
                    residWO = model_washout_out - app.WOtbl.NormCell;
                    compWO = [model_washout_out,app.WOtbl.NormCell];
                    % IC50 residuals
                    model_IC50_out = app.multiEndpointRunFn(paramOut,app.IC50dataparams,simfunc,app.NormVal);                    
                    residIC50 = model_IC50_out - app.IC50tbl.NormCell;
                    compIC50 = [model_IC50_out,app.IC50tbl.NormCell];

                    % Update plot display check box to turn on visability
                    % for this data fit set
                    app.IC50fitCheckBox.Value = true;
                    % Change the residual comparison selection button on
                    % the 'Residuals' tab to be selected for this model
                    app.IC50onlyfitResidButton.Value = true;

                    % Specify what row number to save the fit results in
                    saveFitRes_rowNum = 1; 
                    
                case 'WOonly'
                    % Run LSq fit to just washout data
                    [paramOut,RSS,resid,exitflag,~,~,J] = lsqcurvefit(@(p,conds) app.multiEndpointRunFn(p,conds,simfunc,app.NormVal),...
                        paramIn,app.WashoutDataParams,app.WOtbl.NormCell,param_lb,param_ub,app.fitOptions);
                    
                    % Separately run washout and IC50 ODE values to collect
                    % residuals                    
                    % Washout residuals
                    model_washout_out = app.multiEndpointRunFn(paramOut,app.WashoutDataParams,simfunc,app.NormVal);                    
                    residWO = model_washout_out - app.WOtbl.NormCell;
                    compWO = [model_washout_out,app.WOtbl.NormCell];
                    % IC50 residuals
                    model_IC50_out = app.multiEndpointRunFn(paramOut,app.IC50dataparams,simfunc,app.NormVal);                    
                    residIC50 = model_IC50_out - app.IC50tbl.NormCell;
                    compIC50 = [model_IC50_out,app.IC50tbl.NormCell];

                    % Update plot display check box to turn on visability
                    % for this data fit set
                    app.WashfitCheckBox.Value = true;
                    % Change the residual comparison selection button on
                    % the 'Residuals' tab to be selected for this model
                    app.WashoutonlyfitResidButton.Value = true;

                    % Specify what row number to save the fit results in
                    saveFitRes_rowNum = 2;
                case 'both'
                    % % Run LSq fit to both IC50 and washout data

                    if app.WeightBothFit.Value==false
                        [paramOut,RSS,resid,exitflag,~,~,J] = lsqcurvefit(@(p,conds) app.multiEndpointRunFn(p,conds,simfunc,app.NormVal),...
                            paramIn,[app.WashoutDataParams;app.IC50dataparams],[app.WOtbl.NormCell;app.IC50tbl.NormCell],param_lb,param_ub,app.fitOptions);
                    else
                        % Make differentiation Function
                        % Number of Washout data points
                        nWO = height(app.WOtbl.NormCell);
                        % Number of IC50 data points
                        nIC50 = height(app.IC50tbl.NormCell);

                        weightedFitFun = @(p) [(app.multiEndpointRunFn(p,app.WashoutDataParams,simfunc,app.NormVal)-app.WOtbl.NormCell)/nWO;(app.multiEndpointRunFn(p,app.IC50dataparams,simfunc,app.NormVal)-app.IC50tbl.NormCell)/nIC50];

                        % weightedFitFun = @(p) [(app.multiEndpointRunFn(p,app.WashoutDataParams,simfunc,app.NormVal)-app.WOtbl.NormCell)*(nWO/(nWO+nIC50));(app.multiEndpointRunFn(p,app.IC50dataparams,simfunc,app.NormVal)-app.IC50tbl.NormCell)*(nIC50/(nWO+nIC50))];

                        weightedOpts = optimoptions('lsqnonlin','Display','iter');
                        [paramOut,RSS,resid,exitflag,~,~,J] = lsqnonlin(weightedFitFun,...
                            paramIn,param_lb,param_ub,weightedOpts);
                    end
                    % Separately run washout and IC50 ODE values to collect
                    % residuals                    
                    % Washout residuals
                    model_washout_out = app.multiEndpointRunFn(paramOut,app.WashoutDataParams,simfunc,app.NormVal);                    
                    residWO = model_washout_out - app.WOtbl.NormCell;
                    compWO = [model_washout_out,app.WOtbl.NormCell];
                    % IC50 residuals
                    model_IC50_out = app.multiEndpointRunFn(paramOut,app.IC50dataparams,simfunc,app.NormVal);                    
                    residIC50 = model_IC50_out - app.IC50tbl.NormCell;
                    compIC50 = [model_IC50_out,app.IC50tbl.NormCell];

                    % Update plot display check box to turn on visability
                    % for this data fit set
                    app.BothfitCheckBox.Value = true;
                    % Change the residual comparison selection button on
                    % the 'Residuals' tab to be selected for this model
                    app.FitbothResidButton.Value = true;

                    % Specify what row number to save the fit results in
                    saveFitRes_rowNum = 3;
            end
            nData = length(resid); % Number of data points used in the fitting (for AIC/BIC calcualtion)
            paramCI = nlparci(paramOut,resid,'jacobian',J); % Calculate parameter confidence interval using gaussian assumption (Could add bootstrapping here)

            % save fit results for plotting residuals and updating result
            % tables
            fitResCell ={paramOut,paramIn,residWO,compWO,paramCI,exitflag,[],RSS,nData,param_lb,param_ub,residIC50,compIC50};            
            app.fitRes{saveFitRes_rowNum,handleCol} = fitResCell;

            % Update fit result tables
            app.updateTables;
            % Change editable parameter value boxes to fitted parameter
            % values
            app.updateParams(modelType,paramOut);
            % Generate smoother fit results and plot
            app.updateSimAndPlot(modelType,fit_to);
        end
        
        function plotData(app)
            %%%% Plots the washout and IC50 data after import or changing
            %%%% options

            % Activate the check box on the 'Simulation' tab to plot the
            % data
            app.DataCheckBox.Value = true;

            % Update the data tables
            app.IC50datatable.Data = app.IC50tbl;
            app.WOdatatable.Data = app.WOtbl;

            % Plot the data points on all the washout plots
            WOaxes = {app.CminWOax,app.CavgWOax,app.CmaxWOax};
            for k = 1:length(WOaxes)
                % Clear any previous data plotted on this axis
                if ~isempty(app.dataPlotHand{1,k})
                    cellfun(@(x) delete(x),app.dataPlotHand{1,k});
                end

                % Plot each AUC matched group a different color
                handList = cell(app.nDoses,1);
                for m = 1:app.nDoses
                    % Select data within a AUC matched dose group
                    dosegrp = find(app.WOtbl.EffDose == app.effectiveDoses(m));
                    
                    % Plot as either individual data points or mean with
                    % SEM
                    if app.DisplIndData == true
                        % Plot each individual value
                        hOut = plot(WOaxes{k},app.WOtbl.Duration(dosegrp),app.WOtbl.NormCell(dosegrp),'o','MarkerSize',app.DataMarkerSize,...
                            'MarkerEdgeColor',app.colors(m,:),'MarkerFaceColor',app.colors(m,:),...
                            'DisplayName',num2str( app.effectiveDoses(m)));
                    else
                        % Plot as mean with SEM
                        [DurVals,~,DurInd]=unique(app.WOtbl.Duration(dosegrp));
                        meanVals = zeros(size(DurVals));
                        errVals = zeros(size(DurVals));
                        
                        for n = 1:length(DurVals)
                            meanVals(n) = mean(app.WOtbl.NormCell(dosegrp(DurInd==n)));
                            errVals(n) = std(app.WOtbl.NormCell(dosegrp(DurInd==n)))/sqrt(sum(DurInd==n));
                        end
                        
                        hOut = errorbar(WOaxes{k},DurVals,meanVals,errVals,'Marker','o','MarkerSize',app.DataMarkerSize,...
                            'MarkerEdgeColor',app.colors(m,:),'MarkerFaceColor',app.colors(m,:),...
                            'DisplayName',num2str( app.effectiveDoses(m)),'LineStyle','none','Color','k');
                    end
                    hold(WOaxes{k},'on');
                    handList{m} = hOut;
                end
                app.dataPlotHand{1,k} = handList;
                
                % Add legend to furthest right plot
                if k == length(WOaxes)
                    legend(WOaxes{k},'Location','eastoutside')
                    axis(WOaxes{k},'square')
                end
            end
            
            % Plot the data points on all the IC50 plots
            ICaxes = {app.CminIC50ax,app.CavgIC50ax,app.CmaxIC50ax};
            for k = 1:length(ICaxes)
                % Clear any previous data plotted on this axis
                if ~isempty(app.dataPlotHand{2,k})
                    cellfun(@(x) delete(x),app.dataPlotHand{2,k});
                end

                % cell array to store data plot handles
                handList = cell(app.nDoses+1,1);
                hold(ICaxes{k},'on'); % Add plots on top of eachother
                % Add vertical dotted lines where the effective dose is for
                % each AUC matched group
                for m = 1:app.nDoses
                    hOut = plot(ICaxes{k},[app.effectiveDoses(m) app.effectiveDoses(m)],[-0.1 1.1],'--',...
                        'Color',app.colors(m,:),'DisplayName',num2str( app.effectiveDoses(m)));
                    handList{m} = hOut;
                end

                % Plot the IC50 data either as individual data points or
                % mean with SEM
                if app.DisplIndData == true
                    % Plot each individual value
                    hOut = plot(ICaxes{k},app.IC50tbl.Dose,app.IC50tbl.NormCell,'ok','MarkerSize',app.DataMarkerSize,'DisplayName','Expt Data');
                else
                    % Plot as mean with SEM error bars
                    [DoseVals,~,DoseInd]=unique(app.IC50tbl.Dose);
                    meanVals = zeros(size(DoseVals));
                    errVals = zeros(size(DoseVals));
                    
                    for n = 1:length(DoseVals)
                        meanVals(n) = mean(app.IC50tbl.NormCell(DoseInd==n));
                        errVals(n) = std(app.IC50tbl.NormCell(DoseInd==n))/sqrt(sum(DoseInd==n));
                    end
                    hOut = errorbar(ICaxes{k},DoseVals,meanVals,errVals,'ok','MarkerSize',app.DataMarkerSize,'DisplayName','Expt Data');
                    
                end
                handList{app.nDoses+1} = hOut;
                app.dataPlotHand{2,k} = handList;

                % Add legend to furthest right plot
                if k == length(ICaxes)
                    legend(ICaxes{k},'Location','eastoutside')
                    axis(ICaxes{k},'square')
                end
            end
        end
        
        function [simfunc,paramIn,param_lb,param_ub,IC50ax,WOax,handleCol] = modelSel(app,modelType)
            %%%% Extract ODE model functions, parameters and axes
            % Input:
            %   modelType - (string) Defines what ODE model to gather info
            %       for: Cmin, Cave or Cmax ODE model
            % Outputs:
            %   simfunc - (function handle) function handle for given ODE
            %       model
            %   paramIn - (vector) parameter values currently saved in the
            %       app struct
            %   param_lb - (vector) parameter lower bounds. Changed here if
            %       values are set as locked.
            %   param_ub - (vector) parameter upper bounds. Changed here if
            %       values are set as locked.
            %   IC50ax - (pointer) app identifier for IC50 axes for selected model 
            %   WOax - (pointer) app identifier for washout axes for selected model 
            %   handleCol - (double) column value to save plot handles 
            %%%%

            switch modelType
                case 'Cmin'
                    simfunc=app.simfunc_Cmin;
                    paramIn = [app.IC50_Cmin,app.frac_nr_Cmin,app.n_Cmin,app.Emax_Cmin];
                    % Get the parameter boundaries
                    param_lb = app.param_lb_Cmin;
                    param_ub = app.param_ub_Cmin;
                    
                    % If 'locked' checkbox is selected, set boundaries to 
                    % matched value so that it is fixed at that value while
                    % fitting
                    if app.IC50lockBtn_Cmin.Value
                        param_lb(1) = app.IC50_Cmin;
                        param_ub(1) = app.IC50_Cmin;
                    end
                    if app.fracNRlockBtn_Cmin.Value
                        param_lb(2) = app.frac_nr_Cmin;
                        param_ub(2) = app.frac_nr_Cmin;
                    end
                    if app.nlockBtn_Cmin.Value
                        param_lb(3) = app.n_Cmin;
                        param_ub(3) = app.n_Cmin;
                    end
                    if app.EmaxLockBtn_Cmin.Value
                        param_lb(4) = app.Emax_Cmin;
                        param_ub(4) = app.Emax_Cmin;
                    end

                    % Plot handles
                    IC50ax = app.CminIC50ax;
                    WOax = app.CminWOax;
                    handleCol = 1;
                case 'Cavg'
                    simfunc=app.simfunc_Cavg;
                    paramIn = [app.IC50_Cavg,app.frac_nr_Cavg];
                    % Get the parameter boundaries
                    param_lb = app.param_lb_Cavg;
                    param_ub = app.param_ub_Cavg;
                    
                    % If 'locked' checkbox is selected, set boundaries to 
                    % matched value so that it is fixed at that value while
                    % fitting
                    if app.IC50lockBtn_Cavg.Value
                        param_lb(1) = app.IC50_Cmin;
                        param_ub(1) = app.IC50_Cmin;
                    end
                    if app.fracNRlockBtn_Cavg.Value
                        param_lb(2) = app.frac_nr_Cavg;
                        param_ub(2) = app.frac_nr_Cavg;
                    end
                    
                    % Plot handles
                    IC50ax = app.CavgIC50ax;
                    WOax = app.CavgWOax;
                    handleCol = 2;                    
                case 'Cmax'
                    simfunc=app.simfunc_Cmax;
                    paramIn = [app.IC50_Cmax,app.frac_nr_Cmax,app.n_Cmax];
                    % Get the parameter boundaries
                    param_lb = app.param_lb_Cmax;
                    param_ub = app.param_ub_Cmax;
                    
                    % If 'locked' checkbox is selected, set boundaries to 
                    % matched value so that it is fixed at that value while
                    % fitting
                    if app.IC50lockBtn_Cmax.Value
                        param_lb(1) = app.IC50_Cmax;
                        param_ub(1) = app.IC50_Cmax;
                    end
                    if app.fracNRlockBtn_Cmax.Value
                        param_lb(2) = app.frac_nr_Cmax;
                        param_ub(2) = app.frac_nr_Cmax;
                    end
                    if app.nlockBtn_Cmax.Value
                        param_lb(3) = app.n_Cmax;
                        param_ub(3) = app.n_Cmax;
                    end

                    % Plot handles
                    IC50ax = app.CmaxIC50ax;
                    WOax = app.CmaxWOax;
                    handleCol = 3;                    
            end
        end
        
        function updateParams(app,modelType,paramIn)
            %%%% Update model parameter values in app struct and editable
            %%%% fields
            % Inputs:
            %   modelType - (string) Defines what ODE model to save params
            %       for: Cmin, Cave or Cmax ODE model
            %   paramIn - (vector) Parameters to save/update for a given
            %       model
            %%%%
            switch modelType
                case 'Cmin'
                    % Save params to app struct
                    app.IC50_Cmin =paramIn(1);
                    app.frac_nr_Cmin=paramIn(2);
                    app.n_Cmin=paramIn(3);
                    app.Emax_Cmin=paramIn(4);
                    % Update editable field values
                    app.IC50EditField_Cmin.Value = paramIn(1);
                    app.fracnonrespEditField_Cmin.Value = paramIn(2);
                    app.nEditField_Cmin.Value = paramIn(3);
                    app.EmaxEditField_Cmin.Value = paramIn(4);
                case 'Cavg'
                    % Save params to app struct
                    app.IC50_Cavg=paramIn(1);
                    app.frac_nr_Cavg=paramIn(2);
                    % Update editable field values
                    app.IC50EditField_Cavg.Value = paramIn(1);
                    app.fracnonrespEditField_Cavg.Value = paramIn(2);
                    
                case 'Cmax'
                    % Save params to app struct
                    app.IC50EditField_Cmax.Value = paramIn(1);
                    app.fracnonrespEditField_Cmax.Value = paramIn(2);
                    app.nEditField_Cmax.Value = paramIn(3);
                    % Update editable field values
                    app.IC50_Cmax= paramIn(1);
                    app.frac_nr_Cmax = paramIn(2);
                    app.n_Cmax = paramIn(3);
            end
        end


        function updatePlotVis(app)
            
            for k = 1:6
                cellfun(@(x)set(x,'Visible',app.DataCheckBox.Value),app.dataPlotHand{k});
                cellfun(@(x)set(x,'Visible',app.ManualCheckBox.Value),app.manualPlotHand{k});
                cellfun(@(x)set(x,'Visible',app.IC50fitCheckBox.Value),app.IC50fitPlotHand {k});
                cellfun(@(x)set(x,'Visible',app.WashfitCheckBox.Value),app.WOfitPlotHand{k});
                cellfun(@(x)set(x,'Visible',app.BothfitCheckBox.Value),app.bothfitPlotHand{k});
            end
        end
        
        function updateResiduals(app)
            
            switch app.SelResButtonGroup.SelectedObject
                case app.IC50onlyfitResidButton
                    rowNum = 1;
                    
                case app.WashoutonlyfitResidButton
                    rowNum = 2;
                    
                case app.FitbothResidButton
                    rowNum = 3;
                    
            end
            
            residAx_WO = {app.CminResidAx,app.CavgResidAx,app.CmaxResidAx};
            residAx_IC50 = {app.CminResidAx_IC50,app.CavgResidAx_IC50,app.CmaxResidAx_IC50};
            for k = 1:3
                fitResOut = app.fitRes{rowNum,k};
                if ~isempty(fitResOut)
                    [~,~,~,compWO,~,~,~,~,~,~,~,~,compIC50]=fitResOut{:};
                    plot(residAx_WO{k},compWO(:,1),compWO(:,2),'b*',[-.1 1.1],[-.1 1.1],':k');
                    axis(residAx_WO{k},'equal');
                    plot(residAx_IC50{k},compIC50(:,1),compIC50(:,2),'b*',[-.1 1.1],[-.1 1.1],':k');
                    axis(residAx_IC50{k},'equal');
                else
                    cla(residAx_WO{k});
                    cla(residAx_IC50{k});
                end
            end
            
        end
        
        function updateTables(app)
            fitResOut = app.fitRes;
            
            for fitTo = 1:3
                for modelType = 1:3
                    fitData = fitResOut{fitTo,modelType};
                    %                     fitResCell ={paramOut,paramIn,residWO,compWO,paramCI,exitflag};
                    if ~isempty(fitData)
                        switch modelType
                            case 1
                                fitResVar = {'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax'};
                                fitResInds = [1,3,2,4];
                            case 2
                                fitResVar = {'AUC_S';'AUC_frac_nr'};
                                fitResInds = [1,2];
                            case 3
                                fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                                fitResInds = 1:3;
                        end
                        fitModel = fitData{7};
                        switch fitTo
                            case 1
                                % IC50 only
                                app.fitParamResults_IC50(fitResVar,:).fit_value =fitData{1}(fitResInds)';
                                app.fitParamResults_IC50(fitResVar,:).inital_param_value =fitData{2}(fitResInds)';
                                CI = fitData{5};
                                app.fitParamResults_IC50(fitResVar,:).param_CI_lb =CI(fitResInds,1);
                                app.fitParamResults_IC50(fitResVar,:).param_CI_ub =CI(fitResInds,2);
                                
                                app.fitParamResults_IC50(fitResVar,:).lower_lim =fitData{10}(fitResInds)';
                                app.fitParamResults_IC50(fitResVar,:).upper_lim =fitData{11}(fitResInds)';
                                %                                 app.fitParamResults_IC50(fitResVar,:).fit_value_unb =fitModel.Coefficients.Estimate;
                                %                                 CI =  coefCI(fitModel);
                                %                                 app.fitParamResults_IC50(fitResVar,:).param_CI_lb_unb =CI(fitResInds,1);
                                %                                 app.fitParamResults_IC50(fitResVar,:).param_CI_ub_unb =CI(fitResInds,2);
                                %                                 app.fitParamResults_IC50(fitResVar(1),:).AIC =fitModel.ModelCriterion.AIC;
                                %                                 app.fitParamResults_IC50(fitResVar(1),:).BIC =fitModel.ModelCriterion.BIC;
                                

                                RSSlsqcurvefit = fitData{8};
                                nData = fitData{9};
                                
                                % Number of parameters
                                nLockedParams = sum(fitData{10}(fitResInds)'==fitData{11}(fitResInds)');
                                k = length(fitResVar)-nLockedParams;
                                % Calculate AIC and BIC
                                AIC = 2*k+nData*log(RSSlsqcurvefit/nData);
                                AICc = AIC+2*k*(k+1)/(nData-k-1);
                                BIC = nData*log(RSSlsqcurvefit/nData) + k*log(nData);
                                app.fitParamResults_IC50(fitResVar(1),:).AIC = AICc;
                                app.fitParamResults_IC50(fitResVar(1),:).BIC = BIC;
                            case 2
                                %WO only
                                app.fitParamResults_WOonly(fitResVar,:).fit_value =fitData{1}(fitResInds)';
                                app.fitParamResults_WOonly(fitResVar,:).inital_param_value =fitData{2}(fitResInds)';
                                CI = fitData{5};
                                app.fitParamResults_WOonly(fitResVar,:).param_CI_lb =CI(fitResInds,1);
                                app.fitParamResults_WOonly(fitResVar,:).param_CI_ub =CI(fitResInds,2);
                                
                                app.fitParamResults_WOonly(fitResVar,:).lower_lim =fitData{10}(fitResInds)';
                                app.fitParamResults_WOonly(fitResVar,:).upper_lim =fitData{11}(fitResInds)';
                                
                                %                                 app.fitParamResults_WOonly(fitResVar,:).fit_value_unb =fitModel.Coefficients.Estimate;
                                %                                 CI =  coefCI(fitModel);
                                %                                 app.fitParamResults_WOonly(fitResVar,:).param_CI_lb_unb =CI(fitResInds,1);
                                %                                 app.fitParamResults_WOonly(fitResVar,:).param_CI_ub_unb =CI(fitResInds,2);
                                %                                 app.fitParamResults_WOonly(fitResVar(1),:).AIC =fitModel.ModelCriterion.AIC;
                                %                                 app.fitParamResults_WOonly(fitResVar(1),:).BIC =fitModel.ModelCriterion.BIC;
                                RSSlsqcurvefit = fitData{8};
                                nData = fitData{9};
                                
                                % Number of parameters
                                nLockedParams = sum(fitData{10}(fitResInds)'==fitData{11}(fitResInds)');
                                k = length(fitResVar)-nLockedParams;
                                % Calculate AIC and BIC
                                AIC = 2*k+nData*log(RSSlsqcurvefit/nData);
                                AICc = AIC+2*k*(k+1)/(nData-k-1);
                                BIC = nData*log(RSSlsqcurvefit/nData) + k*log(nData);
                                app.fitParamResults_WOonly(fitResVar(1),:).AIC = AICc;
                                app.fitParamResults_WOonly(fitResVar(1),:).BIC = BIC;
                            case 3
                                % both
                                app.fitParamResults_both(fitResVar,:).fit_value =fitData{1}(fitResInds)';
                                app.fitParamResults_both(fitResVar,:).inital_param_value =fitData{2}(fitResInds)';
                                CI = fitData{5};
                                app.fitParamResults_both(fitResVar,:).param_CI_lb =CI(fitResInds,1);
                                app.fitParamResults_both(fitResVar,:).param_CI_ub =CI(fitResInds,2);
                                
                                app.fitParamResults_both(fitResVar,:).lower_lim =fitData{10}(fitResInds)';
                                app.fitParamResults_both(fitResVar,:).upper_lim =fitData{11}(fitResInds)';
                                
                                %                                 app.fitParamResults_both(fitResVar,:).fit_value_unb =fitModel.Coefficients.Estimate;
                                %                                 CI =  coefCI(fitModel);
                                %                                 app.fitParamResults_both(fitResVar,:).param_CI_lb_unb =CI(fitResInds,1);
                                %                                 app.fitParamResults_both(fitResVar,:).param_CI_ub_unb =CI(fitResInds,2);
                                %                                 app.fitParamResults_both(fitResVar(1),:).AIC =fitModel.ModelCriterion.AIC;
                                %                                  app.fitParamResults_both(fitResVar(1),:).BIC =fitModel.ModelCriterion.BIC;
                                
                                RSSlsqcurvefit = fitData{8};
                                nData = fitData{9};
                                
                                % Number of parameters
                                nLockedParams = sum(fitData{10}(fitResInds)'==fitData{11}(fitResInds)');
                                k = length(fitResVar)-nLockedParams;
                                % Calculate AIC and BIC
                                AIC = 2*k+nData*log(RSSlsqcurvefit/nData);
                                AICc = AIC+2*k*(k+1)/(nData-k-1);
                                BIC = nData*log(RSSlsqcurvefit/nData) + k*log(nData);
                                app.fitParamResults_both(fitResVar(1),:).AIC = AICc;
                                app.fitParamResults_both(fitResVar(1),:).BIC = BIC;
                                
                        end
                        
                    end
                    
                end
            end
            
            set(app.FitData_IC50fit,'Data',app.fitParamResults_IC50);
            app.FitData_WOfit.Data = app.fitParamResults_WOonly;
            %             app.FitData_bothfit.Data = app.fitParamResults_both;
            set(app.FitData_bothfit,'Data',app.fitParamResults_both);
            % app.FitData_IC50fit.Visible = 'off';
            % app.FitData_IC50fit.Visible = 'on';
            
        end
        
        function applyGrowthParams(app,baseParams)
            if ~isempty(baseParams)
                app.Growth = baseParams(1);
                app.InitSeeding =  baseParams(2); % percent confluent
                app.Volmax =  baseParams(3); % carrying capacity
                app.t_run =  baseParams(4); % carrying capacity
            end
            
            app.GrowthEditField.Value = app.Growth;
            app.SeedingEditField.Value = app.InitSeeding;
            app.CarryingCapacityEditField.Value = app.Volmax;
            app.SimulationendtimeEditField.Value = app.t_run;
            app.fraccarryoverafterwashoutEditField.Value = app.CarryOver;
            app.degradationrateEditField.Value = app.CompDeg;
            
        end
        
        function GenSimFunc(app)
            
            f=waitbar(0,'Updating base parameters...');
            %%%% Cmin
            % Get parameter pointers
            Drug_Cmin = sbioselect(app.m_Cmin,'name','Drug');
            
            % Set Parameter values
            set(Drug_Cmin,'Value',0);
            
            csObj = getconfigset(app.m_Cmin,'active');
            set(csObj,'Stoptime',app.t_run);
            
            %%%% Cavg
            % Get parameter pointers
            Drug_Cavg= sbioselect(app.m_Cavg,'name','Drug');
            
            % Set Parameter values
            set(Drug_Cavg,'Value',0);
            
            csObj = getconfigset(app.m_Cavg,'active');
            set(csObj,'Stoptime',app.t_run);
            
            %%%% Cmax
            % Get parameter pointers
            Drug_Cmax = sbioselect(app.m_Cmax,'name','Drug');
            
            % Set Parameter values
            set(Drug_Cmax,'Value',0);
            
            csObj = getconfigset(app.m_Cmax,'active');
            set(csObj,'Stoptime',app.t_run);
            
            
            %             % Simulate to end time with no drugs
            %             [~,x_ctrl,~]=sbiosimulate(app.m_Cmin);
            %             Tumor_ctrl = x_ctrl(:,1);
            %             app.NormVal = Tumor_ctrl(end);
            
            waitbar(1/4,f,'Accelerating Cmin model...');
            %%
            %%%%%% Cmin
            % Define what parameters to fit in the Cmin model (growth parameters should
            % be fixed after fitting to the growth curve)
            fitParamNames_Cmin = {'IC50','frac_nr','n','Imax'};
            app.IC50EditField_Cmin.Value = app.IC50_Cmin;
            app.nEditField_Cmin.Value = app.n_Cmin;
            app.fracnonrespEditField_Cmin.Value = app.frac_nr_Cmin;
            app.EmaxEditField_Cmin.Value = app.Emax_Cmin;
            %             param0_Cmin = [app.IC50_Cmin,app.frac_nr_Cmin,app.n_Cmin];
            %             param_lb_Cmin = [.001,0,0.5];
            %             param_ub_Cmin = [1000,1,10];
            
            
            % add the parameters that are being fitted
            paramNames_Cmin = [app.growthParamNames,app.paramNames,fitParamNames_Cmin];
            % Create the simulation function using the model that is passed in
            simfunc_Cmin = createSimFunction(app.m_Cmin,paramNames_Cmin,app.observables,[]);
            
            % Accelerate the SimFunction
            accelerate(simfunc_Cmin);
            
            waitbar(1/2,f,'Accelerating Cavg model...');
            %%%%%% Cavg
            % Define what parameters to fit in the Cavg model (growth parameters should
            % be fixed after fitting to the growth curve)
            
            fitParamNames_Cavg = {'IC50','frac_nr'};
            app.IC50EditField_Cavg.Value = app.IC50_Cavg;
            app.fracnonrespEditField_Cavg.Value = app.frac_nr_Cavg;
            %             param0_Cavg = [app.IC50_Cavg,1,app.frac_nr_Cavg];
            %             param_lb_Cavg = [.001,1,0];
            %             param_ub_Cavg = [1000,1,1];
            
            % add the parameters that are being fitted
            paramNames_Cavg = [app.growthParamNames,app.paramNames,fitParamNames_Cavg];
            
            % Create the simulation function using the model that is passed in
            simfunc_Cavg = createSimFunction(app.m_Cavg,paramNames_Cavg,app.observables,[]);
            
            % Accelerate the SimFunction
            accelerate(simfunc_Cavg);
            
            waitbar(3/4,f,'Accelerating Cmax model...');
            %%%%%% Cmax
            % Define what parameters to fit in the Cmax model (growth parameters should
            % be fixed after fitting to the growth curve)
            fitParamNames_Cmax = {'IC50','frac_nr','n'};
            app.IC50EditField_Cmax.Value = app.IC50_Cmax;
            app.nEditField_Cmax.Value = app.n_Cmax;
            app.fracnonrespEditField_Cmax.Value = app.frac_nr_Cmax;
            %             param0_Cmax = [app.IC50_Cmax,app.frac_nr_Cmax,app.n_Cmax];
            %             param_lb_Cmax = [.001,.05,1.3];
            %             param_ub_Cmax  = [1000,.05,10];
            
            % add the parameters that are being fitted
            paramNames_Cmax = [app.growthParamNames,app.paramNames,fitParamNames_Cmax];
            
            % Create the simulation function using the model that is passed in
            simfunc_Cmax = createSimFunction(app.m_Cmax,paramNames_Cmax,app.observables,[]);
            
            % Accelerate the SimFunction
            accelerate(simfunc_Cmax);
            
            app.simfunc_Cmin = simfunc_Cmin;
            app.simfunc_Cavg = simfunc_Cavg;
            app.simfunc_Cmax = simfunc_Cmax;
            
            f = waitbar(15/16,f,'Generating Initial simulations...');
            
            %%%% Simulate control growth
            app.simulateControlEndpoint;
            
            %%%% Cmin
            app.updateSimAndPlot('Cmin','manual');
            %%%% Cavg
            app.updateSimAndPlot('Cavg','manual');
            %%%% Cmax
            app.updateSimAndPlot('Cmax','manual');
            
            close(f);


        end
        
        function toggleEnable(app,setEnable)
            plotsChildren = app.SimulationPlotsTab.Children;
            residChildren = app.ResidualsTab.Children;
            fitChildren = app.FitParameterResultsTab.Children;
            dataChildren = app.DataTab.Children;
            
            set(findall(plotsChildren,'-property','Enable'),'Enable',setEnable);
            set(findall(residChildren,'-property','Enable'),'Enable',setEnable);
            set(findall(fitChildren,'-property','Enable'),'Enable',setEnable);
            set(findall(dataChildren,'-property','Enable'),'Enable',setEnable);
            
            switch setEnable
                case 'on'
                    app.SimulationPlotsTab.ForegroundColor = [0,0,0];
                    app.ResidualsTab.ForegroundColor = [0,0,0];
                    app.FitParameterResultsTab.ForegroundColor = [0,0,0];
                    app.DataTab.ForegroundColor = [0,0,0];
                    
                case 'off'
                    app.SimulationPlotsTab.ForegroundColor = [.5,.5,.5];
                    app.ResidualsTab.ForegroundColor = [.5,.5,.5];
                    app.FitParameterResultsTab.ForegroundColor = [.5,.5,.5];
                    app.DataTab.ForegroundColor = [.5,.5,.5];
            end
            
        end
        
        function pullOutPlots(app,axisgrp,plotopt)
            
            nAxis = length(axisgrp);
            if plotopt==1
                newF = figure;
            end
            
            for k = 1: nAxis
                % Gather all the children
                allChildren = allchild(axisgrp{k});
                
                if plotopt==1
                    % subplot
                    ax = subplot(1,nAxis,k);
                    
                    hnew = copyobj(allChildren,ax);
                    lineplotsh = findobj(hnew,'Type','Line');
                    if k == nAxis
                        legend(lineplotsh,'Location','bestoutside');
                        %                     hnew2 = copyobj(hLeg,ax);
                        
                    end
                    OldXLabel = get(axisgrp{k},'XLabel');
                    OldYLabel = get(axisgrp{k},'YLabel');
                    OldTitle = get(axisgrp{k},'Title');
                    OldXScale = get(axisgrp{k},'XScale');
                    %                     copyobj(OldXLabel,ax);
                    ax.XLabel.String = OldXLabel.String;
                    ax.YLabel.String = OldYLabel.String;
                    ax.Title.String = OldTitle.String;
                    ax.XScale = OldXScale;
                    
                else
                    % Individual plots
                    fignew = figure;
                    ax = axes;
                    hnew = copyobj(allChildren,ax);
                    lineplotsh = findobj(hnew,'Type','Line');
                    legend(lineplotsh,'Location','bestoutside');
                    %                 hnew = copyobj(axisgrp{k},fignew);
                    OldXLabel = get(axisgrp{k},'XLabel');
                    OldYLabel = get(axisgrp{k},'YLabel');
                    OldTitle = get(axisgrp{k},'Title');
                    OldXScale = get(axisgrp{k},'XScale');
                    ax.XLabel.String = OldXLabel.String;
                    ax.YLabel.String = OldYLabel.String;
                    ax.Title.String = OldTitle.String;
                    ax.XScale = OldXScale;
                    
                    
                end
            end
            
        end %pullOutPlots


        function output_y = multiEndpointRunFn(app,fitpar,condsIn,simfunc,normValues)
            % Function to generate IC50 curve from model with inputs of model
            % parameters to fit


            % number of conditions
            nCond = length(condsIn(:,1));
            % Add the growth parameters to the input matrix
            paramIn = ones(nCond,1)*[app.Growth,app.InitSeeding,app.Volmax,app.CompDeg,app.CarryOver];
            % Make parameter input matrix
            paramIn = [paramIn,condsIn];
            % number of base parameters
            numBaseParam = size(paramIn,2);
            % Add fitting parameters to matrix
            for k = 1:length(fitpar)
                paramIn(:,k+numBaseParam) = ones(nCond,1)*fitpar(k);
            end
            
            % Get IC50 doses
            [~,Tumor_out] = simfunc(paramIn,app.t_run);
            
            % Get end values
            endTumor = cellfun(@(x) x(end),Tumor_out);
            
            % Normalize to the untreated value
            output_y = endTumor./normValues;
            
            if any(~isfinite(output_y))
                output_y = ones(size(output_y))*1000000;
            end


        end
        
        function output_y = multiEndpointRunFn_wBounds(app,fitpar,condsIn,simfunc,normValues,param_lb,param_ub)
            % Function to generate IC50 curve from model with inputs of model
            % parameters to fit
            
            % define how long the simulation should run
            %                 t_run = 72;
            
            % number of conditions
            nCond = length(condsIn(:,1));
            % Add the growth parameters to the input matrix
            paramIn = ones(nCond,1)*[app.Growth,app.InitSeeding,app.Volmax,app.CompDeg,app.CarryOver];
            % Make parameter input matrix
            paramIn = [paramIn,condsIn];
            % number of base parameters
            numBaseParam = size(paramIn,2);
            % Check if the fitting parameters are out of bounds
            if (any(fitpar<param_lb) | any(fitpar>param_ub))
                % if the parameters are out of bounds then make it so it for sure wont
                % fit
                output_y = ones(nCond ,1)*1000000;
                
            else
                % Add fitting parameters to matrix
                
                for k = 1:length(fitpar)
                    paramIn(:,k+numBaseParam) = ones(nCond,1)*fitpar(k);
                end
                
                % Get IC50 doses
                [~,Tumor_out] = simfunc(paramIn,app.t_run);
                
                % Get end values
                endTumor = cellfun(@(x) x(end),Tumor_out);
                
                % Normalize to the untreated value
                output_y = endTumor./normValues;
                
                if any(~isfinite(output_y))
                    output_y = ones(size(output_y))*1000000;
                end
            end
        end
        
        function updateGrowthParam(app)
            % Get updated base parameters
            app.Growth = app.GrowthEditField.Value;
            app.InitSeeding = app.SeedingEditField.Value;
            app.Volmax =app.CarryingCapacityEditField.Value;
            app.t_run = app.SimulationendtimeEditField.Value;
            app.CompDeg = app.degradationrateEditField.Value;
            app.CarryOver = app.fraccarryoverafterwashoutEditField.Value;
            
            app.simulateControlEndpoint;
            
            app.toggleEnable('on');
            app.GenerateModelsButton.BackgroundColor = [0.81,0.90,0.67];
            app.UpdateModelSettingsButton.BackgroundColor = [0.81,0.90,0.67];
        end
        
        function simulateControlEndpoint(app)
            % create parameter matrix with 0 drug concentration and carrying out to full time
            paramIn = [app.Growth,app.InitSeeding,app.Volmax,app.CompDeg,app.CarryOver,0,app.t_run,app.IC50_Cmin,app.n_Cmin,app.frac_nr_Cmin,0];
            [~,Tumor_out] = app.simfunc_Cmin(paramIn,app.t_run);
            % Get end values
            endTumor = cellfun(@(x) x(end),Tumor_out);
            app.NormVal =endTumor;
        end
        
        function fitGrowthData(app)
            if any(app.Growth_tbl.Time == 0)==0 % Check and see if there is a time zero
                % no time zero shifting growth data.
                minTime = min(app.Growth_tbl.Time);
                app.Growth_tbl.Time = app.Growth_tbl.Time- minTime;
                warning('No time = 0 in growth data, shifting shortest time to be time=0');
            end

            InitSeedingData = mean(app.Growth_tbl.Perc_confl(app.Growth_tbl.Time == 0));
            %             timePoints = unique(app.Growth_tbl.Time);
            
            [paramOut,~,resid,exitflag,~,~,J] = lsqcurvefit(@(p,conds)app.runGrowthModel(p,conds,InitSeedingData),...
                [app.Growth,app.Volmax],app.Growth_tbl.Time,app.Growth_tbl.Perc_confl,[0,0],[10000000,10000000],app.fitOptions);
            
            paramCI = nlparci(paramOut,resid,'jacobian',J);
            
            app.fitParamResults_growth({'Growth_Rate';'Carrying_Capacity'},:).fit_value = paramOut';
            app.fitParamResults_growth({'Growth_Rate';'Carrying_Capacity'},:).param_CI_lb = paramCI(:,1);
            app.fitParamResults_growth({'Growth_Rate';'Carrying_Capacity'},:).param_CI_ub = paramCI(:,2);
            app.fitParamResults_growth({'Seeding_Density';'Simulation_end_time'},:).fit_value = [InitSeedingData;max(app.Growth_tbl.Time)];
            
            app.fitParamResults_growth({'Growth_Rate';'Carrying_Capacity';'Seeding_Density';'Simulation_end_time'},:).inital_param_value = [app.Growth;app.Volmax;app.InitSeeding;app.t_run];
            app.GrowthParamTable.Data = app.fitParamResults_growth;
            app.GrowthParamTable.RowName = app.fitParamResults_growth.Properties.RowNames;
            
            % Run Growth model to plot on axes
            tPoints = linspace(0,max(app.Growth_tbl.Time),50);
            yOut = app.runGrowthModel(paramOut,tPoints,InitSeedingData);
            
            if ~isempty(app.growthPlotHandleGroup)
                delete(app.growthPlotHandleGroup{1});
                app.growthPlotHandleGroup = {};
            end
            app.growthFit_simRes = [tPoints',yOut];
            hold(app.GrowthAx,'on');
            hOut = plot(app.GrowthAx,app.growthFit_simRes(:,1),app.growthFit_simRes(:,2),'r','LineWidth',2,'DisplayName','Growth Fit');
            app.growthPlotHandleGroup = {hOut};
            
        end
        
        function yOut = runGrowthModel(app,p,timeIn,InitSeeding)
            
            uniqueTimes = unique(timeIn);
            paramIn = [p(1),InitSeeding,p(2),0,0,0,app.t_run,app.IC50_Cmin,app.n_Cmin,app.frac_nr_Cmin,app.Emax_Cmin];
            [tMonotonic,yMonotonic] = app.simfunc_Cmin(paramIn,[],[],uniqueTimes);
            tMonotonic = tMonotonic{1};
            yMonotonic = yMonotonic{1};
            for k = 1:length(timeIn)
                tInd = find(timeIn(k)==tMonotonic);
                yOut(k,1) = yMonotonic(tInd);
            end
        end
    
        function updateMetadataFields(app)
            app.CompNumberEditField.Value = app.CompNum;
            app.CompNameEditField.Value = app.CompName;
            app.TargetEditField.Value = app.CompTarg;
            
            app.CellTypeEditField.Value = app.CellType;           
            app.SourceEditField.Value = app.ExptSource;
            app.DatesEditField.Value = app.ExptDates;
            app.NumReplEditField.Value = app.ExptNumRep;
            
            app.PKDriverDropDown.Value = app.FinalPKdriver;
            app.ConfidenceDropDown.Value = app.FinalResultConf;
            app.FinalCommentsTextArea.Value = app.FinalComm;
        end
    
        function [WOvalSet,IC50valSet,WOvalSet_smooth]=getSimResults(app)
            nEffDoses = length(app.effectiveDoses);
            
            ThreshWOvals = nan(length(app.Durations),3*nEffDoses);
            AUCWOvals = nan(length(app.Durations),3*nEffDoses);
            CmaxWOvals = nan(length(app.Durations),3*nEffDoses);
            
            ThreshWOvals_smooth = nan(app.nSmoothPoints,3*nEffDoses);
            AUCWOvals_smooth = nan(app.nSmoothPoints,3*nEffDoses);
            CmaxWOvals_smooth = nan(app.nSmoothPoints,3*nEffDoses);
            
            nIC50doses = length(app.IC50_doses);
            ThreshIC50vals = nan(nIC50doses,3);
            AUCIC50vals = nan(nIC50doses,3);
            CmaxIC50vals = nan(nIC50doses,3);
            f = waitbar(0,'Re-running IC50 only fit...');
                    paramNameOut = 'fit_value';
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmin','IC50only');
                        ThreshWOvals(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves;
                        ThreshWOvals_smooth(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves_smooth;
                        ThreshIC50vals(:,3) = IC50_modelCurves;                        
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cavg','IC50only');
                        AUCWOvals(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves;
                        AUCWOvals_smooth(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves_smooth;
                        AUCIC50vals(:,3) = IC50_modelCurves;
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmax','IC50only');
                        CmaxWOvals(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves;
                        CmaxWOvals_smooth(:,2*nEffDoses+1:3*nEffDoses) = washout_modelCurves_smooth;
                        CmaxIC50vals(:,3) = IC50_modelCurves;
                    end
                    
                    waitbar(1/3,f,'Re-running Washout only fit...');
                    % WO only fit
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmin','WOonly');
                        ThreshWOvals(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves;
                        ThreshWOvals_smooth(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves_smooth;
                        ThreshIC50vals(:,2) = IC50_modelCurves;
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cavg','WOonly');
                        AUCWOvals(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves;
                        AUCWOvals_smooth(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves_smooth;
                        AUCIC50vals(:,2) = IC50_modelCurves;
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmax','WOonly');
                        CmaxWOvals(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves;
                        CmaxWOvals_smooth(:,nEffDoses+1:2*nEffDoses) = washout_modelCurves_smooth;
                        CmaxIC50vals(:,2) = IC50_modelCurves;
                    end
                    waitbar(2/3,f,'Re-running both fit fit...');
                    % Both fit
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmin','both');
                        ThreshWOvals(:,1:nEffDoses) = washout_modelCurves;
                        ThreshWOvals_smooth(:,1:nEffDoses) = washout_modelCurves_smooth;
                        ThreshIC50vals(:,1) = IC50_modelCurves;
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cavg','both');
                        AUCWOvals(:,1:nEffDoses) = washout_modelCurves;
                        AUCWOvals_smooth(:,1:nEffDoses) = washout_modelCurves_smooth;
                        AUCIC50vals(:,1) = IC50_modelCurves;
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        [washout_modelCurves,IC50_modelCurves,washout_modelCurves_smooth,smoothDur]=app.updateSimAndPlot('Cmax','both');
                        CmaxWOvals(:,1:nEffDoses) = washout_modelCurves;
                        CmaxWOvals_smooth(:,1:nEffDoses) = washout_modelCurves_smooth;
                        CmaxIC50vals(:,1) = IC50_modelCurves;
                    end
                    close(f)
            ThreshWOvals = [app.Durations,ThreshWOvals];
            AUCWOvals = [app.Durations,AUCWOvals];
            CmaxWOvals = [app.Durations,CmaxWOvals];
            
            ThreshWOvals_smooth = [smoothDur,ThreshWOvals_smooth];
            AUCWOvals_smooth = [smoothDur,AUCWOvals_smooth];
            CmaxWOvals_smooth = [smoothDur,CmaxWOvals_smooth];
            
            ThreshIC50vals = [app.IC50_doses,ThreshIC50vals];
            AUCIC50vals = [app.IC50_doses,AUCIC50vals];
            CmaxIC50vals = [app.IC50_doses,CmaxIC50vals];
            
            WOvalSet = {ThreshWOvals,AUCWOvals,CmaxWOvals};
            WOvalSet_smooth = {ThreshWOvals_smooth,AUCWOvals_smooth,CmaxWOvals_smooth};
            IC50valSet = {ThreshIC50vals,AUCIC50vals,CmaxIC50vals};
        end
        
        function updateColors(app,nDoses)
            ColormapSelVal = app.ColormapSel; % get string for selected colormap
            switch ColormapSelVal
                case 'copper'
                    app.colors = copper(nDoses);
                case 'jet'
                    app.colors = jet(nDoses);
                case 'turbo'
                    app.colors = turbo(nDoses);
                case 'hsv'
                    app.colors = hsv(nDoses);
                case 'parula'
                    app.colors = parula(nDoses);
                case 'lines'
                    app.colors = lines(nDoses);
                case 'cool'
                    app.colors = cool(nDoses);
                case 'spring'
                    app.colors = spring(nDoses);
                case 'summer'
                    app.colors = summer(nDoses);
                case 'autumn'
                    app.colors = autumn(nDoses);
                case 'winter'
                    app.colors = winter(nDoses);
                case 'abyss'
                    app.colors = abyss(nDoses);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Disable Generate models button until the app has fully loaded
            app.GenerateModelsButton.Enable = false;
            % Disable other tabs until model is generated
            app.toggleEnable('off');
            %% Handle the input data
            if nargin==1
                % No data provided, select default doses
                baseParams = [];
                baseParamsFlag = 0;
            elseif nargin == 2
                % No data provided, but model base parameters provided
                baseParams = varargin{1};
                baseParamsFlag = 1;
                
            elseif nargin == 3
                % IC50 data and base model parameters provided but no washout data
                baseParams = varargin{1};
                baseParamsFlag = 1;
                app.IC50tbl = varargin{2};
                app.IC50_doses = unique(app.IC50tbl.Dose);
            elseif nargin == 4
                baseParams = varargin{1};
                baseParamsFlag = 1;
                app.IC50tbl = varargin{2};
                app.WOtbl = varargin{3};
                app.IC50_doses = unique(app.IC50tbl.Dose);
                app.Durations=unique(app.WOtbl.Duration);
                app.t_run = max(app.Durations);
                app.effectiveDoses = unique(app.WOtbl.EffDose);
                app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
                app.WashoutDataParams = [app.WOtbl.Dose,app.WOtbl.Duration];
                app.DataCheckBox.Value = true;
            else
                baseException = MException('exploreTargetCov:BadInputs','Unable to initialize app');
                causeException = MException('MATLAB:narginchk:tooManyInputs','Too many input arguments.');
                baseException = addCause(baseException,causeException);
                
                throw(baseException);
            end
            
            % fix items data to numerical
            app.ConfidenceDropDown.ItemsData = [0:1:5];
            % Update the base parameters
            app.applyGrowthParams(baseParams)
            
            app.nTime = length(app.Durations);
            app.nDoses = length(app.effectiveDoses);
            app.updateColors(app.nDoses);
            % app.colors=copper(app.nDoses);
            TrueDosesMat = app.t_run*(1./app.Durations)*app.effectiveDoses';
            DurationsSingleMat = app.Durations*ones(1,app.nDoses);
            app.IC50paramsSingle = [app.IC50_doses,ones(length(app.IC50_doses),1)*app.t_run];
            app.WashoutParamsInSingle = [TrueDosesMat(:),DurationsSingleMat(:)];
            
            if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                app.plotData;
            end
            
            %% update table preferences
            %             app.FitData_IC50fit.ColumnName = app.fitParamResults_IC50.Properties.VariableNames;
            app.FitData_IC50fit.RowName = app.fitParamResults_IC50.Properties.RowNames;
            app.FitData_WOfit.RowName = app.fitParamResults_WOonly.Properties.RowNames;
            app.FitData_bothfit.RowName = app.fitParamResults_both.Properties.RowNames;
            
            % Set value of fit parameter limits
            % Threshold
            app.Thresh_IC50_LL.Value = app.param_lb_Cmin(1);
            app.Thresh_IC50_UL.Value = app.param_ub_Cmin(1);
            app.Thresh_fracNR_LL.Value = app.param_lb_Cmin(2);
            app.Thresh_fracNR_UL.Value = app.param_ub_Cmin(2);
            app.Thresh_hill_LL.Value = app.param_lb_Cmin(3);
            app.Thresh_hill_UL.Value = app.param_ub_Cmin(3);
            app.Thresh_Emax_LL.Value = app.param_lb_Cmin(4);
            app.Thresh_Emax_UL.Value = app.param_ub_Cmin(4);

            % AUC
            app.AUC_IC50_LL.Value = app.param_lb_Cavg(1);
            app.AUC_IC50_UL.Value = app.param_ub_Cavg(1);
            app.AUC_fracNR_LL.Value = app.param_lb_Cavg(2);
            app.AUC_fracNR_UL.Value = app.param_ub_Cavg(2);

            % Cmax
            app.Cmax_IC50_LL.Value = app.param_lb_Cmax(1);
            app.Cmax_IC50_UL.Value = app.param_ub_Cmax(1);
            app.Cmax_fracNR_LL.Value = app.param_lb_Cmax(2);
            app.Cmax_fracNR_UL.Value = app.param_ub_Cmax(2);
            app.Cmax_hill_LL.Value = app.param_lb_Cmax(3);
            app.Cmax_hill_UL.Value = app.param_ub_Cmax(3);
            
            f = waitbar(0,'Loading Cmin model...');
            % Cmin
            p2 = sbioloadproject('Target_Coverage_Cmin');% load simbiology project
            app.m_Cmin = p2.m1;
            
            
            waitbar(1/8,f,'Loading Cavg model...');
            % Cavg
            p3 = sbioloadproject('Target_Coverage_Cavg');% load simbiology project
            app.m_Cavg = p3.m1;
            
            
            waitbar(1/4,f,'Loading Cmax model...');
            % Cmax
            p1 = sbioloadproject('Target_Coverage_Cmax');% load simbiology project
            app.m_Cmax = p1.m1;


            close(f);
            % Generate the accelerated simfunctions
            app.GenSimFunc;
            %             if baseParamsFlag ==1
            % base params suppied
            
            %             end
            
            % re-enable Generate models button now the app has fully loaded
            app.GenerateModelsButton.Enable = true;
            
            
        end

        % Value changed function: IC50EditField_Cmin
        function IC50EditField_CminValueChanged(app, event)
            app.IC50_Cmin = app.IC50EditField_Cmin.Value;
            app.updateSimAndPlot('Cmin','manual');
            
        end

        % Value changed function: nEditField_Cmin
        function nEditField_CminValueChanged(app, event)
            app.n_Cmin = app.nEditField_Cmin.Value;
            app.updateSimAndPlot('Cmin','manual');
        end

        % Value changed function: fracnonrespEditField_Cmin
        function fracnonrespEditField_CminValueChanged(app, event)
            app.frac_nr_Cmin = app.fracnonrespEditField_Cmin.Value;
            app.updateSimAndPlot('Cmin','manual');
        end

        % Value changed function: fracnonrespEditField_Cmax
        function fracnonrespEditField_CmaxValueChanged(app, event)
            app.frac_nr_Cmax = app.fracnonrespEditField_Cmax.Value;
            app.updateSimAndPlot('Cmax','manual');
        end

        % Value changed function: IC50EditField_Cmax
        function IC50EditField_CmaxValueChanged(app, event)
            app.IC50_Cmax = app.IC50EditField_Cmax.Value;
            app.updateSimAndPlot('Cmax','manual');
        end

        % Value changed function: nEditField_Cmax
        function nEditField_CmaxValueChanged(app, event)
            app.n_Cmax = app.nEditField_Cmax.Value;
            app.updateSimAndPlot('Cmax','manual');
        end

        % Value changed function: IC50EditField_Cavg
        function IC50EditField_CavgValueChanged(app, event)
            app.IC50_Cavg = app.IC50EditField_Cavg.Value;
            app.updateSimAndPlot('Cavg','manual');
        end

        % Value changed function: fracnonrespEditField_Cavg
        function fracnonrespEditField_CavgValueChanged(app, event)
            app.frac_nr_Cavg = app.fracnonrespEditField_Cavg.Value;
            app.updateSimAndPlot('Cavg','manual');
        end

        % Button pushed function: FitIndividButton_Cmin
        function FitIndividButton_CminPushed(app, event)
            
            if app.IC50onlyButton_Cmin.Value
                app.fitData('Cmin','IC50only');
            elseif app.WashoutOnlyButton_Cmin.Value
                app.fitData('Cmin','WOonly');
            else
                app.fitData('Cmin','both');
            end
            
            
        end

        % Button pushed function: FitIndividButton_Cavg
        function FitIndividButton_CavgPushed(app, event)
            
            if app.IC50onlyButton_Cavg.Value
                app.fitData('Cavg','IC50only');
            elseif app.WashoutOnlyButton_Cavg.Value
                app.fitData('Cavg','WOonly');
            else
                app.fitData('Cavg','both');
            end
        end

        % Button pushed function: FitIndividButton_Cmax
        function FitIndividButton_CmaxPushed(app, event)
            
            if app.IC50onlyButton_Cmax.Value
                app.fitData('Cmax','IC50only');
            elseif app.WashoutOnlyButton_Cmax.Value
                app.fitData('Cmax','WOonly');
            else
                app.fitData('Cmax','both');
            end
        end

        % Button pushed function: FitallButton
        function FitallButtonPushed(app, event)
            f = waitbar(0,'Fitting Cmin model...');
            if app.IC50onlyButton_All.Value
                app.fitData('Cmin','IC50only');
                waitbar(1/3,f,'fitting Cavg model...');
                app.fitData('Cavg','IC50only');
                waitbar(2/3,f,'fitting Cmax model...');
                app.fitData('Cmax','IC50only');
            elseif app.WashoutOnlyButton_All.Value
                app.fitData('Cmin','WOonly');
                waitbar(1/3,f,'fitting Cavg model...');
                app.fitData('Cavg','WOonly');
                waitbar(2/3,f,'fitting Cmax model...');
                app.fitData('Cmax','WOonly');
            else
                app.fitData('Cmin','both');
                waitbar(1/3,f,'fitting Cavg model...');
                app.fitData('Cavg','both');
                waitbar(2/3,f,'fitting Cmax model...');
                app.fitData('Cmax','both');
            end
            close(f);
        end

        % Value changed function: DataCheckBox
        function DataCheckBoxValueChanged(app, event)
            if app.DataCheckBox.Value
                app.NoneCheckBox.Value = false;
            else
                app.AllCheckBox.Value = false;
            end
            
            app.updatePlotVis();
            
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            if app.ManualCheckBox.Value
                app.NoneCheckBox.Value = false;
            else
                app.AllCheckBox.Value = false;
            end
            app.updatePlotVis();
            
        end

        % Value changed function: IC50fitCheckBox
        function IC50fitCheckBoxValueChanged(app, event)
            if app.IC50fitCheckBox.Value
                app.NoneCheckBox.Value = false;
            else
                app.AllCheckBox.Value = false;
            end
            app.updatePlotVis();
            
        end

        % Value changed function: WashfitCheckBox
        function WashfitCheckBoxValueChanged(app, event)
            if app.WashfitCheckBox.Value
                app.NoneCheckBox.Value = false;
            else
                app.AllCheckBox.Value = false;
            end
            app.updatePlotVis();
        end

        % Value changed function: BothfitCheckBox
        function BothfitCheckBoxValueChanged(app, event)
            if app.BothfitCheckBox.Value
                app.NoneCheckBox.Value = false;
            else
                app.AllCheckBox.Value = false;
            end
            app.updatePlotVis();
            
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            if app.AllCheckBox.Value
                app.DataCheckBox.Value =true;
                app.ManualCheckBox.Value=true;
                app.IC50fitCheckBox.Value=true;
                app.WashfitCheckBox.Value=true;
                app.BothfitCheckBox.Value  =true;
                app.NoneCheckBox.Value = false;
            end
            app.updatePlotVis();
        end

        % Value changed function: NoneCheckBox
        function NoneCheckBoxValueChanged(app, event)
            if app.NoneCheckBox.Value
                app.DataCheckBox.Value =false;
                app.ManualCheckBox.Value=false;
                app.IC50fitCheckBox.Value=false;
                app.WashfitCheckBox.Value=false;
                app.BothfitCheckBox.Value  =false;
                app.AllCheckBox.Value = false;
            end
            app.updatePlotVis();
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % flush queue
            drawnow;
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.ResidualsTab
                app.updateResiduals();
            end
            
            app.updateTables();
            
        end

        % Selection changed function: SelResButtonGroup
        function SelResButtonGroupSelectionChanged(app, event)
%             selectedButton = app.SelResButtonGroup.SelectedObject;
            app.updateResiduals;
        end

        % Button pushed function: SetCurrentParamsasButton_IC50
        function SetCurrentParamsasButton_IC50Pushed(app, event)
            switch app.paramtypeButtonGroup_IC50.SelectedObject
                case app.InitialParamButton_IC50
                    paramNameOut = 'inital_param_value';
                case app.BoundedFitButton_IC50
                    paramNameOut = 'fit_value';
                case app.UnboundedFitButton_IC50
                    paramNameOut = 'fit_value_unb';
            end
            
            if app.CminCheckBox_IC50.Value
                fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                app.updateParams('Cmin',table2array(app.fitParamResults_IC50(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmin','manual');
            end
            if app.CavgCheckBox_IC50.Value
                fitResVar = {'AUC_S';'AUC_frac_nr'};
                app.updateParams('Cavg',table2array(app.fitParamResults_IC50(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cavg','manual');
            end
            if app.CmaxCheckBox_IC50.Value
                fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                app.updateParams('Cmax',table2array(app.fitParamResults_IC50(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmax','manual');
            end
        end

        % Button pushed function: SetCurrentParamsasButton_WO
        function SetCurrentParamsasButton_WOPushed(app, event)
            switch app.paramtypeButtonGroup_WO.SelectedObject
                case app.InitialParamButton_WO
                    paramNameOut = 'inital_param_value';
                case app.BoundedFitButton_WO
                    paramNameOut = 'fit_value';
                case app.UnboundedFitButton_WO
                    paramNameOut = 'fit_value_unb';
            end
            
            if app.CminCheckBox_WO.Value
                fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                app.updateParams('Cmin',table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmin','manual');
            end
            if app.CavgCheckBox_WO.Value
                fitResVar = {'AUC_S';'AUC_frac_nr'};
                app.updateParams('Cavg',table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cavg','manual');
            end
            if app.CmaxCheckBox_WO.Value
                fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                app.updateParams('Cmax',table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmax','manual');
            end
        end

        % Button pushed function: SetCurrentParamsasButton_both
        function SetCurrentParamsasButton_bothPushed(app, event)
            switch app.paramtypeButtonGroup_both.SelectedObject
                case app.InitialParamButton_both
                    paramNameOut = 'inital_param_value';
                case app.BoundedFitButton_both
                    paramNameOut = 'fit_value';
                case app.UnboundedFitButton_both
                    paramNameOut = 'fit_value_unb';
            end
            
            if app.CminCheckBox_both.Value
                fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                app.updateParams('Cmin',table2array(app.fitParamResults_both(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmin','manual');
            end
            if app.CavgCheckBox_both.Value
                fitResVar = {'AUC_S';'AUC_frac_nr'};
                app.updateParams('Cavg',table2array(app.fitParamResults_both(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cavg','manual');
            end
            if app.CmaxCheckBox_both.Value
                fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                app.updateParams('Cmax',table2array(app.fitParamResults_both(fitResVar,paramNameOut)));
                app.updateSimAndPlot('Cmax','manual');
            end
        end

        % Value changed function: AllCheckBox_IC50
        function AllCheckBox_IC50ValueChanged(app, event)
            value = app.AllCheckBox_IC50.Value;
            
            if value
                app.CminCheckBox_IC50.Value = true;
                app.CavgCheckBox_IC50.Value = true;
                app.CmaxCheckBox_IC50.Value = true;
            elseif event.PreviousValue
                app.CminCheckBox_IC50.Value = false;
                app.CavgCheckBox_IC50.Value = false;
                app.CmaxCheckBox_IC50.Value = false;
            end
        end

        % Value changed function: AllCheckBox_WO
        function AllCheckBox_WOValueChanged(app, event)
            value = app.AllCheckBox_WO.Value;
            if value
                app.CminCheckBox_WO.Value = true;
                app.CavgCheckBox_WO.Value = true;
                app.CmaxCheckBox_WO.Value = true;
            elseif event.PreviousValue
                app.CminCheckBox_WO.Value = false;
                app.CavgCheckBox_WO.Value = false;
                app.CmaxCheckBox_WO.Value = false;
            end
        end

        % Value changed function: AllCheckBox_both
        function AllCheckBox_bothValueChanged(app, event)
            value = app.AllCheckBox_both.Value;
            if value
                app.CminCheckBox_both.Value = true;
                app.CavgCheckBox_both.Value = true;
                app.CmaxCheckBox_both.Value = true;
            elseif event.PreviousValue
                app.CminCheckBox_both.Value = false;
                app.CavgCheckBox_both.Value = false;
                app.CmaxCheckBox_both.Value = false;
            end
        end

        % Value changed function: CminCheckBox_IC50
        function CminCheckBox_IC50ValueChanged(app, event)
            value = app.CminCheckBox_IC50.Value;
            if ~value
                app.AllCheckBox_IC50.Value = false;
            end
        end

        % Value changed function: CavgCheckBox_IC50
        function CavgCheckBox_IC50ValueChanged(app, event)
            value = app.CavgCheckBox_IC50.Value;
            if ~value
                app.AllCheckBox_IC50.Value = false;
            end
        end

        % Value changed function: CmaxCheckBox_IC50
        function CmaxCheckBox_IC50ValueChanged(app, event)
            value = app.CmaxCheckBox_IC50.Value;
            if ~value
                app.AllCheckBox_IC50.Value = false;
            end
        end

        % Value changed function: CminCheckBox_WO
        function CminCheckBox_WOValueChanged(app, event)
            value = app.CminCheckBox_WO.Value;
            if ~value
                app.AllCheckBox_WO.Value = false;
            end
        end

        % Value changed function: CavgCheckBox_WO
        function CavgCheckBox_WOValueChanged(app, event)
            value = app.CavgCheckBox_WO.Value;
            if ~value
                app.AllCheckBox_WO.Value = false;
            end
        end

        % Value changed function: CmaxCheckBox_WO
        function CmaxCheckBox_WOValueChanged(app, event)
            value = app.CmaxCheckBox_WO.Value;
            if ~value
                app.AllCheckBox_WO.Value = false;
            end
        end

        % Value changed function: CminCheckBox_both
        function CminCheckBox_bothValueChanged(app, event)
            value = app.CminCheckBox_both.Value;
            if ~value
                app.AllCheckBox_both.Value = false;
            end
        end

        % Value changed function: CavgCheckBox_both
        function CavgCheckBox_bothValueChanged(app, event)
            value = app.CavgCheckBox_both.Value;
            if ~value
                app.AllCheckBox_both.Value = false;
            end
        end

        % Value changed function: CmaxCheckBox_both
        function CmaxCheckBox_bothValueChanged(app, event)
            value = app.CmaxCheckBox_both.Value;
            if ~value
                app.AllCheckBox_both.Value = false;
            end
        end

        % Button pushed function: IC50dataWSimportButton
        function IC50dataWSimportButtonPushed(app, event)
            varlist = evalin('base','whos');
            k = cellfun(@(c) ~(strcmp(c,'table')==1),{varlist.class});
            varlist(k) = [];
            
            [indx,tf] = listdlg('PromptString','Select IC50 data table:',...
                'SelectionMode','single',...
                'ListString',{varlist.name});
            
            if tf == 1
                app.IC50tbl = evalin('base',varlist(indx).name);
                app.IC50_doses = unique(app.IC50tbl.Dose);
                app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
                
                if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                    app.plotData;
                end
            end
            
            if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
                app.IC50paramsSingle = [app.IC50_doses,ones(length(app.IC50_doses),1)*app.t_run];
                app.plotData;
            end
            
            %             app.TargetCoverageAppUIFigure.Visible = 'off';
            %             app.TargetCoverageAppUIFigure.Visible = 'on';
            
        end

        % Button pushed function: WAOdataWSimportButton
        function WAOdataWSimportButtonPushed(app, event)
            varlist = evalin('base','whos');
            k = cellfun(@(c) ~(strcmp(c,'table')==1),{varlist.class});
            varlist(k) = [];
            
            [indx,tf] = listdlg('PromptString','Select Washout data table:',...
                'SelectionMode','single',...
                'ListString',{varlist.name});
            
            if tf == 1
                app.WOtbl = evalin('base',varlist(indx).name);
                app.Durations=unique(app.WOtbl.Duration);
                app.t_run = max(app.Durations);
                app.effectiveDoses = unique(app.WOtbl.EffDose);
                
                app.WashoutDataParams = [app.WOtbl.Dose,app.WOtbl.Duration];
                app.DataCheckBox.Value = true;


                app.nTime = length(app.Durations);
                app.nDoses = length(app.effectiveDoses);
                app.updateColors(app.nDoses);
                % app.colors=colormap(copper(app.nDoses));
                TrueDosesMat = app.t_run*(1./app.Durations)*app.effectiveDoses';
                DurationsSingleMat = app.Durations*ones(1,app.nDoses);
                
                app.WashoutParamsInSingle = [TrueDosesMat(:),DurationsSingleMat(:)];
                
                
                if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                    app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
                    app.IC50paramsSingle = [app.IC50_doses,ones(length(app.IC50_doses),1)*app.t_run];
                    app.plotData;
                end
            end
            %             app.TargetCoverageAppUIFigure.Visible = 'off';
            %             app.TargetCoverageAppUIFigure.Visible = 'on';
            
        end

        % Button pushed function: GenerateModelsButton
        function GenerateModelsButtonPushed(app, event)
            app.updateGrowthParam;
        end

        % Value changed function: Cmax_hill_LL
        function Cmax_hill_LLValueChanged(app, event)
            LL_value = app.Cmax_hill_LL.Value;
            UL_value = app.Cmax_hill_UL.Value;
            if UL_value<LL_value
                app.Cmax_hill_LL.Value = UL_value;
                app.Cmax_hill_UL.Value = LL_value;
                app.param_lb_Cmax(3)=UL_value;
                app.param_ub_Cmax(3)=LL_value;
            else
                app.param_lb_Cmax(3)=LL_value;
                app.param_ub_Cmax(3)=UL_value;
            end
        end

        % Button pushed function: ExtractSubPlotsButton
        function ExtractSubPlotsButtonPushed(app, event)
            
            WOaxes = {app.CminWOax,app.CavgWOax,app.CmaxWOax};
            app.pullOutPlots(WOaxes,1);
            
            ICaxes = {app.CminIC50ax,app.CavgIC50ax,app.CmaxIC50ax};
            app.pullOutPlots(ICaxes,1);
        end

        % Button pushed function: ExtractIndPlotsButton
        function ExtractIndPlotsButtonPushed(app, event)
            WOaxes = {app.CminWOax,app.CavgWOax,app.CmaxWOax};
            app.pullOutPlots(WOaxes,2);
            
            ICaxes = {app.CminIC50ax,app.CavgIC50ax,app.CmaxIC50ax};
            app.pullOutPlots(ICaxes,2);
        end

        % Button pushed function: ExtractSubPlotsButton_resid
        function ExtractSubPlotsButton_residPushed(app, event)
            Residaxes_WO = {app.CminResidAx,app.CavgResidAx,app.CmaxResidAx};
            app.pullOutPlots(Residaxes_WO,1);

            Residaxes_IC50 = {app.CminResidAx_IC50,app.CavgResidAx_IC50,app.CmaxResidAx_IC50};
            app.pullOutPlots(Residaxes_IC50,1);
        end

        % Button pushed function: ExtractIndPlotsButton_resid
        function ExtractIndPlotsButton_residPushed(app, event)
            Residaxes_WO = {app.CminResidAx,app.CavgResidAx,app.CmaxResidAx};
            app.pullOutPlots(Residaxes_WO,2);
            
            Residaxes_IC50 = {app.CminResidAx_IC50,app.CavgResidAx_IC50,app.CmaxResidAx_IC50};
            app.pullOutPlots(Residaxes_IC50,2);
        end

        % Button pushed function: ImportWOexcelButton
        function ImportWOexcelButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx,*.xls)'},'Select the Washout data spreadsheet');
            f = waitbar(0,'Loading excel file');
            [status,sheets] = xlsfinfo([path,file]);
            
            RawCellRange = 'D32:O39';
            ExpTimeRange = 'D18:O25';
            ConcRange = 'D07:O14';
            RawCellData = [];
            ExpTimeData = [];
            ConcData = [];
            SheetNum = [];
            
            waitbar(1/4,f,'Reading excel data');
            validSheets = [];
            validCounter = 0;
            for k =  1:length(sheets)
                RawCellDataSh = [];
                RawCellDataSh = xlsread([path,file],sheets{k},RawCellRange);
                
                if isempty(RawCellDataSh)
                    continue
                end
                RawCellData = [RawCellData;RawCellDataSh];
                
                ExpTimeDataSh = [];
                ExpTimeDataSh = xlsread([path,file],sheets{k},ExpTimeRange);
                
                ExpTimeData = [ExpTimeData;ExpTimeDataSh];
                
                ConcDataSh = [];
                ConcDataSh = xlsread([path,file],sheets{k},ConcRange);
                
                ConcData = [ConcData;ConcDataSh];
                
                SheetNum = [SheetNum;ones(size(RawCellDataSh))*k];
                validCounter = validCounter +1;
                validSheets(validCounter) = k;
            end
            
            if isempty(RawCellData)
                error('No data found in the correct location of the spreadsheet');
            end
            waitbar(3/4,f,'Normalizing Data');
            WOdataIn = RawCellData(:,2:11);
            WOexpTimeIn = ExpTimeData(:,2:11);
            WOconcIn = ConcData(:,2:11);
            WOsheetIn = SheetNum(:,2:11);
            
            WOdataCol = WOdataIn(:);
            WOexpTimeCol = WOexpTimeIn(:);
            WOconcCol = WOconcIn(:);
            WOsheetCol = WOsheetIn(:);
            
            goodRows = find(~isnan(WOdataCol) & ~isnan(WOexpTimeCol) & ~isnan(WOconcCol));
            
            if isempty(goodRows)
                error('Data missing matching concentration and/or exposure time');
            end
            
            WOtbl_preNorm = table(zeros(length(goodRows),1),WOdataCol(goodRows),WOconcCol(goodRows),WOexpTimeCol(goodRows),WOsheetCol(goodRows),...
                'VariableNames',{'NormCell','Cell','Dose','Duration','Sheet'});
            
            Durations = unique(WOtbl_preNorm.Duration);
            
            if app.WithinsheetButton.Value
                % Normalize to controls within sheet
                for m = 1: length(validSheets)
                    for k = 1:length(Durations)
                        CtrlVals = WOtbl_preNorm.Cell( WOtbl_preNorm.Dose == 0 & WOtbl_preNorm.Duration ==Durations(k) &  WOtbl_preNorm.Sheet == validSheets(m));
                        ExptInds = find(WOtbl_preNorm.Dose ~= 0 & WOtbl_preNorm.Duration ==Durations(k) & WOtbl_preNorm.Sheet == validSheets(m));
                        WOtbl_preNorm.NormCell(ExptInds)=  WOtbl_preNorm.Cell(ExptInds)/mean(CtrlVals);
                    end
                end
            else
                for k = 1:length(Durations)
                    CtrlVals = WOtbl_preNorm.Cell( WOtbl_preNorm.Dose == 0 & WOtbl_preNorm.Duration ==Durations(k));
                    ExptInds = find(WOtbl_preNorm.Dose ~= 0 & WOtbl_preNorm.Duration ==Durations(k));
                    WOtbl_preNorm.NormCell(ExptInds)=  WOtbl_preNorm.Cell(ExptInds)/mean(CtrlVals);
                end
            end
            
            goodInds = find(WOtbl_preNorm.Dose ~= 0);
            WOtbl = table(WOtbl_preNorm.NormCell(goodInds),...
                WOtbl_preNorm.Dose(goodInds),WOtbl_preNorm.Duration(goodInds),...
                'VariableNames',{'NormCell','Dose','Duration'});
            maxTime = max(WOtbl.Duration);
            diffEffDoses = unique(WOtbl.Dose(WOtbl.Duration ==maxTime));
            
            calcEffDose = WOtbl.Dose.*WOtbl.Duration/maxTime;
            for k =1:length(calcEffDose)
                [~,minInd]=min(abs(calcEffDose(k)-diffEffDoses));
                EffDose(k,1) = diffEffDoses(minInd);
                
            end
            
            WOtbl.EffDose = EffDose;
            
            app.WOtbl = WOtbl;
            app.Durations=unique(app.WOtbl.Duration);
            app.t_run = maxTime;
            app.effectiveDoses = unique(app.WOtbl.EffDose);
            app.nDoses = length(app.effectiveDoses);
            app.updateColors(app.nDoses);
            % app.colors=copper(app.nDoses);
            app.WashoutDataParams = [app.WOtbl.Dose,app.WOtbl.Duration];
            TrueDosesMat = app.t_run*(1./app.Durations)*app.effectiveDoses';
            DurationsSingleMat = app.Durations*ones(1,app.nDoses);
            app.WashoutParamsInSingle = [TrueDosesMat(:),DurationsSingleMat(:)];
            waitbar(7/8,f,'Updating tables and plots ');
            app.WOdatatable.Data = app.WOtbl;
            if app.YesIC50dataButton.Value
                ICdataIn = RawCellData(:,[1,12]);
                % ICexpTimeIn = ExpTimeData(:,[1,12]);
                ICconcIn = ConcData(:,[1,12]);
                ICsheetIn = SheetNum(:,[1,12]);
                ICdataCol = ICdataIn(:);
                % ICexpTimeCol = ICexpTimeIn(:);
                ICconcCol = ICconcIn(:);
                ICsheetCol = ICsheetIn(:);
                
                goodRows = find(~isnan(ICdataCol) & ~isnan(ICconcCol));
                
                IC50tbl_preNorm = table(zeros(length(goodRows),1),ICdataCol(goodRows),ICconcCol(goodRows),ICsheetCol(goodRows),...
                    'VariableNames',{'NormCell','Cell','Dose','Sheet'});
                if app.WithinsheetButton.Value
                    for m = 1: length(validSheets)
                        ctrlVals = mean(IC50tbl_preNorm.Cell(IC50tbl_preNorm.Dose == 0 & IC50tbl_preNorm.Sheet == validSheets(m)));
                        IC50tbl_preNorm.NormCell(IC50tbl_preNorm.Sheet == validSheets(m)) = IC50tbl_preNorm.Cell(IC50tbl_preNorm.Sheet == validSheets(m))./ctrlVals;
                    end
                else
                    ctrlVals = mean(IC50tbl_preNorm.Cell(IC50tbl_preNorm.Dose == 0));
                    IC50tbl_preNorm.NormCell = IC50tbl_preNorm.Cell./ctrlVals;
                end
                
                goodInds = find(IC50tbl_preNorm.Dose~=0);
                app.IC50tbl = table(IC50tbl_preNorm.NormCell(goodInds),IC50tbl_preNorm.Dose(goodInds),'VariableNames',{'NormCell','Dose'});
                app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
                app.IC50_doses = unique(app.IC50tbl.Dose);
                
                app.IC50paramsSingle = [app.IC50_doses,ones(length(app.IC50_doses),1)*app.t_run];
                
                app.IC50datatable.Data = app.IC50tbl;
            end
            
            if ~isempty(app.IC50tbl) & ~isempty(app.WOtbl)
                app.plotData;
                
            end
            close(f)
        end

        % Button pushed function: ImportIC50excelButton
        function ImportIC50excelButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx,*.xls)'},'Select the IC50 data spreadsheet');
            f = waitbar(0,'Loading excel file');
            [status,sheets] = xlsfinfo([path,file]);
            
            RawCellRange = 'D32:O39';
            ConcRange = 'D07:O14';
            
            
            RawCellData = [];
            ConcData = [];
            SheetNum = [];
            
            validSheets = [];
            validCounter = 0;
            waitbar(1/4,f,'Reading excel data');
            for k =  1:length(sheets)
                RawCellDataSh = [];
                RawCellDataSh = xlsread([path,file],sheets{k},RawCellRange);
                
                if isempty(RawCellDataSh)
                    continue
                end
                RawCellData = [RawCellData;RawCellDataSh];
                
                ConcDataSh = [];
                ConcDataSh = xlsread([path,file],sheets{k},ConcRange);
                
                ConcData = [ConcData;ConcDataSh];
                
                SheetNum = [SheetNum;ones(size(RawCellDataSh))*k];
                validCounter = validCounter +1;
                validSheets(validCounter) = k;
            end
            
            if isempty(RawCellData)
                error('No data found in the correct location of the spreadsheet');
            end
            waitbar(3/4,f,'Normalizing Data');
            IC50dataCol = RawCellData(:);
            IC50concCol = ConcData(:);
            IC50sheetCol = SheetNum(:);
            
            goodRows = find(~isnan(IC50dataCol) & ~isnan(IC50concCol));
            
            if isempty(goodRows)
                error('Data missing matching concentration info');
            end
            
            IC50tbl_preNorm = table(zeros(length(goodRows),1),IC50dataCol(goodRows),IC50concCol(goodRows),IC50sheetCol(goodRows),...
                'VariableNames',{'NormCell','Cell','Dose','Sheet'});
            
            if app.WithinsheetButton.Value
                for m = 1: length(validSheets)
                    ctrlVals = mean(IC50tbl_preNorm.Cell(IC50tbl_preNorm.Dose == 0 & IC50tbl_preNorm.Sheet == validSheets(m)));
                    IC50tbl_preNorm.NormCell(IC50tbl_preNorm.Sheet == validSheets(m)) = IC50tbl_preNorm.Cell(IC50tbl_preNorm.Sheet == validSheets(m))./ctrlVals;
                end
            else
                ctrlVals = mean(IC50tbl_preNorm.Cell(IC50tbl_preNorm.Dose == 0));
                IC50tbl_preNorm.NormCell = IC50tbl_preNorm.Cell./ctrlVals;
            end
            
            goodInds = find(IC50tbl_preNorm.Dose~=0);
            app.IC50tbl = table(IC50tbl_preNorm.NormCell(goodInds),IC50tbl_preNorm.Dose(goodInds),'VariableNames',{'NormCell','Dose'});
            app.IC50dataparams = [app.IC50tbl.Dose,ones(height(app.IC50tbl),1)*app.t_run];
            app.IC50_doses = unique(app.IC50tbl.Dose);
            app.IC50paramsSingle = [app.IC50_doses,ones(length(app.IC50_doses),1)*app.t_run];
            waitbar(7/8,f,'Updating tables and plots ');
            app.IC50datatable.Data = app.IC50tbl;
            if ~isempty(app.IC50tbl) & ~isempty(app.WOtbl)
                app.plotData;
            end
            close(f)
        end

        % Button pushed function: ImportGrowthfromExcelButton
        function ImportGrowthfromExcelButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx,*.xls)'},'Select the growth data spreadsheet');
            f = waitbar(0,'Loading excel file');
            [status,sheets] = xlsfinfo([path,file]);
            
            RawCellRange = 'A:B';
            
            waitbar(1/4,f,'Reading excel data');
            
            RawGrowthData = [];
            RawGrowthData = xlsread([path,file],sheets{1},RawCellRange);
            goodInds = 1:length(RawGrowthData(:,1));
            waitbar(3/4,f,'Updating tables and plots ');
            app.Growth_tbl = table(RawGrowthData(goodInds,1),RawGrowthData(goodInds,2),'VariableNames',{'Time','Perc_confl'});
            app.GrowthDataTable.Data = app.Growth_tbl;
            hold(app.GrowthAx,'off');
            hOut = plot(app.GrowthAx,app.Growth_tbl.Time,app.Growth_tbl.Perc_confl,'o','MarkerSize',5,...
                'MarkerEdgeColor','b','MarkerFaceColor','b',...
                'DisplayName','Growth Data');
            legend(hOut,'Location','best');
            close(f)
        end

        % Button pushed function: FitGrowthParametersButton
        function FitGrowthParametersButtonPushed(app, event)
            app.fitGrowthData;
        end

        % Button pushed function: SetFitGrowthParams
        function SetFitGrowthParamsButtonPushed(app, event)
            NewGrowthParams = app.fitParamResults_growth({'Growth_Rate';'Seeding_Density';'Carrying_Capacity';'Simulation_end_time'},:).fit_value;
            
            app.applyGrowthParams(NewGrowthParams);
            
            app.updateGrowthParam;
        end

        % Button pushed function: UpdateModelSettingsButton
        function UpdateModelSettingsButtonPushed(app, event)
            app.updateGrowthParam;
        end

        % Value changed function: GrowthEditField
        function GrowthEditFieldValueChanged(app, event)
            value = app.GrowthEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: SeedingEditField
        function SeedingEditFieldValueChanged(app, event)
            value = app.SeedingEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: CarryingCapacityEditField
        function CarryingCapacityEditFieldValueChanged(app, event)
            value = app.CarryingCapacityEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: SimulationendtimeEditField
        function SimulationendtimeEditFieldValueChanged(app, event)
            value = app.SimulationendtimeEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: degradationrateEditField
        function degradationrateEditFieldValueChanged(app, event)
            value = app.degradationrateEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
            app.UpdateModelSettingsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: fraccarryoverafterwashoutEditField
        function fraccarryoverafterwashoutEditFieldValueChanged(app, event)
            value = app.fraccarryoverafterwashoutEditField.Value;
            app.GenerateModelsButton.BackgroundColor = [1 0.5412 0.5412];
            app.UpdateModelSettingsButton.BackgroundColor = [1 0.5412 0.5412];
        end

        % Value changed function: EmaxEditField_Cmin
        function EmaxEditField_CminValueChanged(app, event)
            app.Emax_Cmin = app.EmaxEditField_Cmin.Value;
            app.updateSimAndPlot('Cmin','manual');
            
        end

        % Button pushed function: SaveAppStateButton
        function SaveAppStateButtonPushed(app, event)
            % Gather all user changeable values
            % Default input parameters
            save_app.IC50_Cmin = app.IC50_Cmin;
            save_app.n_Cmin = app.n_Cmin;
            save_app.frac_nr_Cmin = app.frac_nr_Cmin;
            save_app.Emax_Cmin = app.Emax_Cmin;
            
            save_app.IC50_Cavg = app.IC50_Cavg;
            save_app.frac_nr_Cavg = app.frac_nr_Cavg;
            
            save_app.IC50_Cmax = app.IC50_Cmax;
            save_app.n_Cmax = app.n_Cmax;
            save_app.frac_nr_Cmax = app.frac_nr_Cmax;
            
            save_app.Growth = app.Growth; % 1/hr growth rate
            save_app.InitSeeding = app.InitSeeding; % percent confluent
            save_app.Volmax = app.Volmax; % carrying capacity
            save_app.CompDeg = app.CompDeg; % first order degradation rate of the drug in media
            save_app.CarryOver = app.CarryOver; % Amount of residual drug left behind after washout
            
            
            save_app.t_run = app.t_run;
            
            save_app.param_lb_Cmin = app.param_lb_Cmin;
            save_app.param_ub_Cmin = app.param_ub_Cmin;
            save_app.param_lb_Cavg = app.param_lb_Cavg;
            save_app.param_ub_Cavg = app.param_ub_Cavg;
            save_app.param_lb_Cmax = app.param_lb_Cmax;
            save_app.param_ub_Cmax  = app.param_ub_Cmax;
            
            
            % Data in
            save_app.Growth_tbl=app.Growth_tbl;
            save_app.WOtbl=app.WOtbl;
            save_app.IC50tbl=app.IC50tbl;
            save_app.IC50paramsSingle=app.IC50paramsSingle;
            save_app.IC50dataparams=app.IC50dataparams;
            save_app.IC50_doses= app.IC50_doses;
            save_app.effectiveDoses= app.effectiveDoses;
            save_app.WashoutParamsInSingle=app.WashoutParamsInSingle;
            save_app.WashoutDataParams=app.WashoutDataParams;
            save_app.nDoses=app.nDoses;
            save_app.nTime=app.nTime;
            save_app.Durations = app.Durations;
            
            
            % Color options
            save_app.ColormapSel = app.ColormapSel;

            % Fit results container
            save_app.fitRes=app.fitRes;
            
            
            save_app.fitParamResults_IC50 = app.fitParamResults_IC50;
            save_app.fitParamResults_WOonly = app.fitParamResults_WOonly ;
            save_app.fitParamResults_both = app.fitParamResults_both;
            
            save_app.fitParamResults_growth = app.fitParamResults_growth;
            save_app.growthFit_simRes=app.growthFit_simRes;

            % fitting options
            save_app.WeightBothFitVal = app.WeightBothFitVal;
            
            % Save metadata
            save_app.ReportName = app.ReportName;
            save_app.ReportPath = app.ReportPath;
            
            save_app.CompNum = app.CompNum;
            save_app.CompName = app.CompName;
            save_app.CompTarg = app.CompTarg;
            
            save_app.CellType = app.CellType;           
            save_app.ExptSource = app.ExptSource;
            save_app.ExptDates = app.ExptDates;
            save_app.ExptNumRep = app.ExptNumRep;
            
            save_app.FinalPKdriver = app.FinalPKdriver;
            save_app.FinalResultConf = app.FinalResultConf;
            save_app.FinalComm = app.FinalComm;
            
            % Suggested file name
            name = 'AppData.mat';
            % Ask for location to save data
            [fnm,pth] = uiputfile(name);
            if ischar(fnm)
                save(fullfile(pth,fnm),'save_app')
            end
        end

        % Button pushed function: LoadAppStateButton
        function LoadAppStateButtonPushed(app, event)
            % have the user select the file where the info is saved
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                
                
                % load the data
                load(fullfile(path,file),'save_app');
                
                if exist('save_app','var')==1
                    f = waitbar(0,'Loading previous parameters model...');
                    % Gather all user changeable values
                    % Default input parameters
                    app.IC50_Cmin= save_app.IC50_Cmin;
                    app.n_Cmin = save_app.n_Cmin;
                    app.frac_nr_Cmin = save_app.frac_nr_Cmin;
                    app.Emax_Cmin = save_app.Emax_Cmin;
                    
                    app.IC50_Cavg = save_app.IC50_Cavg;
                    app.frac_nr_Cavg = save_app.frac_nr_Cavg;
                    
                    app.IC50_Cmax = save_app.IC50_Cmax;
                    app.n_Cmax = save_app.n_Cmax;
                    app.frac_nr_Cmax = save_app.frac_nr_Cmax;
                    
                    app.Growth = save_app.Growth; % 1/hr growth rate
                    app.InitSeeding = save_app.InitSeeding; % percent confluent
                    app.Volmax = save_app.Volmax; % carrying capacity
                    app.CompDeg = save_app.CompDeg; % first order degradation rate of the drug in media
                    app.CarryOver = save_app.CarryOver; % Amount of residual drug left behind after washout
                    
                    
                    app.t_run = save_app.t_run;
                    
                    app.param_lb_Cmin = save_app.param_lb_Cmin;
                    app.param_ub_Cmin = save_app.param_ub_Cmin;
                    app.param_lb_Cavg = save_app.param_lb_Cavg;
                    app.param_ub_Cavg = save_app.param_ub_Cavg;
                    app.param_lb_Cmax = save_app.param_lb_Cmax;
                    app.param_ub_Cmax  = save_app.param_ub_Cmax;
                    
                    
                    % Data in
                    app.Growth_tbl=save_app.Growth_tbl;
                    app.WOtbl=save_app.WOtbl;
                    app.IC50tbl=save_app.IC50tbl;
                    app.IC50paramsSingle=save_app.IC50paramsSingle;
                    app.IC50dataparams=save_app.IC50dataparams;
                    app.IC50_doses= save_app.IC50_doses;
                    app.effectiveDoses= save_app.effectiveDoses;
                    app.WashoutParamsInSingle=save_app.WashoutParamsInSingle;
                    app.WashoutDataParams=save_app.WashoutDataParams;
                    app.nDoses=save_app.nDoses;
                    app.nTime=save_app.nTime;
                    app.Durations = save_app.Durations;
                    
                    % Color options
                    app.ColormapSel = save_app.ColormapSel;
                    app.updateColors(app.nDoses);
                    % app.colors=copper(app.nDoses);
                    
                    % fitting options
                    app.WeightBothFitVal = save_app.WeightBothFitVal;
                    app.WeightBothFit.Value = app.WeightBothFitVal;

                    % Fit results container
                    app.fitRes=save_app.fitRes;
                    
                    if width(save_app.fitParamResults_IC50)==8
                        % Has current correct table
                        app.fitParamResults_IC50 = save_app.fitParamResults_IC50;
                    else
                        warning('Saved app state has old table format which will not be imported');
                    end
                    if width(save_app.fitParamResults_WOonly)==8
                        % Has current correct table
                        app.fitParamResults_WOonly = save_app.fitParamResults_WOonly ;
                    else
                        warning('Saved app state has old table format which will not be imported');
                    end
                    if width(save_app.fitParamResults_both)==8
                        % Has current correct table
                        app.fitParamResults_both = save_app.fitParamResults_both;
                    else
                        warning('Saved app state has old table format which will not be imported');
                    end
                    
                    
                    app.fitParamResults_growth = save_app.fitParamResults_growth;
                    app.growthFit_simRes=save_app.growthFit_simRes;
                    
                    % Update fields added after initial design (i.e. ones that might not have been saved before)
                    if isfield(save_app,'ReportName')
                        app.ReportName = save_app.ReportName;
                    end
                    if isfield(save_app,'ReportPath')
                        app.ReportPath = save_app.ReportPath;
                    end
                    
                    if isfield(save_app,'CompNum')
                        app.CompNum = save_app.CompNum;                        
                    end
                    if isfield(save_app,'CompName')
                        app.CompName = save_app.CompName;
                    end
                    if isfield(save_app,'CompTarg')
                        app.CompTarg = save_app.CompTarg;
                    end
                    
                    if isfield(save_app,'CellType')
                        app.CellType = save_app.CellType;
                    end
                    if isfield(save_app,'ExptSource')   
                        app.ExptSource = save_app.ExptSource;
                    end
                    if isfield(save_app,'ExptDates')   
                        app.ExptDates = save_app.ExptDates;
                    end
                    if isfield(save_app,'ExptNumRep')   
                        app.ExptNumRep = save_app.ExptNumRep;
                    end
                    
                    if isfield(save_app,'FinalPKdriver')   
                        app.FinalPKdriver = save_app.FinalPKdriver;
                    end
                    if isfield(save_app,'FinalResultConf')   
                        app.FinalResultConf = save_app.FinalResultConf;
                    end
                    if isfield(save_app,'FinalComm')   
                        app.FinalComm = save_app.FinalComm;
                    end
    
            
                    app.updateMetadataFields();        


                    waitbar(1/6,f,'updating tables...');
                    % Update the app with new data
                    app.GrowthDataTable.Data =app.Growth_tbl;
                    app.GrowthParamTable.Data = app.fitParamResults_growth;
                    app.applyGrowthParams([]);
                    app.updateGrowthParam();
                    
                    app.IC50EditField_Cmin.Value = app.IC50_Cmin;
                    app.fracnonrespEditField_Cmin.Value = app.frac_nr_Cmin;
                    app.nEditField_Cmin.Value = app.n_Cmin;
                    app.EmaxEditField_Cmin.Value = app.Emax_Cmin;
                    
                    app.IC50EditField_Cavg.Value =app.IC50_Cavg;
                    app.fracnonrespEditField_Cavg.Value = app.frac_nr_Cavg;
                    
                    app.IC50EditField_Cmax.Value = app.IC50_Cmax;
                    app.fracnonrespEditField_Cmax.Value = app.frac_nr_Cmax;
                    app.nEditField_Cmax.Value = app.n_Cmax;
                    
                    waitbar(2/6,f,'Plotting data...');
                    if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                        app.plotData;
                    end
                    
                    if ~isempty(app.Growth_tbl)
                        hold(app.GrowthAx,'off');
                        hOut = plot(app.GrowthAx,app.Growth_tbl.Time,app.Growth_tbl.Perc_confl,'o','MarkerSize',5,...
                            'MarkerEdgeColor','b','MarkerFaceColor','b',...
                            'DisplayName','Growth Data');
                        legend(hOut,'Location','best');
                        
                        if ~isempty(app.growthFit_simRes)
                            hold(app.GrowthAx,'on');
                            hOut = plot(app.GrowthAx,app.growthFit_simRes(:,1),app.growthFit_simRes(:,2),'r','LineWidth',2,'DisplayName','Growth Fit');
                            app.growthPlotHandleGroup = {hOut};
                        end
                    end
                    waitbar(3/6,f,'Rerunning base model...');
                    % Simulate control growth
                    app.simulateControlEndpoint;
                    
                    % Cmin
                    app.updateSimAndPlot('Cmin','manual');
                    % Cavg
                    app.updateSimAndPlot('Cavg','manual');
                    % Cmax
                    app.updateSimAndPlot('Cmax','manual');
                    
                    
                    waitbar(4/6,f,'Re-running IC50 only fit...');
                    paramNameOut = 'fit_value';
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        app.updateSimAndPlot('Cmin','IC50only');
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        app.updateSimAndPlot('Cavg','IC50only');
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        app.updateSimAndPlot('Cmax','IC50only');
                    end
                    
                    waitbar(5/6,f,'Re-running Washout only fit...');
                    % WO only fit
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        app.updateSimAndPlot('Cmin','WOonly');
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        app.updateSimAndPlot('Cavg','WOonly');
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        app.updateSimAndPlot('Cmax','WOonly');
                    end
                    waitbar(6/6,f,'Re-running both fit fit...');
                    % Both fit
                    fitResVar = {'Thresh_IC50';'Thresh_frac_nr';'Thresh_h';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmin',extractedParams);
                        app.updateSimAndPlot('Cmin','both');
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cavg',extractedParams);
                        app.updateSimAndPlot('Cavg','both');
                    end
                    fitResVar = {'Cmax_S';'Cmax_frac_nr';'Cmax_n'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    if any(extractedParams ~=0 )
                        app.updateParams('Cmax',extractedParams);
                        app.updateSimAndPlot('Cmax','both');
                    end
                    
                    close(f);
                else
                    error('must select the .mat file saved from the app "Save App State" button');
                    
                end
            end
            
        end

        % Selection changed function: DataPlotFormatButtonGroup
        function DataPlotFormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataPlotFormatButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Individual')
                app.DisplIndData = true;
            else
                app.DisplIndData = false;
            end
        end

        % Button pushed function: UpdatePlotDisplayButton
        function UpdatePlotDisplayButtonPushed(app, event)
            app.plotData();
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged(app, event)
            value = app.MarkerSizeEditField.Value;
            app.DataMarkerSize = value;
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            % Suggested file name
            % todo: get metadata for the data to auto suggest
            name = ['ReportOut_',app.CompNum,'_',app.CompName];
            name = matlab.lang.makeValidName(name);
            name = [name,'.xlsx'];
            if isempty(app.ReportName)
                
                % Ask for location to save data
                [fnm,pth] = uiputfile('*.xlsx','File Save Name',name);
                testExist = 0;
            else
                oldName = app.ReportName;
                oldPath = [app.ReportPath app.ReportName];
                
                testExist = exist(oldPath,'file');
                
                if testExist~=0
                    [fnm,pth] = uiputfile('*.xlsx','File Save Name',oldPath);
                else
                    [fnm,pth] = uiputfile('*.xlsx','File Save Name',name);
                    
                    if strcmp(oldPath,[pth fnm])==0
                        testExist = 0;
                    end
                end
            end
            
            if fnm ~= 0
                
                app.ReportName = fnm;
                app.ReportPath = pth;
                
                
                fullFile = [app.ReportPath app.ReportName];
                
                if exist(fullFile,'file')==0
                    % Copy the template
                    [directory,~,~] = fileparts(mfilename('fullpath'));
                    copyfile([directory,filesep,'ReportOutTemplate.xlsx'],fullFile)
                end
                g = waitbar(0,'Populating report metadata...');
                % record the date that the report was generated
                DateRepGen = string(datetime("today"));
                [~,~] = xlswrite(fullFile,{DateRepGen},'Report','I1:I1'); % writecell does not preserve merged cells so using xlswrite for now
                % writecell({DateRepGen},fullFile,'Sheet','Report','Range','I1:J1','UseExcel', true, 'PreserveFormat', true);
                % writecell({DateRepGen},fullFile,'Sheet','Report','Range','I1:J1','AutoFitWidth', false);

                % Record compound information
                [status,message] = xlswrite(fullFile,{app.CompNum;app.CompName;app.CompTarg},'Report','C6:C8'); % writecell does not preserve merged cells so using xlswrite for now
                % writecell({app.CompNum;app.CompName;app.CompTarg},fullFile,'Sheet','Report','Range','C6:C8','AutoFitWidth', false)
                
                % Record model base parameters            
                [~,~] = xlswrite(fullFile,{app.Growth;app.InitSeeding;app.Volmax;app.t_run;app.CompDeg;app.CarryOver},'Report','C14:C19');
                % writecell({app.Growth;app.InitSeeding;app.Volmax;app.t_run;app.CompDeg;app.CarryOver},fullFile,'Sheet','Report','Range','C14:C19','AutoFitWidth', false)

                % Record final decision selections
                % [~,~] = xlswrite(fullFile,{app.FinalPKdriver;app.FinalResultConf},'Report','H14:H15');
                [~,~] = xlswrite(fullFile,{app.FinalPKdriver;app.FinalResultConf},'Report','C22:C23');
                % writecell({app.FinalPKdriver;app.FinalResultConf},fullFile,'Sheet','Report','Range','H14:H15','AutoFitWidth', false)

                if ~isempty(app.FinalComm)
                    % xlswrite(fullFile,app.FinalComm,'Report','J15:J15');
                    xlswrite(fullFile,app.FinalComm,'Report','C24:C24');
                    % writecell(app.FinalComm,fullFile,'Sheet','Report','Range','J15:J15','AutoFitWidth', false)

                end
                
                if ~isempty(app.IC50tbl) && ~isempty(app.WOtbl)
                    waitbar(1/4,g,'Populating report fit values...');
                    % Record experimental metadata
                    [~,~] = xlswrite(fullFile,{app.CellType;app.ExptSource;app.ExptDates;app.ExptNumRep},'Report','G6:G9');
                    % writecell({app.CellType;app.ExptSource;app.ExptDates;app.ExptNumRep},fullFile,'Sheet','Report','Range','G6:G9','AutoFitWidth', false)

                    % Fit both table generation
                    fitTableVals = nan(9,7);
                    ICcolVals = nan(9,3);
                    paramNameOut = 'fit_value';
                    fitResVar = {'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_both(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(1:4,:) = extractedParamsGather;
                        ICcolVals(1,1) = app.fitParamResults_both.AIC(fitResVar{1});
                        ICcolVals(1,3) = app.fitParamResults_both.BIC(fitResVar{1});                        
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_both(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(5:6,:) = extractedParamsGather;
                        ICcolVals(5,1) = app.fitParamResults_both.AIC(fitResVar{1});
                        ICcolVals(5,3) = app.fitParamResults_both.BIC(fitResVar{1});                        
                    end
                    
                    fitResVar = {'Cmax_S';'Cmax_n';'Cmax_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_both(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_both(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
%                         extractedParamsGather(extractedParamsGather(:,7)==0,7)=nan;
                        fitTableVals(7:9,:) = extractedParamsGather;
                        ICcolVals(7,1) = app.fitParamResults_both.AIC(fitResVar{1});
                        ICcolVals(7,3) = app.fitParamResults_both.BIC(fitResVar{1});                        
                    end
                    % Calculate AIC weights
                    AICdelta_exp = exp(-(ICcolVals(:,1)-min(ICcolVals(:,1),[],"omitnan"))/2);
                    ICcolVals(:,2)= AICdelta_exp./sum(AICdelta_exp,"omitnan");
                    ICcolVals(ICcolVals(:,2)==0,2)=nan;

                    fitTableVals(fitTableVals(:,7)==0,7)=nan;
                    % xlswrite(fullFile,fitTableVals,'Report','D24:J32');
                    % xlswrite(fullFile,ICcolVals,'Report','L24:L32');
                    writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D30:J38','AutoFitWidth', false);
                    writematrix(ICcolVals,fullFile,'Sheet','Report','Range','K30:M38','AutoFitWidth', false);
                    % writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D24:J32','AutoFitWidth', false);
                    % writematrix(ICcolVals,fullFile,'Sheet','Report','Range','L24:L32','AutoFitWidth', false);
                    
                    % fit washout only table generation
                    fitTableVals = nan(9,7);
                    ICcolVals = nan(9,3);
                    paramNameOut = 'fit_value';
                    fitResVar = {'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_WOonly(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(1:4,:) = extractedParamsGather;
                        ICcolVals(1,1) = app.fitParamResults_WOonly.AIC(fitResVar{1});
                        ICcolVals(1,3) = app.fitParamResults_WOonly.BIC(fitResVar{1});                        
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_WOonly(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(5:6,:) = extractedParamsGather;
                        ICcolVals(5,1) = app.fitParamResults_WOonly.AIC(fitResVar{1});
                        ICcolVals(5,3) = app.fitParamResults_WOonly.BIC(fitResVar{1});                        
                    end
                    
                    fitResVar = {'Cmax_S';'Cmax_n';'Cmax_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_WOonly(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_WOonly(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
%                         extractedParamsGather(extractedParamsGather(:,7)==0,7)=nan;
                        fitTableVals(7:9,:) = extractedParamsGather;
                        ICcolVals(7,1) = app.fitParamResults_WOonly.AIC(fitResVar{1});
                        ICcolVals(7,3) = app.fitParamResults_WOonly.BIC(fitResVar{1});                        
                    end
                    % Calculate AIC weights
                    AICdelta_exp = exp(-(ICcolVals(:,1)-min(ICcolVals(:,1),[],"omitnan"))/2);
                    ICcolVals(:,2)= AICdelta_exp./sum(AICdelta_exp,"omitnan");
                    ICcolVals(isnan(ICcolVals(:,1)),2)=nan;

                    fitTableVals(fitTableVals(:,7)==0,7)=nan;
                    % xlswrite(fullFile,fitTableVals,'Report','D37:J45');
                    % xlswrite(fullFile,ICcolVals,'Report','L37:L45');
                    writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D43:J51','AutoFitWidth', false);
                    writematrix(ICcolVals,fullFile,'Sheet','Report','Range','K43:M51','AutoFitWidth', false);
                    % writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D37:J45','AutoFitWidth', false);
                    % writematrix(ICcolVals,fullFile,'Sheet','Report','Range','L37:L45','AutoFitWidth', false);

                    % fit IC only table generation
                    fitTableVals = nan(9,7);
                    ICcolVals = nan(9,3);
                    paramNameOut = 'fit_value';
                    fitResVar = {'Thresh_IC50';'Thresh_h';'Thresh_frac_nr';'Thresh_Emax'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_IC50(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(1:4,:) = extractedParamsGather;
                        ICcolVals(1,1) = app.fitParamResults_IC50.AIC(fitResVar{1});
                        ICcolVals(1,3) = app.fitParamResults_IC50.BIC(fitResVar{1});                        
                    end
                    fitResVar = {'AUC_S';'AUC_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_IC50(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
                        
                        fitTableVals(5:6,:) = extractedParamsGather;
                        ICcolVals(5,1) = app.fitParamResults_IC50.AIC(fitResVar{1});
                        ICcolVals(5,3) = app.fitParamResults_IC50.BIC(fitResVar{1});                        
                    end
                    
                    fitResVar = {'Cmax_S';'Cmax_n';'Cmax_frac_nr'};
                    extractedParams = table2array(app.fitParamResults_IC50(fitResVar,paramNameOut));
                    extractedParamsGather = [];
                    if any(extractedParams ~=0 )
                        extractedParamsGather = table2array(app.fitParamResults_IC50(fitResVar,1:6));
                        extractedParamsGather(:,7)= extractedParamsGather(:,5)==extractedParamsGather(:,6);
%                         extractedParamsGather(extractedParamsGather(:,7)==0,7)=nan;
                        fitTableVals(7:9,:) = extractedParamsGather;
                        ICcolVals(7,1) = app.fitParamResults_IC50.AIC(fitResVar{1});
                        ICcolVals(7,3) = app.fitParamResults_IC50.BIC(fitResVar{1});                        
                    end
                    % Calculate AIC weights
                    AICdelta_exp = exp(-(ICcolVals(:,1)-min(ICcolVals(:,1),[],"omitnan"))/2);
                    ICcolVals(:,2)= AICdelta_exp./sum(AICdelta_exp,"omitnan");
                    ICcolVals(ICcolVals(:,2)==0,2)=nan;

                    fitTableVals(fitTableVals(:,7)==0,7)=nan;
                    % xlswrite(fullFile,fitTableVals,'Report','D50:J58');
                    % xlswrite(fullFile,ICcolVals,'Report','L50:L58');
                    writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D56:J64','AutoFitWidth', false);
                    writematrix(ICcolVals,fullFile,'Sheet','Report','Range','K56:M64','AutoFitWidth', false);
                    % writematrix(fitTableVals,fullFile,'Sheet','Report','Range','D50:J58','AutoFitWidth', false);
                    % writematrix(ICcolVals,fullFile,'Sheet','Report','Range','L50:L58','AutoFitWidth', false);
                    waitbar(5/8,g,'Populating report washout data tables...');
                    % [status,message] = xlswrite(fullFile,{'Washout Experimental Data'},'Report','B65:B65');
                    writecell({'Washout Experimental Data'},fullFile,'Sheet','Report','Range','B71:B71','AutoFitWidth', false);
                    % writecell({'Washout Experimental Data'},fullFile,'Sheet','Report','Range','B65:B65','AutoFitWidth', false);
                    
                    maxNumPts = 0;
                    [DurVals,~,DurInd]=unique(app.WOtbl.Duration);
                    CollVals = cell(length(DurVals),app.nDoses);
                    for m = 1:app.nDoses
                        dosegrp = find(app.WOtbl.EffDose == app.effectiveDoses(m));
                        
                        
                        %                         meanVals = zeros(size(DurVals));
                        %                         errVals = zeros(size(DurVals));
                        
                        for n = 1:length(DurVals)
                            subInds = find(app.WOtbl.Duration(dosegrp) == DurVals(n));
                            normValsVect = app.WOtbl.NormCell(dosegrp(subInds))';
                            
                            nInVect = length(normValsVect);
                            if nInVect>maxNumPts
                                maxNumPts = nInVect;
                            end
                            CollVals{n,m} = normValsVect;
                            
                        end
                        
                        
                    end
                    
                    DataVect = cell(length(DurVals)+1,app.nDoses*maxNumPts+1);
                    
                    for n = 1:length(DurVals)+1
                        for m = 1:app.nDoses+1
                            if n == 1
                                if m == 1
                                    DataVect{1,1} = 'Washout Time (h)';
                                else
                                    DataVect{n,(m-2)*maxNumPts+2}=['Eff Dose - ',num2str( app.effectiveDoses(m-1))];
                                end
                            else
                                if m==1
                                    DataVect{n,1} = DurVals(n-1);
                                    
                                else
                                    vectVals = CollVals{n-1,m-1};
                                    nVals = length(vectVals);
                                    DataVect(n,(m-2)*maxNumPts+2:(m-2)*maxNumPts+2+nVals-1) = num2cell(vectVals);
                                    
                                    
                                end
                            end
                            
                        end
                        
                    end
                    
                    startRow = 72;
                    startCol = 'B';
                    endRow = startRow+length(DurVals);
                    numColsOut = length(DataVect(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,DataVect,'Report',combRange);
                    writecell(DataVect,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    waitbar(6/8,g,'Re-running simulations to get simulated values...');
                    [WOvalSet,IC50valSet,WOvalSet_smooth]=app.getSimResults();
                    [ThreshWOvals,AUCWOvals,CmaxWOvals]=WOvalSet{:};
                    [ThreshIC50vals,AUCIC50vals,CmaxIC50vals]=IC50valSet{:};
                    [ThreshWOvals_smooth,AUCWOvals_smooth,CmaxWOvals_smooth]=WOvalSet_smooth{:};
                    waitbar(7/8,g,'Populating report simulation values...');
                    % Absolute model prediction values
                    startRow = endRow + 5;
                    startCol = 'B';
                    endRow = startRow+length(DurVals)-1;
                    numColsOut = length(ThreshWOvals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-3),':',startCol,num2str(startRow-3)];
                    % [status,message] = xlswrite(fullFile,{'Fitted Model Predicted Values'},'Report',combRange);
                    writecell({'Fitted Model Predicted Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Threshold Model Washout Values'},'Report',combRange);
                    writecell({'Threshold Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,ThreshWOvals,'Report',combRange);
                    writematrix(ThreshWOvals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+length(DurVals)-1;
                    numColsOut = length(ThreshWOvals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'AUC Model Washout Values'},'Report',combRange);
                    writecell({'AUC Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,AUCWOvals,'Report',combRange);
                    writematrix(AUCWOvals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+length(DurVals)-1;
                    numColsOut = length(ThreshWOvals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Cmax Model Washout Values'},'Report',combRange);
                    writecell({'Cmax Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,CmaxWOvals,'Report',combRange);
                    writematrix(CmaxWOvals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    % smooth model prediction values
                    
                    startRow = endRow + 5;
                    startCol = 'B';
                    endRow = startRow+app.nSmoothPoints-1;
                    numColsOut = length(ThreshWOvals_smooth(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-3),':',startCol,num2str(startRow-3)];
                    % [status,message] = xlswrite(fullFile,{'Fitted Model Smooth Values'},'Report',combRange);
                    writecell({'Fitted Model Smooth Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Threshold Model Washout Values'},'Report',combRange);
                    writecell({'Threshold Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,ThreshWOvals_smooth,'Report',combRange);
                    writematrix(ThreshWOvals_smooth,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+app.nSmoothPoints-1;
                    numColsOut = length(AUCWOvals_smooth(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'AUC Model Washout Values'},'Report',combRange);
                    writecell({'AUC Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,AUCWOvals_smooth,'Report',combRange);
                    writematrix(AUCWOvals_smooth,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+app.nSmoothPoints-1;
                    numColsOut = length(CmaxWOvals_smooth(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Cmax Model Washout Values'},'Report',combRange);
                    writecell({'Cmax Model Washout Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    BothStr = cellstr(strcat('Fit Both - ',string(app.effectiveDoses')));
                    WOStr = cellstr(strcat('Fit WO only- ',string(app.effectiveDoses')));
                    IC50Str = cellstr(strcat('Fit IC50 only - ',string(app.effectiveDoses')));
                    combStr = [{'Washout Time (h)'},BothStr,WOStr,IC50Str];
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,CmaxWOvals_smooth,'Report',combRange);
                    writematrix(CmaxWOvals_smooth,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    
                    % IC50 dose response values
                    
                    maxNumPts = 0;
                    [DoseVals,~,DoseInd]=unique(app.IC50tbl.Dose);
                    nIC50doses = length(DoseVals);
                    CollVals = cell(nIC50doses,1);
                    for m = 1:nIC50doses
                        dosegrp = find(app.IC50tbl.Dose == DoseVals(m));
                        normValsVect = app.IC50tbl.NormCell(dosegrp)';
                        nInVect = length(normValsVect);
                            if nInVect>maxNumPts
                                maxNumPts = nInVect;
                            end
                            CollVals{m,1} = normValsVect;                       
                        
                    end
                    
                    DataVect = cell(nIC50doses+1,maxNumPts+1);
                    
                    for n = 1:nIC50doses+1
                        if n == 1
                            DataVect{1,1} = 'Dose (nM)';
                            DataVect{1,2} = 'Normalized response';
                        else
                            vectVals = CollVals{n-1,1};
                            vectVals = [DoseVals(n-1),vectVals];
                            nVals = length(vectVals);
                            DataVect(n,1:nVals) = num2cell(vectVals);
                        end
                    end
                    
                    startRow = endRow + 3;
                    startCol = 'B';
                    endRow = startRow+nIC50doses;
                    numColsOut = length(DataVect(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-1),':',startCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,{'Experimental IC50 Values'},'Report',combRange);
                    writecell({'Experimental IC50 Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,DataVect,'Report',combRange);
                    writecell(DataVect,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 5;
                    startCol = 'B';
                    endRow = startRow+nIC50doses-1;
                    numColsOut = length(ThreshIC50vals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-3),':',startCol,num2str(startRow-3)];
                    % [status,message] = xlswrite(fullFile,{'Fitted Model Values'},'Report',combRange);
                    writecell({'Fitted Model Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Threshold Model IC50 Values'},'Report',combRange);
                    writecell({'Threshold Model IC50 Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    
                    combStr = {'Dose (nM)','Fit Both','Fit WO only','Fit IC50 only'};
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,ThreshIC50vals,'Report',combRange);
                    writematrix(ThreshIC50vals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+nIC50doses-1;
                    numColsOut = length(ThreshIC50vals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-3),':',startCol,num2str(startRow-3)];
                    % [status,message] = xlswrite(fullFile,{'Fitted Model Values'},'Report',combRange);
                    writecell({'Fitted Model Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'AUC Model IC50 Values'},'Report',combRange);
                    writecell({'AUC Model IC50 Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    
                    combStr = {'Dose (nM)','Fit Both','Fit WO only','Fit IC50 only'};
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,AUCIC50vals,'Report',combRange);
                    writematrix(AUCIC50vals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    startRow = endRow + 4;
                    startCol = 'B';
                    endRow = startRow+nIC50doses-1;
                    numColsOut = length(ThreshIC50vals(1,:))+1;
                    PreStr = floor(numColsOut/26);
                    endStr = mod(numColsOut,26);
                    
                    if PreStr>0
                        endCol = [char(PreStr+64),char(endStr+64)];
                    else
                        endCol = char(endStr+64);
                    end
                    combRange = [startCol,num2str(startRow-3),':',startCol,num2str(startRow-3)];
                    % [status,message] = xlswrite(fullFile,{'Fitted Model Values'},'Report',combRange);
                    writecell({'Fitted Model Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    combRange = [startCol,num2str(startRow-2),':',startCol,num2str(startRow-2)];
                    % [status,message] = xlswrite(fullFile,{'Cmax Model IC50 Values'},'Report',combRange);
                    writecell({'Cmax Model IC50 Values'},fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    
                    combStr = {'Dose (nM)','Fit Both','Fit WO only','Fit IC50 only'};
                    
                    combRange = [startCol,num2str(startRow-1),':',endCol,num2str(startRow-1)];
                    % [status,message] = xlswrite(fullFile,combStr,'Report',combRange);
                    writecell(combStr,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                    
                    combRange = [startCol,num2str(startRow),':',endCol,num2str(endRow)];
                    % [status,message] = xlswrite(fullFile,CmaxIC50vals,'Report',combRange);
                    writematrix(CmaxIC50vals,fullFile,'Sheet','Report','Range',combRange,'AutoFitWidth', false);
                end
                waitbar(1,g,'Finalizing report...');
                close(g)
            end
        end

        % Value changed function: ConfidenceDropDown
        function ConfidenceDropDownValueChanged(app, event)
            value = app.ConfidenceDropDown.Value;
            
            app.FinalResultConf = value;
        end

        % Value changed function: PKDriverDropDown
        function PKDriverDropDownValueChanged(app, event)
            value = app.PKDriverDropDown.Value;
            app.FinalPKdriver = value;
        end

        % Value changed function: FinalCommentsTextArea
        function FinalCommentsTextAreaValueChanged(app, event)
            value = app.FinalCommentsTextArea.Value;
            app.FinalComm = value;
        end

        % Value changed function: CompNumberEditField
        function CompNumberEditFieldValueChanged(app, event)
            value = app.CompNumberEditField.Value;
            app.CompNum = value;
        end

        % Value changed function: CompNameEditField
        function CompNameEditFieldValueChanged(app, event)
            value = app.CompNameEditField.Value;
            app.CompName = value;
        end

        % Value changed function: TargetEditField
        function TargetEditFieldValueChanged(app, event)
            value = app.TargetEditField.Value;
            app.CompTarg = value;
        end

        % Value changed function: CellTypeEditField
        function CellTypeEditFieldValueChanged(app, event)
            value = app.CellTypeEditField.Value;
            app.CellType = value;
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value;
            app.ExptSource = value;
        end

        % Value changed function: DatesEditField
        function DatesEditFieldValueChanged(app, event)
            value = app.DatesEditField.Value;
            app.ExptDates = value;
        end

        % Value changed function: NumReplEditField
        function NumReplEditFieldValueChanged(app, event)
            value = app.NumReplEditField.Value;
            app.ExptNumRep = value;
        end

        % Value changed function: Thresh_IC50_LL
        function Thresh_IC50_LLValueChanged(app, event)
            value = app.Thresh_IC50_LL.Value;
            UL_value = app.Thresh_IC50_UL.Value;
            if UL_value<value
                app.Thresh_IC50_LL.Value = UL_value;
                app.Thresh_IC50_UL.Value = value;
                app.param_lb_Cmin(1)=UL_value;
                app.param_ub_Cmin(1)=value;
            else
                app.param_lb_Cmin(1)=value;
                app.param_ub_Cmin(1)=UL_value;
            end
        end

        % Value changed function: Thresh_IC50_UL
        function Thresh_IC50_ULValueChanged(app, event)
            UL_value = app.Thresh_IC50_UL.Value;
            LL_value = app.Thresh_IC50_LL.Value;
            if UL_value<LL_value
                app.Thresh_IC50_LL.Value = UL_value;
                app.Thresh_IC50_UL.Value = LL_value;
                app.param_lb_Cmin(1)=UL_value;
                app.param_ub_Cmin(1)=LL_value;
            else
                app.param_lb_Cmin(1)=LL_value;
                app.param_ub_Cmin(1)=UL_value;
            end
        end

        % Value changed function: Thresh_hill_LL
        function Thresh_hill_LLValueChanged(app, event)
            LL_value = app.Thresh_hill_LL.Value;
            UL_value = app.Thresh_hill_UL.Value;
            if UL_value<LL_value
                app.Thresh_hill_LL.Value = UL_value;
                app.Thresh_hill_UL.Value = LL_value;
                app.param_lb_Cmin(3)=UL_value;
                app.param_ub_Cmin(3)=LL_value;
            else
                app.param_lb_Cmin(3)=LL_value;
                app.param_ub_Cmin(3)=UL_value;
            end
        end

        % Value changed function: Thresh_hill_UL
        function Thresh_hill_ULValueChanged(app, event)
            LL_value = app.Thresh_hill_LL.Value;
            UL_value = app.Thresh_hill_UL.Value;
            if UL_value<LL_value
                app.Thresh_hill_LL.Value = UL_value;
                app.Thresh_hill_UL.Value = LL_value;
                app.param_lb_Cmin(3)=UL_value;
                app.param_ub_Cmin(3)=LL_value;
            else
                app.param_lb_Cmin(3)=LL_value;
                app.param_ub_Cmin(3)=UL_value;
            end
        end

        % Value changed function: Thresh_Emax_LL
        function Thresh_Emax_LLValueChanged(app, event)
            LL_value = app.Thresh_Emax_LL.Value;
            UL_value = app.Thresh_Emax_UL.Value;
            if UL_value<LL_value
                app.Thresh_Emax_LL.Value = UL_value;
                app.Thresh_Emax_UL.Value = LL_value;
                app.param_lb_Cmin(4)=UL_value;
                app.param_ub_Cmin(4)=LL_value;
            else
                app.param_lb_Cmin(4)=LL_value;
                app.param_ub_Cmin(4)=UL_value;
            end
        end

        % Value changed function: Thresh_Emax_UL
        function Thresh_Emax_ULValueChanged(app, event)
            LL_value = app.Thresh_Emax_LL.Value;
            UL_value = app.Thresh_Emax_UL.Value;
            if UL_value<LL_value
                app.Thresh_Emax_LL.Value = UL_value;
                app.Thresh_Emax_UL.Value = LL_value;
                app.param_lb_Cmin(4)=UL_value;
                app.param_ub_Cmin(4)=LL_value;
            else
                app.param_lb_Cmin(4)=LL_value;
                app.param_ub_Cmin(4)=UL_value;
            end
        end

        % Value changed function: Thresh_fracNR_UL
        function Thresh_fracNR_ULValueChanged(app, event)
            value = app.Thresh_fracNR_UL.Value;
            app.param_ub_Cmin(2)=value;
        end

        % Value changed function: AUC_IC50_LL
        function AUC_IC50_LLValueChanged(app, event)
            LL_value = app.AUC_IC50_LL.Value;
            UL_value = app.AUC_IC50_UL.Value;
            if UL_value<LL_value
                app.AUC_IC50_LL.Value = UL_value;
                app.AUC_IC50_UL.Value = LL_value;
                app.param_lb_Cavg(1)=UL_value;
                app.param_ub_Cavg(1)=LL_value;
            else
                app.param_lb_Cavg(1)=LL_value;
                app.param_ub_Cavg(1)=UL_value;
            end
        end

        % Value changed function: AUC_IC50_UL
        function AUC_IC50_ULValueChanged(app, event)
            LL_value = app.AUC_IC50_LL.Value;
            UL_value = app.AUC_IC50_UL.Value;
            if UL_value<LL_value
                app.AUC_IC50_LL.Value = UL_value;
                app.AUC_IC50_UL.Value = LL_value;
                app.param_lb_Cavg(1)=UL_value;
                app.param_ub_Cavg(1)=LL_value;
            else
                app.param_lb_Cavg(1)=LL_value;
                app.param_ub_Cavg(1)=UL_value;
            end
        end

        % Value changed function: AUC_fracNR_UL
        function AUC_fracNR_ULValueChanged(app, event)
            value = app.AUC_fracNR_UL.Value;
            app.param_ub_Cavg(2)=value;
        end

        % Value changed function: Cmax_IC50_LL
        function Cmax_IC50_LLValueChanged(app, event)
            LL_value = app.Cmax_IC50_LL.Value;
            UL_value = app.Cmax_IC50_UL.Value;
            if UL_value<LL_value
                app.Cmax_IC50_LL.Value = UL_value;
                app.Cmax_IC50_UL.Value = LL_value;
                app.param_lb_Cmax(1)=UL_value;
                app.param_ub_Cmax(1)=LL_value;
            else
                app.param_lb_Cmax(1)=LL_value;
                app.param_ub_Cmax(1)=UL_value;
            end
        end

        % Value changed function: Cmax_IC50_UL
        function Cmax_IC50_ULValueChanged(app, event)
            LL_value = app.Cmax_IC50_LL.Value;
            UL_value = app.Cmax_IC50_UL.Value;
            if UL_value<LL_value
                app.Cmax_IC50_LL.Value = UL_value;
                app.Cmax_IC50_UL.Value = LL_value;
                app.param_lb_Cmax(1)=UL_value;
                app.param_ub_Cmax(1)=LL_value;
            else
                app.param_lb_Cmax(1)=LL_value;
                app.param_ub_Cmax(1)=UL_value;
            end
        end

        % Value changed function: Cmax_hill_UL
        function Cmax_hill_ULValueChanged(app, event)
            LL_value = app.Cmax_hill_LL.Value;
            UL_value = app.Cmax_hill_UL.Value;
            if UL_value<LL_value
                app.Cmax_hill_LL.Value = UL_value;
                app.Cmax_hill_UL.Value = LL_value;
                app.param_lb_Cmax(3)=UL_value;
                app.param_ub_Cmax(3)=LL_value;
            else
                app.param_lb_Cmax(3)=LL_value;
                app.param_ub_Cmax(3)=UL_value;
            end
        end

        % Value changed function: Cmax_fracNR_UL
        function Cmax_fracNR_ULValueChanged(app, event)
            value = app.Cmax_fracNR_UL.Value;
            app.param_ub_Cmax(2)=value;
        end

        % Value changed function: WeightBothFit
        function WeightBothFitValueChanged(app, event)
            value = app.WeightBothFit.Value;
            app.WeightBothFitVal=value;
        end

        % Value changed function: WashoutColormapDropDown
        function WashoutColormapDropDownValueChanged(app, event)
            value = app.WashoutColormapDropDown.Value;
            app.ColormapSel = value;
            app.updateColors(app.nDoses)
            
            % Update manual simulation plot colors
            app.updateSimAndPlot('Cmin','manual')
            app.updateSimAndPlot('Cavg','manual')
            app.updateSimAndPlot('Cmax','manual')

            % If IC50 fit is already plotted update colors
            if ~isempty(app.IC50fitPlotHand{1,1})
                app.updateSimAndPlot('Cmin','IC50only')
                app.updateSimAndPlot('Cavg','IC50only')
                app.updateSimAndPlot('Cmax','IC50only')
            end

            % If Washout fit is already plotted update colors
            if ~isempty(app.WOfitPlotHand{1,1})
                app.updateSimAndPlot('Cmin','WOonly')
                app.updateSimAndPlot('Cavg','WOonly')
                app.updateSimAndPlot('Cmax','WOonly')
            end

            % If both fit is already plotted update colors
            if ~isempty(app.bothfitPlotHand{1,1})
                app.updateSimAndPlot('Cmin','both')
                app.updateSimAndPlot('Cavg','both')
                app.updateSimAndPlot('Cmax','both')
            end

            % Re-plot data
            app.plotData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetCoverageAppUIFigure = uifigure('Visible', 'off');
            app.TargetCoverageAppUIFigure.Name = 'Target Coverage App';
            app.TabGroup = uitabgroup(app.TargetCoverageAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.OncologyTargetCoverageSimulationAppLabel = uilabel(app.HomeTab);
            app.OncologyTargetCoverageSimulationAppLabel.Text = 'Oncology Target Coverage Simulation App ';
            app.BasemodelparametersPanel = uipanel(app.HomeTab);
            app.BasemodelparametersPanel.Title = 'Base model parameters';
            app.GrowthRateEditFieldLabel = uilabel(app.BasemodelparametersPanel);
            app.GrowthRateEditFieldLabel.Text = 'Growth Rate';
            app.GrowthEditField = uieditfield(app.BasemodelparametersPanel, 'numeric');
            app.GrowthEditField.Limits = [0 Inf];
            app.GrowthEditField.ValueChangedFcn = createCallbackFcn(app, @GrowthEditFieldValueChanged, true);
            app.SeedingDensityEditFieldLabel = uilabel(app.BasemodelparametersPanel);
            app.SeedingDensityEditFieldLabel.Text = 'Seeding Density';
            app.SeedingEditField = uieditfield(app.BasemodelparametersPanel, 'numeric');
            app.SeedingEditField.Limits = [0 Inf];
            app.SeedingEditField.ValueChangedFcn = createCallbackFcn(app, @SeedingEditFieldValueChanged, true);
            app.hrLabel = uilabel(app.BasemodelparametersPanel);
            app.hrLabel.Text = '1/hr';
            app.CarryingCapacityEditFieldLabel = uilabel(app.BasemodelparametersPanel);
            app.CarryingCapacityEditFieldLabel.Text = 'Carrying Capacity';
            app.CarryingCapacityEditField = uieditfield(app.BasemodelparametersPanel, 'numeric');
            app.CarryingCapacityEditField.Limits = [0 Inf];
            app.CarryingCapacityEditField.ValueChangedFcn = createCallbackFcn(app, @CarryingCapacityEditFieldValueChanged, true);
            app.GenerateModelsButton = uibutton(app.BasemodelparametersPanel, 'push');
            app.GenerateModelsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateModelsButtonPushed, true);
            app.GenerateModelsButton.Text = 'Generate Models';
            app.SimulationendtimeEditFieldLabel = uilabel(app.BasemodelparametersPanel);
            app.SimulationendtimeEditFieldLabel.Text = 'Simulation end time';
            app.SimulationendtimeEditField = uieditfield(app.BasemodelparametersPanel, 'numeric');
            app.SimulationendtimeEditField.Limits = [0 Inf];
            app.SimulationendtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationendtimeEditFieldValueChanged, true);
            app.hrsLabel = uilabel(app.BasemodelparametersPanel);
            app.hrsLabel.Text = 'hrs';
            app.BasicInfoLabel = uilabel(app.HomeTab);
            app.BasicInfoLabel.Text = 'Basic Info';
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please allow the models to finish loading before changing anything in the app'; ''; 'See the tutorial for more details on using the Target coverage app'; ''; '1. Define the base model paramters'; '       - Option 1: Load the growth data in the "Growth Parameter Fitting" tab, fit the growth parameters to the data and apply the parameters to the model by pushing the "Apply Fitted Parameters to Model" button.'; '       - Option 2: Directly define the parameters by defining them in the fields to the right and pressing the "Generate Models" button'; '      The "Generate Models" button should be green when the models are ready to run.'};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
            app.SaveAppStateButton = uibutton(app.HomeTab, 'push');
            app.SaveAppStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAppStateButtonPushed, true);
            app.SaveAppStateButton.Text = 'Save App State';
            app.LoadAppStateButton = uibutton(app.HomeTab, 'push');
            app.LoadAppStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAppStateButtonPushed, true);
            app.LoadAppStateButton.Text = 'Load App State';
            app.SimulationPlotsTab = uitab(app.TabGroup);
            app.SimulationPlotsTab.Title = 'Simulation Plots';
            app.CminWOax = uiaxes(app.SimulationPlotsTab);
            title(app.CminWOax, 'Threshold')
            xlabel(app.CminWOax, 'Washout Time (hr)')
            ylabel(app.CminWOax, 'Norm Prolif Resp')
            app.CavgWOax = uiaxes(app.SimulationPlotsTab);
            title(app.CavgWOax, 'AUC')
            xlabel(app.CavgWOax, 'Washout Time (hr)')
            ylabel(app.CavgWOax, 'Norm Prolif Resp')
            app.CmaxWOax = uiaxes(app.SimulationPlotsTab);
            title(app.CmaxWOax, 'C_{max}')
            xlabel(app.CmaxWOax, 'Washout Time (hr)')
            ylabel(app.CmaxWOax, 'Norm Prolif Resp')
            app.CminIC50ax = uiaxes(app.SimulationPlotsTab);
            title(app.CminIC50ax, 'Threshold')
            xlabel(app.CminIC50ax, 'Inhibitor Conc. (nM)')
            ylabel(app.CminIC50ax, 'Norm Prolif Resp')
            app.CminIC50ax.XScale = 'log';
            app.CavgIC50ax = uiaxes(app.SimulationPlotsTab);
            title(app.CavgIC50ax, 'AUC')
            xlabel(app.CavgIC50ax, 'Inhibitor Conc (nM)')
            ylabel(app.CavgIC50ax, 'Norm Prolif Resp')
            app.CavgIC50ax.XScale = 'log';
            app.CmaxIC50ax = uiaxes(app.SimulationPlotsTab);
            title(app.CmaxIC50ax, 'C_{max}')
            xlabel(app.CmaxIC50ax, 'Inhibitor Conc. (nM)')
            ylabel(app.CmaxIC50ax, 'Norm Prolif Resp')
            app.CmaxIC50ax.XScale = 'log';
            app.ThresholdparamPanel = uipanel(app.SimulationPlotsTab);
            app.ThresholdparamPanel.Title = 'Threshold param';
            app.IC50EditFieldLabel = uilabel(app.ThresholdparamPanel);
            app.IC50EditFieldLabel.Text = 'IC50';
            app.IC50EditField_Cmin = uieditfield(app.ThresholdparamPanel, 'numeric');
            app.IC50EditField_Cmin.Limits = [0 Inf];
            app.IC50EditField_Cmin.ValueChangedFcn = createCallbackFcn(app, @IC50EditField_CminValueChanged, true);
            app.HillCoefLabel = uilabel(app.ThresholdparamPanel);
            app.HillCoefLabel.Text = 'Hill Coef';
            app.nEditField_Cmin = uieditfield(app.ThresholdparamPanel, 'numeric');
            app.nEditField_Cmin.Limits = [0.01 Inf];
            app.nEditField_Cmin.ValueChangedFcn = createCallbackFcn(app, @nEditField_CminValueChanged, true);
            app.nEditField_Cmin.Value = 0.01;
            app.fracnonrespEditFieldLabel_4 = uilabel(app.ThresholdparamPanel);
            app.fracnonrespEditFieldLabel_4.Text = 'frac non-resp';
            app.fracnonrespEditField_Cmin = uieditfield(app.ThresholdparamPanel, 'numeric');
            app.fracnonrespEditField_Cmin.Limits = [0 1];
            app.fracnonrespEditField_Cmin.ValueChangedFcn = createCallbackFcn(app, @fracnonrespEditField_CminValueChanged, true);
            app.FitIndividButton_Cmin = uibutton(app.ThresholdparamPanel, 'push');
            app.FitIndividButton_Cmin.ButtonPushedFcn = createCallbackFcn(app, @FitIndividButton_CminPushed, true);
            app.FitIndividButton_Cmin.Text = 'Fit Individ';
            app.LockLabel = uilabel(app.ThresholdparamPanel);
            app.LockLabel.Text = 'Lock?';
            app.IC50lockBtn_Cmin = uicheckbox(app.ThresholdparamPanel);
            app.IC50lockBtn_Cmin.Text = '';
            app.nlockBtn_Cmin = uicheckbox(app.ThresholdparamPanel);
            app.nlockBtn_Cmin.Text = '';
            app.fracNRlockBtn_Cmin = uicheckbox(app.ThresholdparamPanel);
            app.fracNRlockBtn_Cmin.Text = '';
            app.fracNRlockBtn_Cmin.Value = true;
            app.FittoButtonGroup_Cmin = uibuttongroup(app.ThresholdparamPanel);
            app.FittoButtonGroup_Cmin.Title = 'Fit to...';
            app.IC50onlyButton_Cmin = uiradiobutton(app.FittoButtonGroup_Cmin);
            app.IC50onlyButton_Cmin.Text = 'IC50 only';
            app.IC50onlyButton_Cmin.Value = true;
            app.WashoutOnlyButton_Cmin = uiradiobutton(app.FittoButtonGroup_Cmin);
            app.WashoutOnlyButton_Cmin.Text = {'Washout '; 'Only'};
            app.BothButton_Cmin = uiradiobutton(app.FittoButtonGroup_Cmin);
            app.BothButton_Cmin.Text = 'Both';
            app.EmaxLabel = uilabel(app.ThresholdparamPanel);
            app.EmaxLabel.Text = 'Emax';
            app.EmaxEditField_Cmin = uieditfield(app.ThresholdparamPanel, 'numeric');
            app.EmaxEditField_Cmin.Limits = [0 Inf];
            app.EmaxEditField_Cmin.ValueChangedFcn = createCallbackFcn(app, @EmaxEditField_CminValueChanged, true);
            app.EmaxLockBtn_Cmin = uicheckbox(app.ThresholdparamPanel);
            app.EmaxLockBtn_Cmin.Text = '';
            app.CmaxparamPanel = uipanel(app.SimulationPlotsTab);
            app.CmaxparamPanel.Title = 'Cmax param';
            app.FracnonrespLabel = uilabel(app.CmaxparamPanel);
            app.FracnonrespLabel.Text = 'Frac non-resp';
            app.fracnonrespEditField_Cmax = uieditfield(app.CmaxparamPanel, 'numeric');
            app.fracnonrespEditField_Cmax.Limits = [0 1];
            app.fracnonrespEditField_Cmax.ValueChangedFcn = createCallbackFcn(app, @fracnonrespEditField_CmaxValueChanged, true);
            app.S_maxLabel = uilabel(app.CmaxparamPanel);
            app.S_maxLabel.Text = 'S_max';
            app.IC50EditField_Cmax = uieditfield(app.CmaxparamPanel, 'numeric');
            app.IC50EditField_Cmax.Limits = [0 Inf];
            app.IC50EditField_Cmax.ValueChangedFcn = createCallbackFcn(app, @IC50EditField_CmaxValueChanged, true);
            app.HilCoefLabel = uilabel(app.CmaxparamPanel);
            app.HilCoefLabel.Text = 'n - Cmax Exp.';
            app.nEditField_Cmax = uieditfield(app.CmaxparamPanel, 'numeric');
            app.nEditField_Cmax.Limits = [0.01 Inf];
            app.nEditField_Cmax.ValueChangedFcn = createCallbackFcn(app, @nEditField_CmaxValueChanged, true);
            app.nEditField_Cmax.Value = 0.01;
            app.FitIndividButton_Cmax = uibutton(app.CmaxparamPanel, 'push');
            app.FitIndividButton_Cmax.ButtonPushedFcn = createCallbackFcn(app, @FitIndividButton_CmaxPushed, true);
            app.FitIndividButton_Cmax.Text = 'Fit Individ';
            app.LockLabel_3 = uilabel(app.CmaxparamPanel);
            app.LockLabel_3.Text = 'Lock?';
            app.IC50lockBtn_Cmax = uicheckbox(app.CmaxparamPanel);
            app.IC50lockBtn_Cmax.Text = '';
            app.nlockBtn_Cmax = uicheckbox(app.CmaxparamPanel);
            app.nlockBtn_Cmax.Text = '';
            app.fracNRlockBtn_Cmax = uicheckbox(app.CmaxparamPanel);
            app.fracNRlockBtn_Cmax.Text = '';
            app.FittoButtonGroup_Cmax = uibuttongroup(app.CmaxparamPanel);
            app.FittoButtonGroup_Cmax.Title = 'Fit to...';
            app.IC50onlyButton_Cmax = uiradiobutton(app.FittoButtonGroup_Cmax);
            app.IC50onlyButton_Cmax.Text = 'IC50 only';
            app.IC50onlyButton_Cmax.Value = true;
            app.WashoutOnlyButton_Cmax = uiradiobutton(app.FittoButtonGroup_Cmax);
            app.WashoutOnlyButton_Cmax.Text = {'Washout '; 'Only'};
            app.BothButton_Cmax = uiradiobutton(app.FittoButtonGroup_Cmax);
            app.BothButton_Cmax.Text = 'Both';
            app.AUCparamPanel = uipanel(app.SimulationPlotsTab);
            app.AUCparamPanel.Title = 'AUC param';
            app.S_AUCLabel = uilabel(app.AUCparamPanel);
            app.S_AUCLabel.Text = 'S_AUC';
            app.IC50EditField_Cavg = uieditfield(app.AUCparamPanel, 'numeric');
            app.IC50EditField_Cavg.Limits = [0 Inf];
            app.IC50EditField_Cavg.ValueChangedFcn = createCallbackFcn(app, @IC50EditField_CavgValueChanged, true);
            app.FracnonrespLabel_2 = uilabel(app.AUCparamPanel);
            app.FracnonrespLabel_2.Text = 'Frac non-resp';
            app.fracnonrespEditField_Cavg = uieditfield(app.AUCparamPanel, 'numeric');
            app.fracnonrespEditField_Cavg.Limits = [0 1];
            app.fracnonrespEditField_Cavg.ValueChangedFcn = createCallbackFcn(app, @fracnonrespEditField_CavgValueChanged, true);
            app.FitIndividButton_Cavg = uibutton(app.AUCparamPanel, 'push');
            app.FitIndividButton_Cavg.ButtonPushedFcn = createCallbackFcn(app, @FitIndividButton_CavgPushed, true);
            app.FitIndividButton_Cavg.Text = 'Fit Individ';
            app.IC50lockBtn_Cavg = uicheckbox(app.AUCparamPanel);
            app.IC50lockBtn_Cavg.Text = '';
            app.fracNRlockBtn_Cavg = uicheckbox(app.AUCparamPanel);
            app.fracNRlockBtn_Cavg.Text = '';
            app.LockLabel_2 = uilabel(app.AUCparamPanel);
            app.LockLabel_2.Text = 'Lock?';
            app.FittoButtonGroup_Cavg = uibuttongroup(app.AUCparamPanel);
            app.FittoButtonGroup_Cavg.Title = 'Fit to...';
            app.IC50onlyButton_Cavg = uiradiobutton(app.FittoButtonGroup_Cavg);
            app.IC50onlyButton_Cavg.Text = 'IC50 only';
            app.IC50onlyButton_Cavg.Value = true;
            app.WashoutOnlyButton_Cavg = uiradiobutton(app.FittoButtonGroup_Cavg);
            app.WashoutOnlyButton_Cavg.Text = {'Washout '; 'Only'};
            app.BothButton_Cavg = uiradiobutton(app.FittoButtonGroup_Cavg);
            app.BothButton_Cavg.Text = 'Both';
            app.FittoButtonGroup_All = uibuttongroup(app.SimulationPlotsTab);
            app.FittoButtonGroup_All.Title = 'Fit to...';
            app.IC50onlyButton_All = uiradiobutton(app.FittoButtonGroup_All);
            app.IC50onlyButton_All.Text = 'IC50 only';
            app.IC50onlyButton_All.Value = true;
            app.WashoutOnlyButton_All = uiradiobutton(app.FittoButtonGroup_All);
            app.WashoutOnlyButton_All.Text = {'Washout '; 'Only'};
            app.BothButton_All = uiradiobutton(app.FittoButtonGroup_All);
            app.BothButton_All.Text = 'Both';
            app.FitallButton = uibutton(app.SimulationPlotsTab, 'push');
            app.FitallButton.ButtonPushedFcn = createCallbackFcn(app, @FitallButtonPushed, true);
            app.FitallButton.Text = 'Fit all';
            app.ShowplotsLabel = uilabel(app.SimulationPlotsTab);
            app.ShowplotsLabel.Text = 'Show plots';
            app.DataCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.DataCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataCheckBoxValueChanged, true);
            app.DataCheckBox.Text = 'Data';
            app.ManualCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.ManualCheckBox.Value = true;
            app.IC50fitCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.IC50fitCheckBox.ValueChangedFcn = createCallbackFcn(app, @IC50fitCheckBoxValueChanged, true);
            app.IC50fitCheckBox.Text = 'IC50 fit';
            app.WashfitCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.WashfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @WashfitCheckBoxValueChanged, true);
            app.WashfitCheckBox.Text = 'Wash fit';
            app.BothfitCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.BothfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @BothfitCheckBoxValueChanged, true);
            app.BothfitCheckBox.Text = 'Both fit';
            app.AllCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.NoneCheckBox = uicheckbox(app.SimulationPlotsTab);
            app.NoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBoxValueChanged, true);
            app.NoneCheckBox.Text = 'None';
            app.ExtractSubPlotsButton = uibutton(app.SimulationPlotsTab, 'push');
            app.ExtractSubPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSubPlotsButtonPushed, true);
            app.ExtractSubPlotsButton.Text = 'Extract Sub Plots';
            app.ExtractIndPlotsButton = uibutton(app.SimulationPlotsTab, 'push');
            app.ExtractIndPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractIndPlotsButtonPushed, true);
            app.ExtractIndPlotsButton.Text = 'Extract Ind. Plots';
            app.ResidualsTab = uitab(app.TabGroup);
            app.ResidualsTab.Title = 'Residuals';
            app.CminResidAx = uiaxes(app.ResidualsTab);
            title(app.CminResidAx, 'Threshold')
            xlabel(app.CminResidAx, 'Model Prediction')
            ylabel(app.CminResidAx, 'Experimental WO Data')
            app.CavgResidAx = uiaxes(app.ResidualsTab);
            title(app.CavgResidAx, 'AUC')
            xlabel(app.CavgResidAx, 'Model Prediction')
            ylabel(app.CavgResidAx, 'Experimental WO Data')
            app.CmaxResidAx = uiaxes(app.ResidualsTab);
            title(app.CmaxResidAx, 'C_{max}')
            xlabel(app.CmaxResidAx, 'Model Prediction')
            ylabel(app.CmaxResidAx, 'Experimental WO Data')
            app.CminResidAx_IC50 = uiaxes(app.ResidualsTab);
            title(app.CminResidAx_IC50, 'Threshold')
            xlabel(app.CminResidAx_IC50, 'Model Prediction')
            ylabel(app.CminResidAx_IC50, 'Experimental IC_{50} Data')
            zlabel(app.CminResidAx_IC50, 'Z')
            app.CavgResidAx_IC50 = uiaxes(app.ResidualsTab);
            title(app.CavgResidAx_IC50, 'AUC')
            xlabel(app.CavgResidAx_IC50, 'Model Prediction')
            ylabel(app.CavgResidAx_IC50, 'Experimental IC_{50} Data')
            zlabel(app.CavgResidAx_IC50, 'Z')
            app.CmaxResidAx_IC50 = uiaxes(app.ResidualsTab);
            title(app.CmaxResidAx_IC50, 'C_{max}')
            xlabel(app.CmaxResidAx_IC50, 'Model Prediction')
            ylabel(app.CmaxResidAx_IC50, 'Experimental IC_{50} Data')
            zlabel(app.CmaxResidAx_IC50, 'Z')
            app.SelResButtonGroup = uibuttongroup(app.ResidualsTab);
            app.SelResButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelResButtonGroupSelectionChanged, true);
            app.SelResButtonGroup.Title = 'Show residuals from...';
            app.IC50onlyfitResidButton = uiradiobutton(app.SelResButtonGroup);
            app.IC50onlyfitResidButton.Text = 'IC50 only fit';
            app.IC50onlyfitResidButton.Value = true;
            app.WashoutonlyfitResidButton = uiradiobutton(app.SelResButtonGroup);
            app.WashoutonlyfitResidButton.Text = 'Washout only fit';
            app.FitbothResidButton = uiradiobutton(app.SelResButtonGroup);
            app.FitbothResidButton.Text = 'Fit both';
            app.ExtractSubPlotsButton_resid = uibutton(app.ResidualsTab, 'push');
            app.ExtractSubPlotsButton_resid.ButtonPushedFcn = createCallbackFcn(app, @ExtractSubPlotsButton_residPushed, true);
            app.ExtractSubPlotsButton_resid.Text = 'Extract Sub Plots';
            app.ExtractIndPlotsButton_resid = uibutton(app.ResidualsTab, 'push');
            app.ExtractIndPlotsButton_resid.ButtonPushedFcn = createCallbackFcn(app, @ExtractIndPlotsButton_residPushed, true);
            app.ExtractIndPlotsButton_resid.Text = 'Extract Ind. Plots';
            app.WashoutdataresidualplotsLabel = uilabel(app.ResidualsTab);
            app.WashoutdataresidualplotsLabel.Text = 'Washout data residual plots';
            app.FitParameterResultsTab = uitab(app.TabGroup);
            app.FitParameterResultsTab.Title = 'Fit Parameter Results';
            app.WashoutonlyfitPanel = uipanel(app.FitParameterResultsTab);
            app.WashoutonlyfitPanel.Title = 'Washout only fit';
            app.FitData_WOfit = uitable(app.WashoutonlyfitPanel);
            app.FitData_WOfit.ColumnName = {'Fit Value'; 'Param CI LB'; 'Param CI UB'; 'Init param'; 'Lower Limit'; 'Upper Limit'; 'AIC'; 'BIC'};
            app.FitData_WOfit.RowName = {};
            app.SetCurrentParamsasButton_WO = uibutton(app.WashoutonlyfitPanel, 'push');
            app.SetCurrentParamsasButton_WO.ButtonPushedFcn = createCallbackFcn(app, @SetCurrentParamsasButton_WOPushed, true);
            app.SetCurrentParamsasButton_WO.Text = {'Set Current '; 'Params as...'};
            app.paramtypeButtonGroup_WO = uibuttongroup(app.WashoutonlyfitPanel);
            app.paramtypeButtonGroup_WO.Title = 'param type';
            app.InitialParamButton_WO = uiradiobutton(app.paramtypeButtonGroup_WO);
            app.InitialParamButton_WO.Text = 'Initial Param';
            app.InitialParamButton_WO.Value = true;
            app.BoundedFitButton_WO = uiradiobutton(app.paramtypeButtonGroup_WO);
            app.BoundedFitButton_WO.Text = 'Bounded Fit';
            app.forthesemodelsPanel_WO = uipanel(app.WashoutonlyfitPanel);
            app.forthesemodelsPanel_WO.Title = 'for these models';
            app.CminCheckBox_WO = uicheckbox(app.forthesemodelsPanel_WO);
            app.CminCheckBox_WO.ValueChangedFcn = createCallbackFcn(app, @CminCheckBox_WOValueChanged, true);
            app.CminCheckBox_WO.Text = 'Threshold';
            app.CminCheckBox_WO.Value = true;
            app.CavgCheckBox_WO = uicheckbox(app.forthesemodelsPanel_WO);
            app.CavgCheckBox_WO.ValueChangedFcn = createCallbackFcn(app, @CavgCheckBox_WOValueChanged, true);
            app.CavgCheckBox_WO.Text = 'AUC';
            app.CavgCheckBox_WO.Value = true;
            app.CmaxCheckBox_WO = uicheckbox(app.forthesemodelsPanel_WO);
            app.CmaxCheckBox_WO.ValueChangedFcn = createCallbackFcn(app, @CmaxCheckBox_WOValueChanged, true);
            app.CmaxCheckBox_WO.Text = 'Cmax';
            app.CmaxCheckBox_WO.Value = true;
            app.AllCheckBox_WO = uicheckbox(app.forthesemodelsPanel_WO);
            app.AllCheckBox_WO.ValueChangedFcn = createCallbackFcn(app, @AllCheckBox_WOValueChanged, true);
            app.AllCheckBox_WO.Text = 'All';
            app.AllCheckBox_WO.Value = true;
            app.FittobothPanel = uipanel(app.FitParameterResultsTab);
            app.FittobothPanel.Title = 'Fit to both';
            app.FitData_bothfit = uitable(app.FittobothPanel);
            app.FitData_bothfit.ColumnName = {'Fit Value'; 'Param CI LB'; 'Param CI UB'; 'Init param'; 'Lower Limit'; 'Upper Limit'; 'AIC'; 'BIC'};
            app.FitData_bothfit.RowName = {};
            app.SetCurrentParamsasButton_both = uibutton(app.FittobothPanel, 'push');
            app.SetCurrentParamsasButton_both.ButtonPushedFcn = createCallbackFcn(app, @SetCurrentParamsasButton_bothPushed, true);
            app.SetCurrentParamsasButton_both.Text = {'Set Current '; 'Params as...'};
            app.paramtypeButtonGroup_both = uibuttongroup(app.FittobothPanel);
            app.paramtypeButtonGroup_both.Title = 'param type';
            app.InitialParamButton_both = uiradiobutton(app.paramtypeButtonGroup_both);
            app.InitialParamButton_both.Text = 'Initial Param';
            app.InitialParamButton_both.Value = true;
            app.BoundedFitButton_both = uiradiobutton(app.paramtypeButtonGroup_both);
            app.BoundedFitButton_both.Text = 'Bounded Fit';
            app.forthesemodelsPanel_both = uipanel(app.FittobothPanel);
            app.forthesemodelsPanel_both.Title = 'for these models';
            app.CminCheckBox_both = uicheckbox(app.forthesemodelsPanel_both);
            app.CminCheckBox_both.ValueChangedFcn = createCallbackFcn(app, @CminCheckBox_bothValueChanged, true);
            app.CminCheckBox_both.Text = 'Threshold';
            app.CminCheckBox_both.Value = true;
            app.CavgCheckBox_both = uicheckbox(app.forthesemodelsPanel_both);
            app.CavgCheckBox_both.ValueChangedFcn = createCallbackFcn(app, @CavgCheckBox_bothValueChanged, true);
            app.CavgCheckBox_both.Text = 'AUC';
            app.CavgCheckBox_both.Value = true;
            app.CmaxCheckBox_both = uicheckbox(app.forthesemodelsPanel_both);
            app.CmaxCheckBox_both.ValueChangedFcn = createCallbackFcn(app, @CmaxCheckBox_bothValueChanged, true);
            app.CmaxCheckBox_both.Text = 'Cmax';
            app.CmaxCheckBox_both.Value = true;
            app.AllCheckBox_both = uicheckbox(app.forthesemodelsPanel_both);
            app.AllCheckBox_both.ValueChangedFcn = createCallbackFcn(app, @AllCheckBox_bothValueChanged, true);
            app.AllCheckBox_both.Text = 'All';
            app.AllCheckBox_both.Value = true;
            app.IC50onlyPanel = uipanel(app.FitParameterResultsTab);
            app.IC50onlyPanel.Title = 'IC50 only';
            app.FitData_IC50fit = uitable(app.IC50onlyPanel);
            app.FitData_IC50fit.ColumnName = {'Fit Value'; 'Param CI LB'; 'Param CI UB'; 'Init param'; 'Lower Limit'; 'Upper Limit'; 'AIC'; 'BIC'};
            app.FitData_IC50fit.RowName = {};
            app.forthesemodelsPanel_IC50 = uipanel(app.IC50onlyPanel);
            app.forthesemodelsPanel_IC50.Title = 'for these models';
            app.CminCheckBox_IC50 = uicheckbox(app.forthesemodelsPanel_IC50);
            app.CminCheckBox_IC50.ValueChangedFcn = createCallbackFcn(app, @CminCheckBox_IC50ValueChanged, true);
            app.CminCheckBox_IC50.Text = 'Threshold';
            app.CminCheckBox_IC50.Value = true;
            app.CavgCheckBox_IC50 = uicheckbox(app.forthesemodelsPanel_IC50);
            app.CavgCheckBox_IC50.ValueChangedFcn = createCallbackFcn(app, @CavgCheckBox_IC50ValueChanged, true);
            app.CavgCheckBox_IC50.Text = 'AUC';
            app.CavgCheckBox_IC50.Value = true;
            app.CmaxCheckBox_IC50 = uicheckbox(app.forthesemodelsPanel_IC50);
            app.CmaxCheckBox_IC50.ValueChangedFcn = createCallbackFcn(app, @CmaxCheckBox_IC50ValueChanged, true);
            app.CmaxCheckBox_IC50.Text = 'Cmax';
            app.CmaxCheckBox_IC50.Value = true;
            app.AllCheckBox_IC50 = uicheckbox(app.forthesemodelsPanel_IC50);
            app.AllCheckBox_IC50.ValueChangedFcn = createCallbackFcn(app, @AllCheckBox_IC50ValueChanged, true);
            app.AllCheckBox_IC50.Text = 'All';
            app.AllCheckBox_IC50.Value = true;
            app.paramtypeButtonGroup_IC50 = uibuttongroup(app.IC50onlyPanel);
            app.paramtypeButtonGroup_IC50.Title = 'param type';
            app.InitialParamButton_IC50 = uiradiobutton(app.paramtypeButtonGroup_IC50);
            app.InitialParamButton_IC50.Text = 'Initial Param';
            app.InitialParamButton_IC50.Value = true;
            app.BoundedFitButton_IC50 = uiradiobutton(app.paramtypeButtonGroup_IC50);
            app.BoundedFitButton_IC50.Text = 'Bounded Fit';
            app.SetCurrentParamsasButton_IC50 = uibutton(app.IC50onlyPanel, 'push');
            app.SetCurrentParamsasButton_IC50.ButtonPushedFcn = createCallbackFcn(app, @SetCurrentParamsasButton_IC50Pushed, true);
            app.SetCurrentParamsasButton_IC50.Text = {'Set Current '; 'Params as...'};
            app.TextArea_2 = uitextarea(app.FitParameterResultsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'*Warning: confidence intervals on parameter values may be inaccurate if the parameter is near the boundaries'; '* Tables may not update until all 3 model types have been fitted.'};
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.IC50datatable = uitable(app.DataTab);
            app.IC50datatable.ColumnName = {'Norm Cell'; 'Dose'};
            app.IC50datatable.RowName = {};
            app.WOdatatable = uitable(app.DataTab);
            app.WOdatatable.ColumnName = {'Norm Cell'; 'Dose'; 'Duration'; 'Effective AUC Conc'};
            app.WOdatatable.RowName = {};
            app.ImportfromMATLABworkspacetablevariablesPanel = uipanel(app.DataTab);
            app.ImportfromMATLABworkspacetablevariablesPanel.Title = 'Import from MATLAB workspace table variables';
            app.IC50dataWSimportButton = uibutton(app.ImportfromMATLABworkspacetablevariablesPanel, 'push');
            app.IC50dataWSimportButton.ButtonPushedFcn = createCallbackFcn(app, @IC50dataWSimportButtonPushed, true);
            app.IC50dataWSimportButton.Text = {'Import '; 'IC50 data'};
            app.WAOdataWSimportButton = uibutton(app.ImportfromMATLABworkspacetablevariablesPanel, 'push');
            app.WAOdataWSimportButton.ButtonPushedFcn = createCallbackFcn(app, @WAOdataWSimportButtonPushed, true);
            app.WAOdataWSimportButton.Text = {'Import '; 'washout data'};
            app.TextArea_4 = uitextarea(app.ImportfromMATLABworkspacetablevariablesPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Must be a table variable with column variables:'; '*NormCell : Cell number normalized to DMSO control'; '*Dose : Drug concentration'};
            app.TextArea_5 = uitextarea(app.ImportfromMATLABworkspacetablevariablesPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Must be a table variable with column variables:'; '*NormCell : Cell number normalized to DMSO control'; '*Dose : Actual drug concentration applied'; '*Duration : Time of drug application before washout (hr) '; '*EffDose : Equivalent non-washout concentration dose'; '                  with the same AUC'};
            app.ImportfromexceltemplatePanel = uipanel(app.DataTab);
            app.ImportfromexceltemplatePanel.Title = 'Import from excel template';
            app.ImportWOexcelButton = uibutton(app.ImportfromexceltemplatePanel, 'push');
            app.ImportWOexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportWOexcelButtonPushed, true);
            app.ImportWOexcelButton.Text = {'Import washout'; 'spreadsheet'};
            app.IC50datafromwashoutGroup = uibuttongroup(app.ImportfromexceltemplatePanel);
            app.IC50datafromwashoutGroup.Title = 'Use IC50 data from Washout Experiment?';
            app.NoIC50dataButton = uiradiobutton(app.IC50datafromwashoutGroup);
            app.NoIC50dataButton.Text = 'No, Import seperate IC50 data';
            app.NoIC50dataButton.Value = true;
            app.YesIC50dataButton = uiradiobutton(app.IC50datafromwashoutGroup);
            app.YesIC50dataButton.Text = 'Yes';
            app.ImportIC50excelButton = uibutton(app.ImportfromexceltemplatePanel, 'push');
            app.ImportIC50excelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportIC50excelButtonPushed, true);
            app.ImportIC50excelButton.Text = {'Import IC50'; 'spreadsheet'};
            app.NormalizeDataButtonGroup = uibuttongroup(app.ImportfromexceltemplatePanel);
            app.NormalizeDataButtonGroup.Title = 'Normalize Data...';
            app.WithinsheetButton = uiradiobutton(app.NormalizeDataButtonGroup);
            app.WithinsheetButton.Text = 'Within sheet';
            app.WithinsheetButton.Value = true;
            app.AcrossallsheetsButton = uiradiobutton(app.NormalizeDataButtonGroup);
            app.AcrossallsheetsButton.Text = 'Across all sheets';
            app.GrowthParameterFittingTab = uitab(app.TabGroup);
            app.GrowthParameterFittingTab.Title = 'Growth Parameter Fitting';
            app.GrowthAx = uiaxes(app.GrowthParameterFittingTab);
            title(app.GrowthAx, 'Growth Data')
            xlabel(app.GrowthAx, 'Time (h)')
            ylabel(app.GrowthAx, 'Growth Measurement')
            app.GrowthDataTable = uitable(app.GrowthParameterFittingTab);
            app.GrowthDataTable.ColumnName = {'Time (h)'; 'Percent Confluent'};
            app.GrowthDataTable.RowName = {};
            app.ImportGrowthDataPanel = uipanel(app.GrowthParameterFittingTab);
            app.ImportGrowthDataPanel.Title = 'Import Growth Data';
            app.ImportGrowthfromExcelButton = uibutton(app.ImportGrowthDataPanel, 'push');
            app.ImportGrowthfromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGrowthfromExcelButtonPushed, true);
            app.ImportGrowthfromExcelButton.Text = 'Import from Excel';
            app.Label = uilabel(app.ImportGrowthDataPanel);
            app.Label.Text = {'Excel spreadsheet of the growth data with the '; 'follwoing format.'; '*Column A - Time of growth measurement in hours'; '*Column B - Growth measurement '};
            app.GrowthParamTable = uitable(app.GrowthParameterFittingTab);
            app.GrowthParamTable.ColumnName = {'Fit Value'; 'Param LB'; 'Param UB'; 'Init Value'};
            app.GrowthParamTable.RowName = {};
            app.FitGrowthParametersButton = uibutton(app.GrowthParameterFittingTab, 'push');
            app.FitGrowthParametersButton.ButtonPushedFcn = createCallbackFcn(app, @FitGrowthParametersButtonPushed, true);
            app.FitGrowthParametersButton.Text = 'Fit Growth Parameters';
            app.SetFitGrowthParams = uibutton(app.GrowthParameterFittingTab, 'push');
            app.SetFitGrowthParams.ButtonPushedFcn = createCallbackFcn(app, @SetFitGrowthParamsButtonPushed, true);
            app.SetFitGrowthParams.Text = 'Apply Fitted Parameters to Model';
            app.OtherOptionsTab = uitab(app.TabGroup);
            app.OtherOptionsTab.Title = 'Other Options';
            app.DrugConcentrationcorrectionsPanel = uipanel(app.OtherOptionsTab);
            app.DrugConcentrationcorrectionsPanel.Title = 'Drug Concentration corrections';
            app.storderdegradationrateEditFieldLabel = uilabel(app.DrugConcentrationcorrectionsPanel);
            app.storderdegradationrateEditFieldLabel.Text = '1st order degradation rate';
            app.degradationrateEditField = uieditfield(app.DrugConcentrationcorrectionsPanel, 'numeric');
            app.degradationrateEditField.ValueChangedFcn = createCallbackFcn(app, @degradationrateEditFieldValueChanged, true);
            app.hrLabel_2 = uilabel(app.DrugConcentrationcorrectionsPanel);
            app.hrLabel_2.Text = '1/hr';
            app.fraccarryoverafterwashoutEditFieldLabel = uilabel(app.DrugConcentrationcorrectionsPanel);
            app.fraccarryoverafterwashoutEditFieldLabel.Text = 'frac carry over after washout';
            app.fraccarryoverafterwashoutEditField = uieditfield(app.DrugConcentrationcorrectionsPanel, 'numeric');
            app.fraccarryoverafterwashoutEditField.ValueChangedFcn = createCallbackFcn(app, @fraccarryoverafterwashoutEditFieldValueChanged, true);
            app.UpdateModelSettingsButton = uibutton(app.DrugConcentrationcorrectionsPanel, 'push');
            app.UpdateModelSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateModelSettingsButtonPushed, true);
            app.UpdateModelSettingsButton.Text = 'Update Model Settings';
            app.PlotOptionsPanel = uipanel(app.OtherOptionsTab);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.DataPlotFormatButtonGroup = uibuttongroup(app.PlotOptionsPanel);
            app.DataPlotFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataPlotFormatButtonGroupSelectionChanged, true);
            app.DataPlotFormatButtonGroup.Title = 'Data Plot Format';
            app.IndividualButton = uitogglebutton(app.DataPlotFormatButtonGroup);
            app.IndividualButton.Text = 'Individual';
            app.IndividualButton.Value = true;
            app.SEMButton = uitogglebutton(app.DataPlotFormatButtonGroup);
            app.SEMButton.Text = 'SEM';
            app.MarkerSizeEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.MarkerSizeEditField.Limits = [0.001 Inf];
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.MarkerSizeEditField.Value = 5;
            app.UpdatePlotDisplayButton = uibutton(app.PlotOptionsPanel, 'push');
            app.UpdatePlotDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotDisplayButtonPushed, true);
            app.UpdatePlotDisplayButton.Text = 'Update Plot Display';
            app.WashoutColormapDropDownLabel = uilabel(app.PlotOptionsPanel);
            app.WashoutColormapDropDownLabel.Text = 'Washout Colormap';
            app.WashoutColormapDropDown = uidropdown(app.PlotOptionsPanel);
            app.WashoutColormapDropDown.Items = {'copper', 'lines', 'turbo', 'hsv', 'parula', 'jet', 'cool', 'spring', 'summer', 'autumn', 'winter', 'abyss'};
            app.WashoutColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @WashoutColormapDropDownValueChanged, true);
            app.WashoutColormapDropDown.Value = 'copper';
            app.FitparameterlimitsPanel = uipanel(app.OtherOptionsTab);
            app.FitparameterlimitsPanel.Title = 'Fit parameter limits';
            app.ThresholdmodelPanel = uipanel(app.FitparameterlimitsPanel);
            app.ThresholdmodelPanel.Title = 'Threshold model';
            app.IC50EditFieldLabel_4 = uilabel(app.ThresholdmodelPanel);
            app.IC50EditFieldLabel_4.Text = 'IC50';
            app.Thresh_IC50_LL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_IC50_LL.Limits = [0 Inf];
            app.Thresh_IC50_LL.ValueChangedFcn = createCallbackFcn(app, @Thresh_IC50_LLValueChanged, true);
            app.MinLabel = uilabel(app.ThresholdmodelPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.ThresholdmodelPanel);
            app.MaxLabel.Text = 'Max';
            app.Thresh_IC50_UL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_IC50_UL.Limits = [0 Inf];
            app.Thresh_IC50_UL.ValueChangedFcn = createCallbackFcn(app, @Thresh_IC50_ULValueChanged, true);
            app.hillcoefEditFieldLabel = uilabel(app.ThresholdmodelPanel);
            app.hillcoefEditFieldLabel.Text = 'hill coef';
            app.Thresh_hill_LL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_hill_LL.Limits = [0.01 Inf];
            app.Thresh_hill_LL.ValueChangedFcn = createCallbackFcn(app, @Thresh_hill_LLValueChanged, true);
            app.Thresh_hill_LL.Value = 0.01;
            app.Thresh_hill_UL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_hill_UL.Limits = [0.01 Inf];
            app.Thresh_hill_UL.ValueChangedFcn = createCallbackFcn(app, @Thresh_hill_ULValueChanged, true);
            app.Thresh_hill_UL.Value = 0.01;
            app.EmaxEditFieldLabel = uilabel(app.ThresholdmodelPanel);
            app.EmaxEditFieldLabel.Text = 'Emax';
            app.Thresh_Emax_LL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_Emax_LL.Limits = [0 Inf];
            app.Thresh_Emax_LL.ValueChangedFcn = createCallbackFcn(app, @Thresh_Emax_LLValueChanged, true);
            app.Thresh_Emax_UL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_Emax_UL.Limits = [0 Inf];
            app.Thresh_Emax_UL.ValueChangedFcn = createCallbackFcn(app, @Thresh_Emax_ULValueChanged, true);
            app.fracnonrespEditFieldLabel_5 = uilabel(app.ThresholdmodelPanel);
            app.fracnonrespEditFieldLabel_5.Text = 'frac non-resp';
            app.Thresh_fracNR_UL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_fracNR_UL.Limits = [0 1];
            app.Thresh_fracNR_UL.ValueChangedFcn = createCallbackFcn(app, @Thresh_fracNR_ULValueChanged, true);
            app.Thresh_fracNR_LL = uieditfield(app.ThresholdmodelPanel, 'numeric');
            app.Thresh_fracNR_LL.Editable = 'off';
            app.AUCmodelPanel = uipanel(app.FitparameterlimitsPanel);
            app.AUCmodelPanel.Title = 'AUC model';
            app.MinLabel_2 = uilabel(app.AUCmodelPanel);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.AUCmodelPanel);
            app.MaxLabel_2.Text = 'Max';
            app.S_AUCLabel_2 = uilabel(app.AUCmodelPanel);
            app.S_AUCLabel_2.Text = 'S_AUC';
            app.AUC_IC50_LL = uieditfield(app.AUCmodelPanel, 'numeric');
            app.AUC_IC50_LL.Limits = [0 Inf];
            app.AUC_IC50_LL.ValueChangedFcn = createCallbackFcn(app, @AUC_IC50_LLValueChanged, true);
            app.AUC_IC50_UL = uieditfield(app.AUCmodelPanel, 'numeric');
            app.AUC_IC50_UL.Limits = [0 Inf];
            app.AUC_IC50_UL.ValueChangedFcn = createCallbackFcn(app, @AUC_IC50_ULValueChanged, true);
            app.fracnonrespEditFieldLabel_6 = uilabel(app.AUCmodelPanel);
            app.fracnonrespEditFieldLabel_6.Text = 'frac non-resp';
            app.AUC_fracNR_UL = uieditfield(app.AUCmodelPanel, 'numeric');
            app.AUC_fracNR_UL.Limits = [0 1];
            app.AUC_fracNR_UL.ValueChangedFcn = createCallbackFcn(app, @AUC_fracNR_ULValueChanged, true);
            app.AUC_fracNR_LL = uieditfield(app.AUCmodelPanel, 'numeric');
            app.AUC_fracNR_LL.Editable = 'off';
            app.CmaxModelPanel = uipanel(app.FitparameterlimitsPanel);
            app.CmaxModelPanel.Title = 'Cmax Model';
            app.S_maxLabel_2 = uilabel(app.CmaxModelPanel);
            app.S_maxLabel_2.Text = 'S_max';
            app.Cmax_IC50_LL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_IC50_LL.Limits = [0 Inf];
            app.Cmax_IC50_LL.ValueChangedFcn = createCallbackFcn(app, @Cmax_IC50_LLValueChanged, true);
            app.MinLabel_3 = uilabel(app.CmaxModelPanel);
            app.MinLabel_3.Text = 'Min';
            app.MaxLabel_3 = uilabel(app.CmaxModelPanel);
            app.MaxLabel_3.Text = 'Max';
            app.Cmax_IC50_UL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_IC50_UL.Limits = [0 Inf];
            app.Cmax_IC50_UL.ValueChangedFcn = createCallbackFcn(app, @Cmax_IC50_ULValueChanged, true);
            app.Cmax_hill_UL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_hill_UL.Limits = [1 Inf];
            app.Cmax_hill_UL.ValueChangedFcn = createCallbackFcn(app, @Cmax_hill_ULValueChanged, true);
            app.Cmax_hill_UL.Value = 1;
            app.fracnonrespEditFieldLabel_7 = uilabel(app.CmaxModelPanel);
            app.fracnonrespEditFieldLabel_7.Text = 'frac non-resp';
            app.Cmax_fracNR_UL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_fracNR_UL.Limits = [0 1];
            app.Cmax_fracNR_UL.ValueChangedFcn = createCallbackFcn(app, @Cmax_fracNR_ULValueChanged, true);
            app.Cmax_fracNR_LL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_fracNR_LL.Editable = 'off';
            app.hillcoefLabel = uilabel(app.CmaxModelPanel);
            app.hillcoefLabel.Text = 'n - Cmax Exp.';
            app.Cmax_hill_LL = uieditfield(app.CmaxModelPanel, 'numeric');
            app.Cmax_hill_LL.Limits = [1 Inf];
            app.Cmax_hill_LL.ValueChangedFcn = createCallbackFcn(app, @Cmax_hill_LLValueChanged, true);
            app.Cmax_hill_LL.Value = 1;
            app.SimultaneousfittowashoutandIC50dataPanel = uipanel(app.OtherOptionsTab);
            app.SimultaneousfittowashoutandIC50dataPanel.Title = 'Simultaneous fit to washout and IC50 data';
            app.WeightBothFit = uicheckbox(app.SimultaneousfittowashoutandIC50dataPanel);
            app.WeightBothFit.ValueChangedFcn = createCallbackFcn(app, @WeightBothFitValueChanged, true);
            app.WeightBothFit.Text = 'weight residuals by number of data points in each data set to normalize for differences in replicates?';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GenerateReportButton = uibutton(app.ReportTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.CompoundMetadataPanel = uipanel(app.ReportTab);
            app.CompoundMetadataPanel.Title = 'Compound Metadata';
            app.CompNumberEditFieldLabel = uilabel(app.CompoundMetadataPanel);
            app.CompNumberEditFieldLabel.Text = 'Comp. Number';
            app.CompNumberEditField = uieditfield(app.CompoundMetadataPanel, 'text');
            app.CompNumberEditField.ValueChangedFcn = createCallbackFcn(app, @CompNumberEditFieldValueChanged, true);
            app.NameifAvailEditFieldLabel = uilabel(app.CompoundMetadataPanel);
            app.NameifAvailEditFieldLabel.Text = 'Name (if Avail)';
            app.CompNameEditField = uieditfield(app.CompoundMetadataPanel, 'text');
            app.CompNameEditField.ValueChangedFcn = createCallbackFcn(app, @CompNameEditFieldValueChanged, true);
            app.TargetEditFieldLabel = uilabel(app.CompoundMetadataPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.CompoundMetadataPanel, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.ExperimentalMetadataPanel = uipanel(app.ReportTab);
            app.ExperimentalMetadataPanel.Title = 'Experimental Metadata';
            app.CellTypeEditFieldLabel = uilabel(app.ExperimentalMetadataPanel);
            app.CellTypeEditFieldLabel.Text = 'Cell Type';
            app.CellTypeEditField = uieditfield(app.ExperimentalMetadataPanel, 'text');
            app.CellTypeEditField.ValueChangedFcn = createCallbackFcn(app, @CellTypeEditFieldValueChanged, true);
            app.DatesEditFieldLabel = uilabel(app.ExperimentalMetadataPanel);
            app.DatesEditFieldLabel.Text = 'Dates';
            app.DatesEditField = uieditfield(app.ExperimentalMetadataPanel, 'text');
            app.DatesEditField.ValueChangedFcn = createCallbackFcn(app, @DatesEditFieldValueChanged, true);
            app.SourceEditFieldLabel = uilabel(app.ExperimentalMetadataPanel);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.ExperimentalMetadataPanel, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.NumberofReplEditFieldLabel = uilabel(app.ExperimentalMetadataPanel);
            app.NumberofReplEditFieldLabel.Text = 'Number of Repl.';
            app.NumReplEditField = uieditfield(app.ExperimentalMetadataPanel, 'numeric');
            app.NumReplEditField.Limits = [0 Inf];
            app.NumReplEditField.ValueChangedFcn = createCallbackFcn(app, @NumReplEditFieldValueChanged, true);
            app.AnalysisOutcomePanel = uipanel(app.ReportTab);
            app.AnalysisOutcomePanel.Title = 'Analysis Outcome';
            app.PKDriverDropDownLabel = uilabel(app.AnalysisOutcomePanel);
            app.PKDriverDropDownLabel.Text = 'PK Driver';
            app.PKDriverDropDown = uidropdown(app.AnalysisOutcomePanel);
            app.PKDriverDropDown.Items = {'TBD', ' Threshold', ' AUC', ' Cmax'};
            app.PKDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @PKDriverDropDownValueChanged, true);
            app.PKDriverDropDown.Value = 'TBD';
            app.ConfidenceDropDownLabel = uilabel(app.AnalysisOutcomePanel);
            app.ConfidenceDropDownLabel.Text = 'Confidence';
            app.ConfidenceDropDown = uidropdown(app.AnalysisOutcomePanel);
            app.ConfidenceDropDown.Items = {'0 - TBD', ' 1 - Low conf.', ' 2 - Medium-low conf.', ' 3 - Medium conf.', ' 4 - Medium-high conf.', ' 5 - High conf.'};
            app.ConfidenceDropDown.ItemsData = {'0', '1', '2', '3', '4', '5'};
            app.ConfidenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfidenceDropDownValueChanged, true);
            app.ConfidenceDropDown.Value = '0';
            app.CommentsTextAreaLabel = uilabel(app.AnalysisOutcomePanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.FinalCommentsTextArea = uitextarea(app.AnalysisOutcomePanel);
            app.FinalCommentsTextArea.ValueChangedFcn = createCallbackFcn(app, @FinalCommentsTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.TargetCoverageAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TargetCoverage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetCoverageAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetCoverageAppUIFigure)
        end
    end
end